classdef DPorCCA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DetectorMenu                  matlab.ui.container.Menu
        SettingsDetectorMenu          matlab.ui.container.Menu
        PorCCMenu                     matlab.ui.container.Menu
        PorCCSettingsMenu             matlab.ui.container.Menu
        ClickTrainsMenu               matlab.ui.container.Menu
        NewprojectMenu                matlab.ui.container.Menu
        OpenexistingprojectMenu       matlab.ui.container.Menu
        DownloadsMenu                 matlab.ui.container.Menu
        TablesMenu                    matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutDPorCCAMenu              matlab.ui.container.Menu
        SearchMenu                    matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        CLICKTRAINSTab                matlab.ui.container.Tab
        DISPLAYSETTINGSPanel          matlab.ui.container.Panel
        DatePanel                     matlab.ui.container.Panel
        DateandtimeofCTLabel          matlab.ui.control.Label
        ClicktrainnumberTotalPanel    matlab.ui.container.Panel
        CTForw                        matlab.ui.control.Button
        CTNumD                        matlab.ui.control.NumericEditField
        CTBack                        matlab.ui.control.Button
        TotalLabel                    matlab.ui.control.Label
        ClicktraintypePanel           matlab.ui.container.Panel
        CTTypeLabel                   matlab.ui.control.Label
        LengthPanelength              matlab.ui.container.Panel
        LengthLabel                   matlab.ui.control.Label
        BehaviourPanel                matlab.ui.container.Panel
        BehavLabel                    matlab.ui.control.Label
        CalfPanel                     matlab.ui.container.Panel
        CalfLabel                     matlab.ui.control.Label
        DayNightPanel                 matlab.ui.container.Panel
        DayLabel                      matlab.ui.control.Label
        NotesPanel                    matlab.ui.container.Panel
        SeeAddButton                  matlab.ui.control.Button
        SaveupdatesButton             matlab.ui.control.Button
        ACTIONSClicktrainsPanel       matlab.ui.container.Panel
        SelectionPanel                matlab.ui.container.Panel
        SelectedCTCheckBox            matlab.ui.control.CheckBox
        SelectButton                  matlab.ui.control.Button
        UnselectButton                matlab.ui.control.Button
        Individualclicks3DPanel       matlab.ui.container.Panel
        IndClicksAnd3D                matlab.ui.control.Button
        SpectrogramButton             matlab.ui.control.Button
        VALIDATIONCHANGESPanel        matlab.ui.container.Panel
        CTTypeDropDownLabel           matlab.ui.control.Label
        CTTypeDropDown                matlab.ui.control.DropDown
        BehaviourDropDownLabel        matlab.ui.control.Label
        BehaviourDropDown             matlab.ui.control.DropDown
        UpdateButton                  matlab.ui.control.Button
        DisplayButtonGroup            matlab.ui.container.ButtonGroup
        AllButton                     matlab.ui.control.RadioButton
        NBHFButton                    matlab.ui.control.RadioButton
        PLOTSPanel                    matlab.ui.container.Panel
        AmpAxesCT                     matlab.ui.control.UIAxes
        ICIAxesCT                     matlab.ui.control.UIAxes
        FreqAxesCT                    matlab.ui.control.UIAxes
        ButtonGroupFreqBear           matlab.ui.container.ButtonGroup
        CentroidfrequencykHzButton    matlab.ui.control.RadioButton
        DirectionofarrivalButton      matlab.ui.control.RadioButton
        ButtonGroupICICPS             matlab.ui.container.ButtonGroup
        InterclickintervalmsButton    matlab.ui.control.RadioButton
        ClickspersecondButton         matlab.ui.control.RadioButton
        Image                         matlab.ui.control.Image
        METRICSTab                    matlab.ui.container.Tab
        DISPLAYPanelMetrics           matlab.ui.container.Panel
        CPMAxesMetrics                matlab.ui.control.UIAxes
        DISPLAYSETTINGSPanel_2        matlab.ui.container.Panel
        MetricstodisplayPanel         matlab.ui.container.Panel
        ButtonGroup                   matlab.ui.container.ButtonGroup
        PositiveporpoiseminuteButton  matlab.ui.control.RadioButton
        TypeofCTButton                matlab.ui.control.RadioButton
        BehaviourButton               matlab.ui.control.RadioButton
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        AllButtonMetrics              matlab.ui.control.RadioButton
        NightDayButton                matlab.ui.control.RadioButton
        PanelDropdown                 matlab.ui.container.Panel
        SelectadateDropDownLabel      matlab.ui.control.Label
        SelectadateDropDown           matlab.ui.control.DropDown
        DisplayButton                 matlab.ui.control.Button
        ALLDAYSPanel                  matlab.ui.container.Panel
        MetricsTable                  matlab.ui.control.Table
        Panel_3                       matlab.ui.container.Panel
        SelectFolderMetricEdit        matlab.ui.control.EditField
        BrowseButton_2                matlab.ui.control.Button
        SelectfolderwithsubfoldersLabel  matlab.ui.control.Label
        UploadData                    matlab.ui.control.Button
        ResetallButton                matlab.ui.control.Button
        DISPLAYPanelCPM               matlab.ui.container.Panel
        CPMAxesTimes                  matlab.ui.control.UIAxes
        CPMAxesTot                    matlab.ui.control.UIAxes
    end

    %% GLOBAL VARIABLES
    properties (Access = public)
        SelectedFolder =  'C:\Users\'% Description
        NumParMatFiles = 0; % Number of Click Parameters mat files
    end
    
    properties (Access = private)
        ClickParameters = table(); % Description
        ClickParFile = 'C:\Users\'; % Click Parameters file location
        NumCT = 1; % Click train number
        CTInfo = []; % Table with info on all click trains
        MetricSelectedFolder = []; % Description
        FoldersIn = []; % Description
        DropDownDay = []; % Dropdown of all days for metrics
        PosPorMin = []; % Positive Porpoise Minute
        FolderToOpen = []; % Description
        CTTemp = []; % Temporary click train
        Fs = []; % Sampling frequency
        Gain = []; % Gain of the hydrophone
        HydSens = []; % Hydrophone sensitivity (dB)
        ThresHQ = []; % Threshold for HQ
        ThresLQ = []; % Threshold for LQ
        HydN = 1; % Number of Hydrophones
        DAQpp = []; % Clipping level of DAQ card
       %Coefficients Log Reg Model 1 = Q(rms) duration (80%)
        logitCoefHQ = [-12.857529903901488;1.480233893649301;-0.037748903932102];
        % Coefficients for Model 2 (LQ)
        logitCoefLQ = [4.359888452493062;0.873710953710921;-0.017386257791731;...
             -6.901099488779461;35.272929893479020;-1.443845172568886e-05;-0.142569799845068];
        % Click model for cross correlation
        click_model = [0.00336253736901471;0.00336253736901471;-0.0201752242140883; ...
             0;0.0437129857971912;-0.0302628363211324;-0.0672507473802942;0.114326270546500;0.0336253736901471;-0.201752242140883;0.0941510463324119;0.211839854247927;-0.238740153200044;-0.124413882653544;0.346341349008515;-0.0235377615831030;-0.359791498484574;0.171489405819750;0.302628363211324;-0.272365526890192;-0.228652541093000;0.353066423746545;0.137864032129603;-0.396779409543736;-0.0403504484281765;0.413592096388809;-0.0538005979042354;-0.427042245864868;0.178214480557780;0.386691797436692;-0.299265825842309;-0.285815676366250;0.393416872174721;0.137864032129603;-0.400141946912750;0.00336253736901471;0.346341349008515;-0.121051345284530;-0.232015078462015;0.147951644236647;0.134501494760588;-0.141226569498618;-0.0605256726422648;0.104238658439456;0.0302628363211324;-0.0672507473802942;-0.0269002989521177;0.0504380605352207;0.0437129857971912;-0.0807008968563530;-0.00672507473802942;0.0773383594873383;-0.0201752242140883;-0.0638882100112795;0.0504380605352207;0.0369879110591618;-0.0672507473802942;-0.0100876121070441;0.0706132847493089;-0.0168126868450736;-0.0571631352732501;0.0235377615831030;0.0538005979042354;-0.0470755231662059;-0.0403504484281765;0.0504380605352207;0.0269002989521177;-0.0571631352732501;-0.0100876121070441;0.0605256726422648;-0.00336253736901471;-0.0437129857971912;0.0201752242140883;0.0235377615831030;-0.0168126868450736;-0.0100876121070441;0.00336253736901471;0;0.0168126868450736;-0.0134501494760588;-0.0269002989521177;0.0336253736901471;0.0302628363211324;-0.0571631352732501;-0.0134501494760588;0.0807008968563530;-0.0168126868450736;-0.0807008968563530;0.0538005979042354;0.0605256726422648;-0.0773383594873383;-0.0269002989521177;0.0840634342253677;-0.0100876121070441;-0.0739758221183236;0.0369879110591618;0.0470755231662059;-0.0470755231662059;-0.0168126868450736;0.0403504484281765;0;-0.0269002989521177;0.00672507473802942;0.0134501494760588;-0.00672507473802942;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0];
                
        NumberBinaryFiles = []; % Description
        clickfiles = 'a'; % Description
        signalCT = []; % Description
        LAT = 0; % Description
        LONG = 0; % Description
        MaxLength = []; % Description
        MinLength = []; % Description
        ICISep = []; % Description
        DATE = []; % Description
        topLevelFolderMetrics = [];% Description
        listOfFolderNamesMetrics = [];% Description
        SelectedDays = [];% Description
        CTInfo2 = []; % Description
        NBHF = []; % Description
        LQNBHF = []; % Description
        NonNBHF = []; % Description
        Travel = []; % Description
        Forage = []; % Description
        Comm = []; % Description
        Unknown = []; % Description
        Night = []; % Description
        Day = []; % Description
        NBHFd = []; % Description
        LQNBHFd = []; % Description
        NonNBHFd = []; % Description
        NoiseD = []; % Description
        NBHFn = []; % Description
        LQNBHFn = []; % Description
        NonNBHFn = []; % Description
        NoiseN = []; % Description
        TravelD = []; % Description
        ForageD = []; % Description
        CommD = []; % Description
        UnknownD = []; % Description
        TravelN = []; % Description
        ForageN = []; % Description
        CommN = []; % Description
        UnknownN = []; % Description
        CalvesD = []; % Description
        CalvesN = []; % Description
        Calves = []; % Description
        NoCalves = []; % Description
        SubFoldersChecked = []; % Description
        listOfFolderNames = []; % Description
        numberOfFoldersMetrics = []; % Description
        Minutes = []; % Description
        SummaryTable = []; % Description
        Sonar = []; % Description
        Orient = []; % Description
        listOfFolderNamesProj = []; % Description
        CTTemp2 = [];
        NewCT = [];
        
    end
    
    %% GLOBAL FUNCTIONS
    methods (Access = private)
        %% UPDATE PLOTS FUNCTION
        function UpdateCT(app, NumCT)
            cla(app.AmpAxesCT, 'reset')
            cla(app.FreqAxesCT, 'reset')
            cla(app.ICIAxesCT, 'reset')
%            RowN = find([app.CTInfo.CTNum] == NumCT);
%            if RowN <= length(app.CTInfo)% && app.CTInfo(RowN).Length >= app.MinLength
            app.CTTemp = [];
            app.CTTemp = app.ClickParameters(app.ClickParameters.CT == NumCT, :);
%                 if size(app.CTTemp, 1) > 5
            PosICI = find(app.CTTemp.ICI ~= 0);
            app.Fs = app.CTTemp.ICI(PosICI(3),1)/(1000*(app.CTTemp.startSample(PosICI(3),1) - app.CTTemp.startSample(PosICI(3)-1,1)));
            app.Fs = 1/app.Fs;
%                     if size(app.CTTemp,1) >= app.MinLength
            app.CTNumD.Value = NumCT;
            app.CTTemp.CPS(:,1) = 1000./app.CTTemp.ICI(1:end,1);
            app.CTTemp.CPS(1,1) = 0;
            app.CTTypeLabel.Text = app.CTInfo([app.CTInfo.CTNum] == NumCT).Species;
            app.DateandtimeofCTLabel.Text = app.CTInfo([app.CTInfo.CTNum] == NumCT).Date;
            app.LengthLabel.Text = num2str(size(app.CTTemp,1));
            app.BehavLabel.Text = app.CTInfo([app.CTInfo.CTNum] == NumCT).Behav;
          %  app.CalfLabel.Text = app.CTInfo([app.CTInfo.CTNum] == NumCT).Calf;
            app.TotalLabel.Text = sprintf(['( ', num2str(app.ClickParameters.CT(end,1)), ')']);
            app.DayLabel.Text = app.CTInfo([app.CTInfo.CTNum] == NumCT).DayNight;
               if app.CTInfo([app.CTInfo.CTNum] == NumCT).Saved == 1
                     app.SelectedCTCheckBox.Value = 1;
               end
            app.CTTemp = NewICI(app, app.CTTemp, app.Fs);
            app.CTTemp.SumMs(1,1) = 0;
               for i = 2:size(app.CTTemp,1)
                app.CTTemp.SumMs(i,1) = app.CTTemp.SumMs(i-1,1) + app.CTTemp.ICI(i,1);
               end 
            cla(app.AmpAxesCT, 'reset')
            app.CTTemp.SumMs = (app.CTTemp.SumMs)./1000;
            CT1HQ = app.CTTemp(app.CTTemp.Class == 1,:);
            CT1LQ = app.CTTemp(app.CTTemp.Class == 2,:);                      
                        
            % plot amplitude
            stem(app.AmpAxesCT, CT1HQ.SumMs, CT1HQ.Amp, 'marker', '.', 'color', 'red')
            hold (app.AmpAxesCT, 'on');
            stem(app.AmpAxesCT, CT1LQ.SumMs, CT1LQ.Amp, 'marker', '.', 'color', [0.0, 0.502, 1.00]) % [0.5843 0.8157 0.9882])
            hold(app.AmpAxesCT,'off')
            YLimAmpMin = 80;
            YLimAmpMax = 180;
            ylim(app.AmpAxesCT, [YLimAmpMin, YLimAmpMax])
            xlim(app.FreqAxesCT, [0, app.CTTemp.SumMs(end,1)])
          %  app.AmpAxesCT.DataAspectRatio = [0.197154471544715 1 1];
            app.AmpAxesCT.PlotBoxAspectRatio = [5.07216494845361 1 1];
            app.AmpAxesCT.Position = [21 332 715 174];
                        
            % plot click per second (default) or ICI
            ICIorCPS = app.InterclickintervalmsButton.Value;
            
            if ICIorCPS == 1 
                % plot inter-click interval
                plot(app.ICIAxesCT, CT1HQ.SumMs, CT1HQ.ICI, '.', 'color', 'red')
                hold (app.ICIAxesCT, 'on');
                plot(app.ICIAxesCT, CT1LQ.SumMs, CT1LQ.ICI, '.', 'color', [0.0, 0.502, 1.00]) %[0.5843 0.8157 0.9882])
                hold (app.ICIAxesCT, 'off');
                YLimICIMin = 0;
                YLimICIMax = 500;
                ylim(app.ICIAxesCT, [YLimICIMin, YLimICIMax])
                xlim(app.FreqAxesCT, [0, app.CTTemp.SumMs(end,1)])
                resetplotview(app.FreqAxesCT,'InitializeCurrentView');  
             %   app.ICIAxesCT.DataAspectRatio = [0.2125 1 0.68];
                app.ICIAxesCT.PlotBoxAspectRatio = [4.70588235294118 1 1.47058823529412];
                app.ICIAxesCT.Position = [21 153 714 190];
           else
               % plot clicks per second
                plot(app.ICIAxesCT, CT1HQ.SumMs, CT1HQ.CPS, '.', 'color', 'red')
                hold (app.ICIAxesCT, 'on');
                plot(app.ICIAxesCT, CT1LQ.SumMs, CT1LQ.CPS, '.', 'color', [0.0, 0.502, 1.00]) %[0.5843 0.8157 0.9882])
                hold (app.ICIAxesCT, 'off');
                YLimICIMin = 0;
                YLimICIMax = max(app.CTTemp.CPS) + 10;
                ylim(app.ICIAxesCT, [YLimICIMin, YLimICIMax])
                xlim(app.FreqAxesCT, [0, app.CTTemp.SumMs(end,1)])
             %   app.ICIAxesCT.DataAspectRatio = [0.2125 1 0.68];
                app.ICIAxesCT.PlotBoxAspectRatio = [4.70588235294118 1 1.47058823529412];
                app.ICIAxesCT.Position = [21 153 714 190];                
            end
                    
                        FreqOrBearing = app.DirectionofarrivalButton.Value;
                        BearExist = strcmpi('Bearing', app.ClickParameters.Properties.VariableNames);
                        
                        if FreqOrBearing == 1 && size(BearExist,1) > 0
                            % plot bearing
                            plot(app.FreqAxesCT, CT1HQ.SumMs, CT1HQ.Bearing, '.', 'color', 'red')
                            hold (app.FreqAxesCT, 'on');
                            plot(app.FreqAxesCT, CT1LQ.SumMs, CT1LQ.Bearing, '.', 'color', [0.0, 0.502, 1.00]) %[0.5843 0.8157 0.9882])
                            hold (app.FreqAxesCT, 'off');
                            YLimBearMin = 0;
                            YLimBearMax = 180;
                            ylim(app.FreqAxesCT, [YLimBearMin, YLimBearMax])
                            xlim(app.FreqAxesCT, [0, app.CTTemp.SumMs(end,1)])
                         %   app.FreqAxesCT.DataAspectRatio = [0.15 1 0.811948051948052];
                            app.FreqAxesCT.PlotBoxAspectRatio = [6.66666666666667 1 1.23160588611644];
                            app.FreqAxesCT.Position = [21 7 716 167];
                        else
                
                            % plot frequency
                            stem(app.FreqAxesCT, CT1HQ.SumMs, CT1HQ.CF/1000, 'marker', '.', 'color', 'red')
                            hold (app.FreqAxesCT, 'on');
                            stem(app.FreqAxesCT, CT1LQ.SumMs, CT1LQ.CF/1000, 'marker', '.', 'color', [0.0, 0.502, 1.00]) %[0.5843 0.8157 0.9882])
                            hold (app.FreqAxesCT, 'off');
                            YLimBearMin = 90;
                            YLimBearMax = 160;
                            ylim(app.FreqAxesCT, [YLimBearMin, YLimBearMax])
                            xlim(app.FreqAxesCT, [0, app.CTTemp.SumMs(end,1)])
                          %  app.FreqAxesCT.DataAspectRatio = [0.15 1 0.811948051948052];
                            app.FreqAxesCT.PlotBoxAspectRatio = [6.66666666666667 1 1.23160588611644];
                            app.FreqAxesCT.Position = [21 7 716 167];
                        end
                        CT1HQ = [];
                        CT1LQ = [];
%                 else
%                      CTForwCB(app)
%                 end % if new length
%               end
              %close(figure(1))
        end
                
        %% BEHAVIOUR
        function [Behav] = Behaviour(app, CPS, MeanPF, MeanPercChangeCF)
            MedianCPS = median(CPS);
            L = size(CPS,1);
            SortedCPS = sort(CPS);
            CPS90Perc1 = SortedCPS(1:floor(0.90*L));
            CPS20 = mean(CPS(1:floor(L*0.2)));
            CPS50 = mean(CPS(floor(0.2*L):floor(0.8*L)));
            CPS90 = mean(CPS(floor(0.8*L):end));
               if MeanPF > 140000 && MedianCPS < 8.5 && MedianCPS > 7.1 && MeanPercChangeCF < 0.5
                 Behav = 'Sonar';
               elseif CPS90Perc1 < 100
                 Behav = 'Orientation';
               else
                    CPS90Perc2 = SortedCPS(floor(0.10*L):end);
                    if CPS90Perc2 > 100
                      Behav = 'Socialising';
                    else    
                      BreakingPoint = find(CPS > 100);
                      DiffBP = diff(BreakingPoint);
                      BP = find(DiffBP == 1);
                      if size(BP, 1) > 0
                        Pos = BreakingPoint(BP(1));
                        if size(BP, 1) > 0 && Pos > 5 && size(CPS,1) > Pos+5
                          Before = mean(CPS(Pos-5:Pos));
                          After = mean(CPS(Pos:Pos+5));
                        else
                            Before = 0;
                            After = 0;
                        end
                        
                        if Before < 100 && After > 100
                          Behav = 'Foraging';        
                        else
                            if mean(CPS90Perc1) > 120
                              Behav = 'Socialising';
                            elseif CPS20 > 100 && CPS50 < 100 && CPS90 < CPS50
                              Behav = 'Socialising';     
                            else    
                              Behav = 'Unknown';    
                            end   
                        end
                      else
                        if mean(CPS90Perc1) > 150
                           Behav = 'Socialising';
                        elseif CPS20 > 100 && CPS50 < 100 && CPS90 < 100
                           Behav = 'Socialising';     
                        else    
                           Behav = 'Unknown';  
                        end
                      end 
                    end
               end
        end
        
        %% CLICK DURATION
        function [duration] = clickDuration(app, click)
                % Click duration based on Madsen & Walhberg 2007 - 80%
               Ener = cumsum(click.^2);
               Ener10 = Ener(end) * 0.1; % 1.5% of acumulated energy
               Ener90 = Ener(end) * 0.9;% 98.5% of acumulated energy
               ix = sum(Ener<=Ener10)+1; % index of where the 1.5% is
               iy = sum(Ener<=Ener90)+1; % index of where the 98.5% is
               duration = (iy-ix)*2; %in microseconds
        end
        
        %% CLICK AMPLITUDE
        function [amp] = clickAmplitude(app, clickWave, hSens, gain, adcPeakPeak)
            sz = size(clickWave);
            nChan = sz(2);
            amp = zeros(1,nChan);
            for i = 1:nChan
                mV = max(abs(clickWave(:,i)));
                dbV = 20*log10(mV/2*adcPeakPeak);
                amp(i) = dbV - (hSens + gain);
            end
        end
        
        %% PEAK AND CENTROID FREQUENCIES
        function [PSD, f, CF, PF] = CentroidAndPeakFrq1(app, click, FFT, Fs)
            [PSD,f] = periodogram(click,[],FFT,Fs,'power');
            PSD = PSD./max(PSD); % normalize spectrum
            CF = sum(f.*PSD.^2)./sum(PSD.^2);
            [~,indfc] = max(PSD);
            PF = f(indfc);
        end
                        
        %% EXTRACT PATTERNS IN  CT
        function[CTTemp2, NewCT] = ExtractPatterns(app, CTTemp, Fs)           
            % add rown numbers to see if what we removed is another click train
            CTTemp.RowN(1,1) = 1;
            CTTemp.RowN(1:end,1) = 1:size(CTTemp,1);
            % Save in another variable
            CTTemp1 = CTTemp;  
            % A large difference indicates echoes
            SortedCPS = sort(CTTemp.CPS);
            DiffSorted = diff(SortedCPS);
            [MaxDiffSorted, ~] = max(DiffSorted);
            % Good click trains that only have echoes 
            if MaxDiffSorted < 28
                % Do nothing
            else % if MaxDiffSorted < 500
                Outlier = isoutlier(CTTemp.CPS);
                HighCPS = CTTemp.CPS > 50;
                Both = Outlier + HighCPS;
                CTTemp(Both == 2,:) = [];
                CTTemp = NewICI(app, CTTemp,Fs);
                SortedCPS = sort(CTTemp.CPS);
                DiffSorted = diff(SortedCPS);
                [MaxDiffSorted, ~] = max(DiffSorted);
            end
            if MaxDiffSorted < 28 && MaxDiffSorted > 10 && size(CTTemp,1) < 100
               NewCT = CTTemp; % it ends here
               NewCT = NewICI(app, NewCT, Fs);
            else
                % remove local min
                S1 = size(CTTemp,1);
                S2 = 1;
                while S1 ~= S2
                    clear DeleteRows LargeDiff LocMin DiffAmps
                    S1 = size(CTTemp,1);
                    DiffAmps(1,1) = 0;
                    L = size(CTTemp,1);
                    DiffAmps(2:L,1) = diff(CTTemp.Amp);
                    LocMin = islocalmin(CTTemp.Amp);
                    LargeDiff = DiffAmps < -5;
                    DeleteRows = LocMin + LargeDiff;
                    CTTemp(DeleteRows == 2,:) = [];
                    CTTemp = NewICI(app, CTTemp,Fs);
                    S2 = size(CTTemp,1);
                end 
            end
            SortedCPS = sort(CTTemp.CPS);
            DiffSorted = diff(SortedCPS);
            [MaxDiffSorted, ~] = max(DiffSorted);
            
            if MaxDiffSorted > 35 && size(CTTemp,1) > 100
                 % Finding the stable areas
                 StartRow = [];
                  % Bit by bit
                 CPSDiff = diff(CTTemp.CPS);
                 PercChangeS = (CPSDiff./CTTemp.CPS(1:end-1))*100;
                 PercChangeS = abs(PercChangeS(2:end));
                 PercCPSDiff = movmean(PercChangeS,4);
                 StartRow = find(PercCPSDiff < 5);
                 StartRow = StartRow + 2; % because I removed the first value == 0
                 DiffStartRow = diff(StartRow);
                 Here = find(DiffStartRow == 1);
                 k = []; L = []; StdCPS1 = []; StdCPS2 = [];
             
                 % go into the CT
                 clear RowN 
                 RowsToKeep = [];
                 Keep = 1;
                 if size(StartRow,1) == 0 
                   RowN = 1;
                 else
                   RowN = StartRow(Here(1));  % Low variability in CPS (for the next 4) 
                 end 
                 RowsToKeep(1,1) = RowN;
                 FirstRowN = RowN;
                 
                 % Look backwards
                 while RowN > 10
                 ClickCPS = CTTemp.CPS(RowN,1);
                 ClickAmp = CTTemp.Amp(RowN,1);
                 ClickStartSample = CTTemp.startSample(RowN,1);
                 ICIs = abs(ClickStartSample - CTTemp.startSample(RowN-9:RowN-1,1))/(Fs/1000);
                 CPSs = 1000./ICIs;
                 Amps = CTTemp.Amp(RowN-9:RowN-1,1);
                 Amps = abs(ClickAmp - Amps);
                 DiffCPSs = abs(ClickCPS - CPSs);
                 [~, ixCPS] = min(DiffCPSs);
                     if Amps(ixCPS) < 5
                       DiffCPSs(ixCPS) = 1000; % high arbitrary numnber
                       [~, ixCPS] = min(DiffCPSs);
                       RowN = RowN - ixCPS;
                     else
                       RowN = RowN - ixCPS;  
                     end 
                 Keep = Keep + 1;  
                 RowsToKeep(Keep,1) = RowN;
                 end 
                 
                 % Look forwards
                 RowN = FirstRowN;
                 while RowN < size(CTTemp,1)-10
                 ClickCPS = CTTemp.CPS(RowN,1);
                 ClickAmp = CTTemp.Amp(RowN,1);
                 ClickStartSample = CTTemp.startSample(RowN,1);
                 ICIs = abs(CTTemp.startSample(RowN+1:RowN+9,1) - ClickStartSample)/(Fs/1000);
                 CPSs = 1000./ICIs;
                 Amps = CTTemp.Amp(RowN+1:RowN+9,1);
                 Amps = abs(Amps - ClickAmp);
                 DiffCPSs = abs(CPSs - ClickCPS);
                 [~, ixCPS] = min(DiffCPSs);
                     if Amps(ixCPS) < 5
                       DiffCPSs(ixCPS) = 1000;
                       [~, ixCPS] = min(DiffCPSs);
                       RowN = RowN + ixCPS;
                     else
                       RowN = RowN + ixCPS;  
                     end 
                 Keep = Keep + 1;  
                 RowsToKeep(Keep,1) = RowN;
                 end 
             RowsToKeep(end+1:end+20,1) = [1:10, size(CTTemp,1)-9:size(CTTemp,1)];
             RowsToKeep = sort(unique(RowsToKeep));
             
             % Delete loose clicks
            L1 = size(CTTemp,1);
            L2 = 1;
               while L2 ~= L1
                L1 = size(CTTemp,1);           
                CTTemp = NewICI(app, CTTemp, Fs);   
                LooseClicks = find(CTTemp.ICI > 250);
                Positions = find(diff(LooseClicks) == 1);
                RowsToDelete = LooseClicks(Positions);
                CTTemp(RowsToDelete, :) = [];
                L2 = size(CTTemp,1);
               end
             NewCT = CTTemp(RowsToKeep,:);
             NewCT = NewICI(NewCT, Fs);
            else 
             NewCT = CTTemp; 
             NewCT = NewICI(app, NewCT, Fs);
            end 
            RowsLeft = NewCT.RowN(:,1);
            CTTemp2 = CTTemp1;
            CTTemp2(RowsLeft,:) = [];
            % SAVE            
            ClickParNames = strcat(app.SelectedFolder,'\ClickParameters*.mat');
            ClickParFilesInFolder = uigetdir(ClickParNames);
            app.NumParMatFiles = size(ClickParFilesInFolder,1);
            ClickParFileName = ClickParFilesInFolder(1).name;
            app.ClickParFile = strcat(app.SelectedFolder, '\', ClickParFileName);
            ClickParameters = app.ClickParameters;
            save(app.ClickParFile, 'ClickParameters');
            ClickParameters = [];
        end 
            
        % ESTIMATE ICI and CPS VALUES
        function CTTemp = NewICI(app, CTTemp, Fs)
            CTTemp.ICI(1,1) = 0;
            CTTemp.ICI(2:end,1) = diff(CTTemp.startSample)/(Fs/1000);
            CTTemp.CPS(1:size(CTTemp,1),1) = 1000./CTTemp.ICI(1:end,1);
            CTTemp.CPS(1,1) = 0;
        end
            
        % DELETE CLICKS WITH ICI > 200ms BEFORE AND AFTER
        function CTTemp = DeleteLoose(app, CTTemp, Fs)
        L1 = size(CTTemp,1);
        L2 = 1;
            while L2 ~= L1
               L1 = size(CTTemp,1);
               for j = size(CTTemp,1)-1:-1:1
                  if CTTemp.ICI(j,1) > 200 && CTTemp.ICI(j+1,1) > 200
                      CTTemp(j,:) = [];  
                  end
               CTTemp = NewICI(app, CTTemp, Fs);
               L2 = size(CTTemp,1);
               end
            end
        end    
        
    end % methods (Access = private)

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CTForw
        function CTForwCB(app, event)
            CTNum = app.CTNumD.Value;
            cla(app.AmpAxesCT, 'reset')
            cla(app.ICIAxesCT, 'reset')
            cla(app.FreqAxesCT, 'reset')
            % Finds row in CTInfo
            app.NumCT = find([app.CTInfo.CTNum] == CTNum);
            if app.NumCT < length(app.CTInfo)
                app.NumCT = app.NumCT + 1;
            else
                app.NumCT = app.NumCT;
            end
            % CTNum of the following CT in CTInfo
            app.CTNumD.Value = app.CTInfo(app.NumCT).CTNum;
            UpdateCT(app, app.CTNumD.Value)
        end

        % Button pushed function: CTBack
        function CTBackCB(app, event)
            CTNum = app.CTNumD.Value;
            cla(app.AmpAxesCT, 'reset')
            cla(app.ICIAxesCT, 'reset')
            cla(app.FreqAxesCT, 'reset')
            app.NumCT = find([app.CTInfo.CTNum] == CTNum);
            if app.NumCT > 1
                app.NumCT = app.NumCT - 1;
            else
                app.NumCT = 1;
            end
            app.CTNumD.Value = app.CTInfo(app.NumCT).CTNum;
            UpdateCT(app, app.CTNumD.Value)
        end

        % Menu selected function: PorCCSettingsMenu
        function FigPorCCSettings(app, event)
            % CREATE MENU FIGURE
            MenuPorCCF = uifigure('Position', [200 200 260 400]);
            MenuPorCCF.Name = 'Select folder PorCC';
            MenuPorCCF.MenuBar = 'none';    % Hide standard menu bar menus.
            MenuPorCCF.Resize='off';
            MenuPorCCF.Visible = 'on';
            
            % Create panel within figure
            PorCCSetPan = uipanel('Parent', MenuPorCCF,'Units','pixels', 'Position',[10 10 240 380]);
            SelectFolderText = uilabel(PorCCSetPan,'Position', [10 355 80 20]);
            SelectFolderText.HorizontalAlignment = 'left';
            SelectFolderText.Text = 'Select Folder';
            % Browse button
            FolderPathPorCC = uieditfield('Parent', PorCCSetPan,'Position', [10 335 220 20],...
                'value', 'C:\');
            
            FileDD = uidropdown('Parent', PorCCSetPan,'Position', [70 310 160 20]);
            FileDD.Items = {'PAMGUard output - pgdf', 'SoundTrap output'};
            uilabel(PorCCSetPan,'Position', [10 310 70 20], 'Text','Data type');
            
            BrowsePorCC = uibutton(PorCCSetPan, 'push', 'Position',[150 275 80 20], "Text", 'Browse', ...
                'ButtonPushedFcn', @(BrowsePorCC,event) PushBrowseButton(BrowsePorCC));
            function PushBrowseButton(BrowsePorCC,event)
                    % Selects the folder or folder/subfolder where pgdf files are
               folder = fullfile(matlabroot, 'C:\Users\');
               app.SelectedFolder = uigetdir(folder); %uigetdir
                   if app.SelectedFolder == 0
                   	           return;
                   end
               FolderPathPorCC.Value = app.SelectedFolder;
            end
            
            % PARAMETERS PANEL
            ParPan = uipanel('Parent', PorCCSetPan,'Units','pixels', 'Position',[10 130 220 140]);
            % HydrophoneS section
            uilabel('Parent', ParPan, "Text",'No of Hydrophones', "Position", [10 110 120 20]);
            EditHydN = uieditfield('Parent', ParPan, "Value", '1', 'Position', [120 110 50 20]);
            EditHydN.HorizontalAlignment = 'right';
            % Sampling Frequency
            uilabel('Parent', ParPan,'Text', 'Sampling Freq', 'Position', [10 85 80 20]);
            FsEdit = uieditfield('Parent', ParPan,'Value', '576', 'Position',[120 85 50 20]);
            FsEdit.HorizontalAlignment = 'right';
            uilabel('Parent', ParPan,'Text', 'kHz', 'Position', [180 85 80 20]);
            % Sensitivity
            uilabel('Parent', ParPan,'Text', 'Hyd Sensitivity', 'Position',[10 60 80 20]);
            SensEdit = uieditfield('Parent', ParPan,'Value', '-182', 'Position',[120 60 50 20]);
            SensEdit.HorizontalAlignment = 'right';
            uilabel('Parent', ParPan,'Text', 'dB', 'Position',[180 60 50 20]);
            % Gain
            uilabel('Parent', ParPan,'Text', 'Gain', 'Position',[10 35 80 20]);
            GainEdit = uieditfield('Parent', ParPan,'Value', '0', 'Position',[120 35 50 20]);
            GainEdit.HorizontalAlignment = 'right';
            uilabel('Parent', ParPan,'Text', 'dB', 'Position',[180 35 50 20]);
            % DAQ Peak to Peak (Clipping level)
            uilabel('Parent', ParPan,'Text', 'Peak to Peak', 'Position',[10 10 80 20]);
            DAQppEdit = uieditfield('Parent', ParPan,'Value', '2', 'Position',[120 10 50 20]);
            DAQppEdit.HorizontalAlignment = 'right';
            uilabel('Parent', ParPan,'Text', 'Volts', 'Position',[180 10 50 20]);
            
            % Classifier panel
            ProbPan = uipanel('Parent', PorCCSetPan,'Units','pixels', 'Position',[10 60 220 65]);
            % HQ
            uilabel('Parent', ProbPan,'Text', 'Prob threshold', 'Position',[10 35 80 20]);
            HQThres = uieditfield('Parent', ProbPan,'Value', '0.999999', 'Position',[120 35 65 20]);
            HQThres.HorizontalAlignment = 'right';
            uilabel('Parent', ProbPan,'Text', 'HQ', 'Position',[190 35 50 20]);
            % LQ
            uilabel('Parent', ProbPan,'Text', 'Prob threshold', 'Position',[10 10 80 20]);
            LQThres = uieditfield('Parent', ProbPan,'Value', '0.55', 'Position',[120 10 65 20]);
            LQThres.HorizontalAlignment = 'right';
            uilabel('Parent', ProbPan,'Text', 'LQ', 'Position',[190 10 50 20]);
            
            % Buttons
            ButPan = uipanel('Parent', PorCCSetPan,'Units','pixels', 'Position',[10 10 220 45]);
                    
            % Apply Values
            ApplyButton = uibutton(ButPan,'push', 'Position',[10 10 90 25], 'Text', 'Apply Values', ...
               'ButtonPushedFcn', @(ApplyButton,event) PushApplyButton(ApplyButton));
                               
            %% PORCC - STEPS INCLUDE
            % 1) Getting the necessary values for PorCC
            % 2) Accessing individual clicks in .pgdf files
            % 3) Estimating probabilities and all other parameters
            % 4) Saving if classified as LQ or HQ
            % 5) Remove clicks with the same date keeping the best one
            
        function PushApplyButton(ApplyButton, event)
            %% Apply the values in order to run PorCC with these
            app.Fs = str2double(FsEdit.Value)*1000;
            app.HydSens = str2double(SensEdit.Value);
            app.Gain = str2double(GainEdit.Value);
            app.ThresHQ = str2double(HQThres.Value);
            app.ThresLQ = str2double(LQThres.Value);
            app.HydN = str2double(EditHydN.Value);
            app.DAQpp = str2double(DAQppEdit.Value);
            PushPorCCMenu.Enable = 'on';
            app.SelectedFolder = FolderPathPorCC.Value;  
            Message = ['D-PorCCA will get to work as soon as you click the Run Classifier button' ...
                'A message will pop up when it is ready.'];
            uialert(app.UIFigure, Message, 'Running PorCC', 'Icon', 'warning')
            %DataType = FileDD.Items.Value;
        end
                 
                %% RUN PorCC
                PushPorCCMenu = uibutton(ButPan, 'push', 'Position', [120 10 90 25], 'Text', 'Run Classifier', ...
                    'ButtonPushedFcn', @(PushPorCCMenu,event) PushPorCCButton(PushPorCCMenu));
                PushPorCCMenu.Enable = 'off';
                
                function PushPorCCButton(PushPorCCMenu, event)
                 MenuPorCCF.Visible = 'off';
                
                % Message that it is working
%                 f = uifigure('Position', [300 300 300 150]);
%                 UpdateMes = uilabel(f, 'Position', [10 10 280 130]);
%                 Message = 'PorCC is at work! \n A message will pop up when ready.';
% %                 UpdateMes.Text = Message;
%                  uialert(app.UIFigure, Message, 'PorCC Running', 'Icon', 'warning')
              %  app.TabGroup.Enable = 'off';
                
                %% Create a table with 0s to make it faster and more effective
                ID(1:2000000, 1) = 0;
                Date(1:2000000,1) = 0;
                startSample(1:2000000,1) = 0;
                Duration(1:2000000,1) = 0; % % DURATION
                Amp(1:2000000,1) = 0; % MAXIMUM AMPLITUDE
                PF(1:2000000,1) = 0; % PEAK FREQUENCY
                CF(1:2000000,1) = 0; % CENTROID FREQUENCY
                BW(1:2000000,1) = 0; % BANDWIDTH -3dB
                RMSBW(1:2000000,1) = 0; % RMS-BW
                   if app.HydN > 1 % && exist(dataPoint.delays)
                         Bearing(1:2000000,1) = 0;
                   end
                ICI(1:2000000,1) = 0;
                CT(1:2000000,1) = 0;
                InFile{1,1} = 'a';
                ClickN(1:2000000,1) = 0;
                Class(1:2000000,1) = 0;
                
               % Get list of all subfolders.
                allSubFolders = genpath(app.SelectedFolder);
                % Parse into a cell array.
                remain = allSubFolders;
                listOfFolderNames = {};
                while true
                      	[singleSubFolder, remain] = strtok(remain, ';');
                      	if isempty(singleSubFolder)
                            		break;
                      	end
                      	listOfFolderNames = [listOfFolderNames singleSubFolder];
                end
                numberOfFolders = length(listOfFolderNames);
                
                % Get this folder and print it out.
                thisFolder = listOfFolderNames{1};

                % how many binary files in this folder          
                app.clickfiles = findBinaryFiles(thisFolder, 'click');
                % Now we have a list of all files in this folder.
                app.NumberBinaryFiles = length(app.clickfiles);
               
                % Running the classifier
                format longg;
                FFT = 512;
                RowN = 0;
                for p=1:app.NumberBinaryFiles %each pgdf file
                    click_id = 0; % Click Id within the pgdf file
                    % print out because lots of files and script takes a very long time.
                    Message = strcat('Loading file pgdf',' ', num2str(p) ,' of a total of  ', ' ',num2str(length(app.clickfiles)));
                    UpdateMes.Text = Message;

                    %LOAD THE FILES
                    fileName = app.clickfiles{p};
                    fid = fopen(fileName, 'r', 'ieee-be.l64');
                    % initialize variables
                    prevPos = -1;
                    fileInfo = [];
                    fileInfo.readModuleHeader=@readStdModuleHeader;
                    fileInfo.readModuleFooter=@readStdModuleFooter;
                    % Create a file with information about each file
                            % FilesInfo.FilNum(p,1) = p;
                            % FilesInfo.FilName(p,1) = filename;
                            % FilesInfo.Fs(p,1) = app.Fs;
                            % FilesInfo.Lat(p,1) = Lat;
                            % FilesInfo.Long(p,1) = Long;
                    % Main loop = goes into .pgdf files to find clicks
                    while (1)
                        % if for some reason we're stuck at one byte, warn the user and
                        % abort
                      pos = ftell(fid);
                        if (pos == prevPos)
                               disp(fprintf('File stuck at byte %d', pos));
                               break;
                        end
                      prevPos = pos;
                   
                        % read in the length of the object and the type. Move the menufile
                        % pointer back to the beginning of the length variable, and switch
                        % on the type.  If we couldn't read nextLen or nextType, assume
                        % that means we've hit the end of the menufile and break out of loop
                        [~, nL] = fread(fid, 1, 'int32');
                        [nextType, nT] = fread(fid, 1, 'int32');
                      if (nL == 0 || nT == 0)
                             break;
                      end
                      
                      fseek(fid, -8, 'cof');
                      switch nextType
                       
                        % Case 1: MenuFile Header. Read in the menufile header, and then set
                        % the function handles depending on what type of data the
                        % binary menufile holds. The module type is typically specified in
                        % the package class that extends PamControlledUnit, and is a
                        % string unique to that module.
                        case -1
                          fileInfo.fileHeader = readFileHeader(fid);
                          switch fileInfo.fileHeader.moduleType
                          % Click Detector Module
                             case 'Click Detector'
                               switch fileInfo.fileHeader.streamName
                                  case 'Clicks'
                                     fileInfo.objectType=1000;
                                     fileInfo.readModuleData=@readClickData;
                                     fileInfo.readModuleFooter=@readClickFooter;
                                  case 'Trigger Background'
                                     fileInfo.objectType=0;
                                     fileInfo.readModuleData=@readClickTriggerData;
                                     fileInfo.readModuleHeader=@readClickTriggerHeader;
                                end
                           otherwise
                              disp(['Don''t recognize type ' fileInfo.fileHeader.moduleType '.  Aborting load']);
                              break;
                           end
                        % Case 2: MenuFile Footer. The menufile version should have been set
                        % when we read the menufile header. If the menufile header is empty,
                        % something has gone wrong so warn the user and exit
                           case -2
                            if (isempty(fileInfo.fileHeader))
                                disp('Error: found file footer before file header.  Aborting load');
                                break;
                            end
                            fileInfo.fileFooter = readFileFooterInfo(fid, fileInfo.fileHeader.fileFormat);                              
                        % Case 3: Module Header.  The correct function handle should
                        % have been set when we read the menufile header.  If the menufile
                        % header is empty, something has gone wrong so warn the user
                        % and exit
                            case -3
                               if (isempty(fileInfo.fileHeader))
                                 disp('Error: found module header before file header.  Aborting load');
                                 break;
                               end
                              fileInfo.moduleHeader = fileInfo.readModuleHeader(fid);                                       
                        % Case 4: Module Footer.  The correct function handle should
                        % have been set when we read the menufile header.  If the menufile
                        % header is empty, something has gone wrong so warn the user
                        % and exit
                             case -4
                                if (isempty(fileInfo.fileHeader))
                                  disp('Error: found module footer before file header.  Aborting load');
                                  break;
                                end
                              fileInfo.moduleFooter = fileInfo.readModuleFooter(fid);                                         
                        % Case 5: Data.  The correct function handle should have been
                        % set when we read in the menufile header.  If the menufile header is
                        % empty, something has gone wrong so warn the user and exit
                            otherwise
                                if (isempty(fileInfo.fileHeader))
                                   disp('Error: found data before file header.  Aborting load');
                                   break;
                                end
                          dataPoint = readPamData(fid, fileInfo);
                          % check if the data point has a click in it
                          a = isfield(dataPoint, 'wave');
                          if a == 0
                            continue
                          else
                          end                                       
                        % If there are 2 Hydrophones use the hydrophone where the click impinges first, because it
                        % is expected to be of best quality
                        if app.HydN > 1
                           if dataPoint.delays < 0
                              click = dataPoint.wave(:,1);
                           else
                              click = dataPoint.wave(:,2);
                           end
                        else
                              click = dataPoint.wave;
                        end
                                                        
                     % Start counting clicks (impulsive sound), regardless of whether we use it
                    click_id = click_id + 1;
                                
                     % PorCC Classification
                     % PF = peak frequency , CF = centroid frequency
                     % PSD = power spectrum, f = frequency
                    [PSD, f, CF_Val, PF_Val] = CentroidAndPeakFrq1(app, click, FFT, app.Fs);
                        if PF_Val > 100000 && PF_Val < 160000 && CF_Val > 100000 && CF_Val < 160000 % Møhl & Andersen, 1973
                           % RMS-BW = RMS bandwidth, ratio = PF / CF, BW = -3dB bandwidth
                           % duration = estimated using 80% energy of the clip
                           % Q = CF/RMSBW, XC = peak cross-correlation coeficient with a model click
                          [RMSBW_Val] = (sqrt(sum((f-CF_Val).^2.*PSD.^2 ) / sum(PSD.^2)))/1000;
                          clear PSD f
                          durationV = clickDuration(app,click);
                          Q_Val = (CF_Val/RMSBW_Val)/1000;
                          Ratio_Val = PF_Val/CF_Val;
                          [XCoeff, ~] = xcorr(click, app.click_model); XC_Val = max(XCoeff);
                          BW_Val = powerbw(click, app.Fs)/1000;
                        if Q_Val >= 4 && durationV < 450
                               % estimate probabilities for HQ
                               ProbHQ = glmval(app.logitCoefHQ,[Q_Val durationV],'logit');
                               % assign each clip to a category
                           if ProbHQ >= app.ThresHQ
                                 Porps_Val = 1;  % HQ click
                           else
                             ProbLQ = glmval(app.logitCoefLQ,[Q_Val durationV Ratio_Val XC_Val CF_Val BW_Val],'logit'); % Log Model 2
                             if ProbLQ > app.ThresLQ
                                   Porps_Val = 2; % LQ click
                             else
                                   Porps_Val = 3; % HF noise
                             end
                           end
                        else
                               Porps_Val = 3; % if Q < 4 && duration > 450
                        end
                   else
                       Porps_Val = 3; % if peak is outside porpoise range
                       end % if Porps_Val...
                     % store in ClickParameters if Class HQ or LQ
                     if Porps_Val == 1 || Porps_Val == 2 || Porps_Val == 3
                         RowN = RowN + 1; % our ID
                         %%%%%% save HQ & LQ clicks  %%%%%%
                         ID(RowN,1) = RowN;
                         Date(RowN,1) = dataPoint.date;
                         startSample(RowN,1) = dataPoint.startSample;
                         Duration(RowN,1) = durationV; % % DURATION
                            Amp_Val = clickAmplitude(app, click, app.HydSens, app.Gain, app.DAQpp);
                         Amp(RowN,1) = Amp_Val; % MAXIMUM AMPLITUDE
                         PF(RowN,1) = PF_Val; % PEAK FREQUENCY
                         CF(RowN,1) = CF_Val; % CENTROID FREQUENCY
                         BW(RowN,1) = BW_Val; % BANDWIDTH -3dB
                         RMSBW(RowN,1) = RMSBW_Val; % RMS-BW
                        if app.HydN > 1 %&& exist(dataPoint.delays)
                              Bearing(RowN,1) = dataPoint.angles*57.2958;
                        end
%                         if RowN == 1
%                            ici = 0;
%                         else
%                            ici = (app.ClickParameters.startSample(RowN,1) - app.ClickParameters.startSample(RowN-1,1))/(app.Fs/1000); % in microseconds
%                          if ici < 0
%                            ici = (app.ClickParameters.Date(RowN,1) - app.ClickParameters.Date(RowN-1,1))*120*24; % in milliseconds
%                          end
%                         end
%                         app.ClickParameters.ICI(RowN,1) = ici;
                        % PORPOISE OR NOT?
                        CT(RowN,1) = 0;
                        b = fileName(end-56:end);
                        InFile{RowN,1} = b;
                        clear b 
                        ClickN(RowN,1) = click_id;
                        Class(RowN,1) = Porps_Val;
                     end % if Porps_Val ~= 3
                       end % switch
                    end  % while (1)
                    fclose(fid);
                end  % for Binary files
                     
                % Remove the ends
                ID(RowN+1:end, :) = [];
                Date(RowN+1:end, :) = [];
                startSample(RowN+1:end, :) = [];
                Duration(RowN+1:end, :) = []; % % DURATION
                Amp(RowN+1:end, :) = []; % MAXIMUM AMPLITUDE
                PF(RowN+1:end, :) = []; % PEAK FREQUENCY
                CF(RowN+1:end, :) = []; % CENTROID FREQUENCY
                BW(RowN+1:end, :) = []; % BANDWIDTH -3dB
                RMSBW(RowN+1:end, :) = [];
                if RowN < 2000000
                   ICI(RowN+1:end, :) = [];
                else
                   ICI(end+1:RowN, 1) = 0; 
                end 
                CT(RowN+1:end, :) = [];
                InFile(RowN+1:end, :) = [];
                ClickN(RowN+1:end, :) = [];
                Class(RowN+1:end, :) = [];
                              
                if app.HydN == 1
                    app.ClickParameters = table(ID, Date, startSample, Duration, Amp, PF, CF, BW, RMSBW, ICI, CT, InFile, ClickN, Class);
                    assignin('base', 'ClickParameters', app.ClickParameters)
                elseif app.NHyd == 2
                    Bearing(RowN+1:end, :) = [];
                    app.ClickParameters= table(ID, Date, startSample, Duration, Amp, PF, CF, BW, RMSBW, ICI, Bearing, CT, InFile, ClickN, Class);
                    assignin('base', 'ClickParameters', app.ClickParameters)
                end
             %S   app.ClickParameters(RowN + 1:end,:) = [];
%                         assignin('base', 'ClickParameters', app.ClickParameters)
%                         %% REMOVE CLICKS WITH THE SAME DATE
%                         ZeroRep = find(diff(app.ClickParameters.Date)==0);
%                     if size(ZeroRep,1) > 0
%                         d = 1;
%                    while size(d,1) > 0
%                         a = find(diff(app.ClickParameters.Date)==0);
%                         b = find(diff(app.ClickParameters.Amp) < -3);
%                         c=arrayfun(@(x) find(b==x,1),a,'UniformOutput',false);
%                         c = cell2mat(c);
%                         d = b(c);
%                         d = d + 1;
%                         % remove the same date but amp < first
%                         app.ClickParameters(d,:) = [];
%                         a = []; b = []; c = []; e = [];
%                    end
%                         d = 1;
%                    while size(d,1) > 0
%                         a = find(diff(app.ClickParameters.Date)==0);
%                         b = find(diff(app.ClickParameters.Amp) > 3);
%                         c=arrayfun(@(x) find(b==x,1),a,'UniformOutput',false);
%                         c = cell2mat(c);
%                         d = b(c);
%                         % remove the same date but amp < first
%                         app.ClickParameters(d,:) = [];
%                         a = []; b = []; c = []; e = [];
%                    end
%                          ICI = diff(app.ClickParameters.startSample)/(app.Fs/1000);
%                          app.ClickParameters.ICI(1,1) = 0;
%                          app.ClickParameters.ICI(2:end,1) = ICI;
%                     else
%                          ICI = diff(app.ClickParameters.startSample)/(app.Fs/1000);
%                          app.ClickParameters.ICI(1,1) = 0;
%                          app.ClickParameters.ICI(2:end,1) = ICI;
%                          
%                     end
                       
                        % ICI
                        app.ClickParameters.ICI(1,1) = 0;
                        app.ClickParameters.ICI(2:end,1) = diff(app.ClickParameters.startSample)/(app.Fs/1000);
                        app.ClickParameters.ICI(app.ClickParameters.ICI < 0) = 0;
                        app.ClickParameters.CPS(1:size(app.ClickParameters,1),1) = 1000./app.ClickParameters.ICI(1:end,1);
                        app.ClickParameters.CPS(1,1) = 0;     
                        assignin('base', 'ClickParameters', app.ClickParameters)
                        DateTime = datestr(app.ClickParameters.Date(1,1));
                        yyyy = DateTime(8:11); mmm = DateTime(4:6); dd = DateTime(1:2);
                        hh = DateTime(13:14); mm = DateTime(16:17); ss = DateTime(19:20);
                        DateTimeEnd = datestr(app.ClickParameters.Date(end,1));
                        hhEnd = DateTimeEnd(13:14); mmEnd = DateTimeEnd(16:17); ssEnd = DateTimeEnd(19:20);
                        FileDateTimePar = strcat(app.SelectedFolder,'\ClickParameters',yyyy,mmm,dd,'_',hh,mm,ss,'_',hhEnd,mmEnd,ssEnd,'.mat');
                        ClickParameters = app.ClickParameters;
                        save(FileDateTimePar, 'ClickParameters');
                        app.ClickParameters = []; ID = []; Date = []; startSample = []; Duration = []; Amp = []; PF = []; 
                        CF = []; BW = []; RMSBW = []; ICI = []; CT = []; InFile = []; ClickN = []; Class = [];
                        
                        % message to let know it's finished
                        message = sprintf('The file was now generated, you can start seeing the data.');
                        uialert(app.UIFigure,message,'Done!',...
                        'Icon','success');
               %         app.TabGroup.Enable = 'on';
                end
            
 
                        %% PAMGuard functions
                        function [filenames]= findBinaryFiles(folder, containsName)
                            %FINDBINARYFILES finds all binary menufile paths within a folder and subfolders
                            %   [FILENAMES]= FINDBINARYFILES(FOLDER) finds all .pgdf files within a
                            %   folder and it's sub folders. The function returns a cell array of
                            %   FILENAMES.
                            %   [FILENAMES]= FINDBINARYFILES(FOLDER, CONTAINSNAME) finds all .pgdf files
                            %   within a folder that all also contain a string CONTAINSNAME e.g. this
                            %   could be 'clicks' which would find all click binary files.
                            
                            %if nargin &lt; 2
                         containsName=[];
                            %end
                           
                            filenames={};
                            subFiles=dir(folder);
                            for i=1:length(subFiles)
                                   
                                 if (strcmp(subFiles(i).name,'.')==1 || strcmp(subFiles(i).name,'..')==1)
                                       continue;
                                 end
                                   
                                 if (subFiles(i).isdir==1)
                                       subFolderName=[folder,'\', subFiles(i).name];
                                       filenames=cat(2, filenames,findBinaryFiles(subFolderName,containsName));
                                 else
                                       binaryFileName=[folder,'\',subFiles(i).name];
                                       binaryChar=char(binaryFileName);
                                       fileEnd=binaryChar(length(binaryChar)-3: length(binaryChar));
                                         %add to binary menufile list
                                           
                                       if (strcmp(fileEnd,'pgdf')==1)
                                             if (isempty(containsName))
                                                  % if no contansName specified then add to list
                                                filenames=cat(2,filenames ,binaryFileName(1,:));
                                             elseif (~isempty(strfind(subFiles(i).name, containsName)))
                                                  %if a contansName is specified only add menufile if it contains
                                                  %the specified string
                                                  filenames=cat(2,filenames ,binaryFileName(1,:));
                                             end
                                       end
                                 end
                                    
                            end
                        end
                        
                        function header = readFileHeader(file, readExtra)
                            
                            if nargin < 2
                                readExtra = 0;
                            end
                            header.length = fread(file, 1, 'int32');
                            header.identifier = fread(file, 1, 'int32');
                            header.fileFormat = fread(file, 1, 'int32');
                            header.pamguard = char(fread(file, 12, 'uchar')');
                            header.version = readJavaUTFString(file);
                            header.branch = readJavaUTFString(file);
                            header.dataDate = millisToDateNum(fread(file, 1, 'int64'));
                            header.analysisDate = millisToDateNum(fread(file, 1, 'int64'));
                            header.startSample = fread(file, 1, 'int64');
                            header.moduleType = readJavaUTFString(file);
                            header.moduleName = readJavaUTFString(file);
                            header.streamName = readJavaUTFString(file);
                            header.extraInfoLen = fread(file, 1, 'int32');
                            if readExtra
                                header.extraInfo = fread(file, header.extraInfoLen, 'int8');
                            else
                                fseek(file, header.extraInfoLen, 'cof');
                            end
                        end
                        
                        function [str, len] = readJavaUTFString(file)
                            % function [str len] = readJavaUTFString(menufile);
                            % read a string written in Java UTF-8 format from a menufile.
                            % The first two bytes are the length of the string, then
                            % it's the string.
                            len = fread(file, 1, 'int16');
                            str = char(fread(file, len, 'uchar')');
                        end
                        
                        function datenum = millisToDateNum(millis)
                            datenum = double(millis)/86400000.0+719529;
                        end
                        
                        function header = readStdModuleHeader(file)
                            % reads the module header information common to all modules.  Differs from
                            % the legacy code in that it does not read in or skip any information
                            % specific to a module.
                            header.length = fread(file, 1, 'int32');
                            header.identifier = fread(file, 1, 'int32');
                            header.version = fread(file, 1, 'int32');
                            header.binaryLength = fread(file, 1, 'int32');
                        end
                        
                        function [data hasAnnotation] = readPamData(fid, fileInfo)
                            % Reads in the object data that is common to all modules.  This reads up to
                            % (but not including) the object binary length, and then calls a function
                            % to read the module-specific data.
                            % Inputs:
                            %   fid = menufile identifier
                            %   fileInfo = structure holding the menufile header, module header, a handle
                            %   to the function to read module-specific data, etc.
                            % Output:
                            %   data = structure containing data from a single object
                            % set constants to match flag bitmap constants in class
                            % DataUnitBaseData.java.  The following contstants match header version 4.
                            TIMEMILLIS           = hex2dec('1');
                            TIMENANOS            = hex2dec('2');
                            CHANNELMAP           = hex2dec('4');
                            UID                  = hex2dec('8');
                            STARTSAMPLE          = hex2dec('10');
                            SAMPLEDURATION       = hex2dec('20');
                            FREQUENCYLIMITS      = hex2dec('40');
                            MILLISDURATION       = hex2dec('80');
                            TIMEDELAYSSECS       = hex2dec('100');
                            HASBINARYANNOTATIONS = hex2dec('200');
                            
                            % initialize a new variable to hold the data
                            data=[];
                            data.flagBitmap=0;
                            hasAnnotation = 0;
                            
                            % calculate where the next object starts, in case there is an error trying
                            % to read this one
                            objectLen = fread(fid, 1, 'int32');
                            curObj = ftell(fid);
                            nextObj = curObj + objectLen;
                            
                            % first thing to check is that this is really the type of object we think
                            % it should be, based on the menufile header.  If not, warn the user, move the
                            % pointer to the next object, and exit
                            data.identifier = fread(fid, 1, 'int32');
                            if (any(data.identifier==fileInfo.objectType))
                                % do nothing here - couldn't figure out a clean way of checking if
                                % number wasn't in array
                            else
                                disp(['Error - Object Identifier does not match ' fileInfo.fileHeader.moduleType ' type.  Aborting data read.']);
                                fseek(fid, nextObj, 'bof');
                                return;
                            end
                        
                            % read the data, starting with the standard data that every data unit has
                            version=fileInfo.fileHeader.fileFormat;
                            try
                                data.millis = fread(fid, 1, 'int64');
                                
                                if (version == 2 || (bitand(data.flagBitmap, TIMENANOS)~=0) )
                                    data.timeNanos = fread(fid,1,'int64');
                                end
                                
                                if (bitand(data.flagBitmap, UID)==UID)
                                    data.UID = fread(fid,1,'int64');
                                end
                                
                                if (bitand(data.flagBitmap, STARTSAMPLE)~=0)
                                    data.startSample = fread(fid,1,'int64');
                                end
                                
                                if (bitand(data.flagBitmap, MILLISDURATION)~=0)
                                    data.millisDuration = fread(fid,1,'float');
                                end
                                
                                if (bitand(data.flagBitmap, TIMEDELAYSSECS)~=0)
                                    data.numTimeDelays = fread(fid,1,'int16');
                                    td=zeros(1, data.numTimeDelays);
                                    for i = 1:data.numTimeDelays
                                        td(i)=fread(fid,1,'float');
                                    end
                                    data.timeDelays=td;
                                end
                                
                                % set date, to maintain backwards compatibility
                                data.date = millisToDateNum(data.millis);
                                
                                % now read the module-specific data
                                if(isa(fileInfo.readModuleData,'function_handle'))
                                    [data, error] = fileInfo.readModuleData(fid, fileInfo, data);
                                    if (error)
                                        disp(['Error - cannot retrieve ' fileInfo.fileHeader.moduleType ' data properly.']);
                                        fseek(fid, nextObj, 'bof');
                                        return;
                                    end
                                end
                                % now check to see if there are standard annotations to the main data.
                                if (bitand(data.flagBitmap, HASBINARYANNOTATIONS)~=0)
                                    hasAnnotation = 1;
                                    anStart = ftell(fid);
                                    anTotLength = fread(fid, 1, 'int16');
                                    nAn = fread(fid, 1, 'int16');
                                    for i = 1:nAn
                                        filePos = ftell(fid);
                                         anLength = fread(fid, 1, 'int16')-2; %tis length does no tinclude itself !
                                         anId = readJavaUTFString(fid);
                                         anVersion = fread(fid, 1, 'int16');
                                         switch (anId)
                                             case 'Beer'
                                                 data.annotations.beamAngles = readBeamFormerAnnotation(fid, anId, anLength, fileInfo, anVersion);
                                             case 'TMAN'
                                                 data.annotations.targetMotion = readTMAnnotation(fid, anId, anLength, fileInfo, anVersion);
                                             case 'TDBL'
                                                 data.annotations.toadAngles = readTDBLAnnotation(fid, anId, anLength, fileInfo, anVersion);
                                             otherwise
                                                 fprintf('Unknown anotation type "%s" length %d version %d in file\n', ...
                                                     anId, anLength, anVersion);
                                                 fseek(fid, filePos+anLength, 'bof');
                                         end
                                         endPos = ftell(fid);
                                         if (endPos ~= filePos+anLength)
                                              disp('Possible annotation read size error in file')
                                              fseek(fid, filePos+anLength, 'bof');
                                              endPos = ftell(fid);
                                         end
                                    end
                                    if (endPos ~= anStart+anTotLength)
                                        fseek(fid, anStart+anTotLength, 'bof');
                                    end
                                else
                                    data.annotations = [];
                                end
                                
                            catch mError
                                disp('Error loading object data');
                                disp(data);
                                disp(getReport(mError));
                                fseek(fid, nextObj, 'bof');
                            end
                        end
                        
                        function array = checkArrayAllocation(array, reqLength, sampleObject)
                            %global array
                            if isempty(array)
                                currentLength = 0;
                                array = [];
                            else
                                currentLength = numel(array);
                            end
                            if (currentLength >= reqLength)
                                return;
                            end
                            allocStep = round(sqrt(reqLength));
                            allocStep = max(10, min(allocStep, 10000));
                            array(reqLength + allocStep) = sampleObject;
                            return;
                        end
                        
                        function [data, error] = readClickData(fid, fileInfo, data)
                            error=false;
                            
                            try
                                
                                % read click detector specific data
                                dataLength = fread(fid, 1, 'int32');
                                if (dataLength==0)
                                    return;
                                end
                                
                                if (fileInfo.moduleHeader.version<=3)
                                    data.startSample = fread(fid, 1, 'int64');
                                    data.channelMap = fread(fid, 1, 'int32');
                                end
                                
                                data.triggerMap = fread(fid, 1, 'int32');
                                data.type = fread(fid, 1, 'int16');
                                if (fileInfo.moduleHeader.version >= 2)
                                    data.flags = fread(fid, 1, 'int32');
                                else
                                    data.flags = 0;
                                end
                                
                                if (fileInfo.moduleHeader.version <= 3)
                                    nDelays = fread(fid, 1, 'int16');
                                    if (nDelays)
                                        data.delays = fread(fid, nDelays, 'float');
                                    end
                                end
                                
                                nAngles = fread(fid, 1, 'int16');
                                if (nAngles)
                                    data.angles = fread(fid, nAngles, 'float');
                                end
                                
                                if (fileInfo.moduleHeader.version >= 3)
                                    nAngleErrors = fread(fid, 1, 'int16');
                                    data.angleErrors = fread(fid, nAngleErrors, 'float');
                                else
                                    data.angleErrors = [];
                                end
                                
                                if (fileInfo.moduleHeader.version <= 3)
                                    data.duration = fread(fid, 1, 'int16');
                                else
                                    data.duration = data.sampleDuration;    % duplicate the value to maintain backwards compatibility
                                end
                                
                                data.nChan = countChannels(data.channelMap);
                                maxVal = fread(fid, 1, 'float');
                                data.wave = fread(fid, [data.duration,data.nChan], 'int8') * maxVal / 127;
                                
                            catch mError
                                disp(['Error reading ' fileInfo.fileHeader.moduleType '  data object.  Data read:']);
                                disp(data);
                                disp(getReport(mError));
                                error=true;
                            end
                        end
                        
                        function nC = countChannels(channelMap)
                            % function nC = countChannels(channelMap)
                            % count the numebr of set bits in the channel map
                            nC = 0;
                            j = 1;
                            for i = 1:32
                                if (bitand(channelMap, j))
                                    nC = nC + 1;
                                end
                                j = j * 2;
                            end
                        end
                        
                        function footer = readClickFooter(file)
                            % reads module footer information for the Click Detector module.  Note that
                            % sometimes there is no additional footer information, so check first
                            % whether or not the binaryLength variable is 0.
                            
                            footer=readStdModuleFooter(file);
                            if (footer.binaryLength ~= 0)
                                footer.typesCountLength = fread(file, 1, 'int16');
                                footer.typesCount = fread(file, footer.typesCountLength, 'int32');
                            end
                        end
                        
                        function footer = readStdModuleFooter(file)
                            % reads the module footer information common to all modules.  Differs from
                            % the legacy code in that it does not read in or skip any information
                            % specific to a module.
                            
                            footer.length = fread(file, 1, 'int32');
                            footer.identifier = fread(file, 1, 'int32');
                            footer.binaryLength = fread(file, 1, 'int32');
                        end
                        
                        function footer = readFileFooterInfo(fid,version)
                            footer.length = fread(fid, 1, 'int32');
                            footer.identifier = fread(fid, 1, 'int32');
                            footer.nObjects = fread(fid, 1, 'int32');
                            footer.dataDate = millisToDateNum(fread(fid, 1, 'int64'));
                            footer.analysisDate = millisToDateNum(fread(fid, 1, 'int64'));
                            footer.endSample = fread(fid, 1, 'int64');
                            if (version>=3)
                                footer.lowestUID = fread(fid, 1, 'int64');
                                footer.highestUID = fread(fid, 1, 'int64');
                            end
                            footer.fileLength = fread(fid, 1, 'int64');
                            footer.endReason = fread(fid, 1, 'int32');
                        end
                        
        end

        % Menu selected function: OpenexistingprojectMenu
        function OpenCT(app, event)
            OpenCTFig = uifigure('Position', [350 350 260 150]);
            OpenCTFig.Name = 'Open CT Project';
            OpenCTFig.MenuBar = 'none';    % Hide standard menu bar menus.
            OpenCTFig.Resize='off';
            OpenCTFig.Visible = 'on';
            % create a panel inside the figure
            OpenCTPan = uipanel('Parent', OpenCTFig,'Units','pixels', 'Position',[10 10 240 130]);
            % label "Select folder"
            SelectFolderText = uilabel(OpenCTPan,'Position', [10 100 120 20]);
            SelectFolderText.Text = 'Select Folder';
            % edit - selected folder to be read
            FolderPathOpenCT = uieditfield('Parent', OpenCTPan,...
                'Position', [10 80 220 20], ...
                "Enable","on",...
                "Value",'C:\Users\');
            % browse button
            BrowseButton = uibutton(OpenCTPan, 'push',...
                'ButtonPushedFcn', @(BrowseButton,event) PushBrowseButton(BrowseButton));
            BrowseButton.Position = [150 55 80 20];
            BrowseButton.Text = 'Browse';
            
       function PushBrowseButton(BrowseButton, event)
           folder = fullfile(matlabroot, 'C:\Users\');
           app.SelectedFolder = uigetdir(folder);
       if app.SelectedFolder == 0
       	           return;
       end
           FolderPathOpenCT.Value = app.SelectedFolder;
       end
            
            % OK button
            OKButton = uibutton(OpenCTPan, 'push',...
                'ButtonPushedFcn', @(OKButton,event) PushOKButton(app,OKButton));
            OKButton.Position = [10 10 80 20];
            OKButton.Text = 'OK';
            
       function PushOKButton(OKButton, event)
            OpenCTFig.Visible = 'off';
            app.AllButton.Value = 1;
            ClickParNames = strcat(app.SelectedFolder,'\ClickParametersTested.mat');
            ClickParFilesInFolder = dir(ClickParNames);
            app.NumParMatFiles = size(ClickParFilesInFolder,1);
            ClickParFileName = ClickParFilesInFolder(1).name;
            app.ClickParFile = strcat(app.SelectedFolder, '\', ClickParFileName);
            %load(ClickParFile);
            TempClickParameters = load(app.ClickParFile);
            app.ClickParameters = TempClickParameters.ClickParameters;
            TempClickParameters = [];
            CTInfoFile = strcat(app.SelectedFolder, '\CTInfoNew.mat');
            %load(ClickParFile);
            CTInfoTemp = load(CTInfoFile);
            app.CTInfo = CTInfoTemp.CTInfo;
            app.CTInfo2 = app.CTInfo;
            CTInfoTemp = [];
            CTNum = app.CTInfo(1).CTNum;
            UpdateCT(app, CTNum)
       end
                        
            % Cancel button
            CancelButton = uibutton(OpenCTPan, 'push',...
                'ButtonPushedFcn', @(CancelButton,event) PushCancelButton(app, CancelButton));
            CancelButton.Position = [150 10 80 20];
            CancelButton.Text = 'Cancel';
      function PushCancelButton(CancelButton, event)
      OpenCTFig.Visible = 'off';
      end
                        
        end

        % Button pushed function: IndClicksAnd3D
        function SeeClicksCB(app, event)
             %  BasicWaitbar(app)
                SeeClicksFig.Visible = 'off';
                CTNum = app.CTNumD.Value;
                app.CTTemp = app.ClickParameters(app.ClickParameters.CT == CTNum, :);
                PosICI = find(app.CTTemp.ICI ~= 0);
                app.Fs = app.CTTemp.ICI(PosICI(3),1)/(1000*(app.CTTemp.startSample(PosICI(3),1) - app.CTTemp.startSample(PosICI(3)-1,1)));
                app.Fs = 1/app.Fs;

                InFile = app.CTTemp.InFile(1,1);
                InFile = InFile{1,1};
                InFile = strcat(app.SelectedFolder, '\', InFile);
                 
                %% GET CLICK TRAINS WITH THE CLICKS %%
                RowN = 0;
                startC = app.CTTemp.ClickN(1,1);
                endC = app.CTTemp.ClickN(end,1);
                        
                app.clickfiles = findBinaryFiles(app.SelectedFolder, 'click');
               % Now we have a list of all files in this folder.
                app.NumberBinaryFiles = length(app.clickfiles);
               
              %set(handles.LoadPanel, 'Visible', 'on')
              for n=1:app.NumberBinaryFiles %each pgdf file
                     ClickN = 0;
                    %LOAD THE FILES
                    fileName = app.clickfiles{n};
                    ab = strcmp(fileName, InFile);
                    if ab == 0
                        % nothing
                    else
                            
                            fid = fopen(fileName, 'r', 'ieee-be.l64');
                            % initialize variables
                            prevPos = -1;
                            fileInfo = [];
                            fileInfo.readModuleHeader=@readStdModuleHeader;
                            fileInfo.readModuleFooter=@readStdModuleFooter;
                            % main loop
                            while (1)
                                    % if for some reason we're stuck at one byte, warn the user and
                                    % abort
                                  pos = ftell(fid);
                                if (pos == prevPos)
                                       disp(fprintf('File stuck at byte %d', pos));
                                       break;
                                end
                                  prevPos = pos;
                           
                                    % read in the length of the object and the type. Move the menufile
                                    % pointer back to the beginning of the length variable, and switch
                                    % on the type.  If we couldn't read nextLen or nextType, assume
                                    % that means we've hit the end of the menufile and break out of loop
                                    [~, nL] = fread(fid, 1, 'int32');
                                    [nextType, nT] = fread(fid, 1, 'int32');
                                  if (nL == 0 || nT == 0)
                                         break;
                                  end
                                    fseek(fid, -8, 'cof');
                                    switch nextType
                               
                                            % Case 1: MenuFile Header. Read in the menufile header, and then set
                                            % the function handles depending on what type of data the
                                            % binary menufile holds. The module type is typically specified in
                                            % the package class that extends PamControlledUnit, and is a
                                            % string unique to that module.
                                           case -1
                                                  fileInfo.fileHeader = readFileHeader(fid);
                                                 switch fileInfo.fileHeader.moduleType
                                                        % Click Detector Module
                                                        case 'Click Detector'
                                                               switch fileInfo.fileHeader.streamName
                                                                      case 'Clicks'
                                                                             fileInfo.objectType=1000;
                                                                             fileInfo.readModuleData=@readClickData;
                                                                             fileInfo.readModuleFooter=@readClickFooter;
                                                                      case 'Trigger Background'
                                                                             fileInfo.objectType=0;
                                                                             fileInfo.readModuleData=@readClickTriggerData;
                                                                             fileInfo.readModuleHeader=@readClickTriggerHeader;
                                                               end
                                                         otherwise
                                                              disp(['Don''t recognize type ' fileInfo.fileHeader.moduleType '.  Aborting load']);
                                                              break;
                                                 end
                                                    % Case 2: MenuFile Footer. The menufile version should have been set
                                                    % when we read the menufile header. If the menufile header is empty,
                                                    % something has gone wrong so warn the user and exit
                                          case -2
                                                 if (isempty(fileInfo.fileHeader))
                                                        disp('Error: found file footer before file header.  Aborting load');
                                                        break;
                                                 end
                                                 fileInfo.fileFooter = readFileFooterInfo(fid, fileInfo.fileHeader.fileFormat);
                                                
                                                    % Case 3: Module Header.  The correct function handle should
                                                    % have been set when we read the menufile header.  If the menufile
                                                    % header is empty, something has gone wrong so warn the user
                                                    % and exit
                                          case -3
                                                 if (isempty(fileInfo.fileHeader))
                                                       disp('Error: found module header before file header.  Aborting load');
                                                       break;
                                                 end
                                                    %   assingin('base', 'fileInfo', fileInfo)
                                                 fileInfo.moduleHeader = fileInfo.readModuleHeader(fid);
                                                
                                                    % Case 4: Module Footer.  The correct function handle should
                                                    % have been set when we read the menufile header.  If the menufile
                                                    % header is empty, something has gone wrong so warn the user
                                                    % and exit
                                          case -4
                                                 if (isempty(fileInfo.fileHeader))
                                                        disp('Error: found module footer before file header.  Aborting load');
                                                        break;
                                                 end
                                                 fileInfo.moduleFooter = fileInfo.readModuleFooter(fid);
                                                   
                                                    % Case 5: Data.  The correct function handle should have been
                                                    % set when we read in the menufile header.  If the menufile header is
                                                    % empty, something has gone wrong so warn the user and exit
                                          otherwise
                                                 if (isempty(fileInfo.fileHeader))
                                                        disp('Error: found data before file header.  Aborting load');
                                                        break;
                                                 end
                                                  dataPoint = readPamData(fid, fileInfo);
                                                 
                                                  b = isfield(dataPoint, 'wave');
                                                  if b == 0
                                                       continue
                                                  else
                                                  end
                                                    % use the hydrophone where the click impinges first, because it
                                                    % is expected to be of best quality
                                                  if app.HydN > 1
                                                         if dataPoint.delays < 0
                                                                click = dataPoint.wave(:,1);
                                                         else
                                                                click = dataPoint.wave(:,2);
                                                         end
                                                  else
                                                         click = dataPoint.wave;
                                                  end
                                                                
                                                  ClickN = ClickN + 1;
                                                  if ClickN >= startC && ClickN <= endC
                                                       RowN = RowN + 1;
                                                       app.signalCT(RowN).ClickN = ClickN;
                                                       app.signalCT(RowN).wave = click;
                                                  else
                                                      % nothing
                                                  end
                                                  
                                    end % switch
                             end % while(1)
                    end           % if file name == file in folder
                                
              end % n = 1:
              fclose(fid);
            
            %%
            a = app.CTTemp.ClickN(:,1);
            b = [app.signalCT.ClickN];
            c=arrayfun(@(x) find(b==x,1),a,'UniformOutput',false);
            c = cell2mat(c);
            app.signalCT = app.signalCT(c);
 
            %% Now create the figure to plot stuff
            SeeClicksFig = uifigure('Position',[150 150 1000 530], 'Name',...
                'Clicks within CT', 'NumberTitle', 'off');
            SeeClicksFig.MenuBar = 'none';    % Hide standard menu bar menus.
            SeeClicksFig.Resize='off';
            SeeClicksFig.Visible = 'on';
                        
            SeeClicksPan = uipanel('Parent',SeeClicksFig,'Units','pixels',...
                    'Position',[10 10 480 510]);
            % Panel upper
            SeeClicksUpp = uipanel('Parent',SeeClicksPan,'Units','pixels',...
                  'Position',[10 410 460 90]);
              
            % Left side (Click trains)
            SeeClicksUppLeft = uipanel('Parent',SeeClicksUpp,'Units','pixels',...
                  'Position',[10 10 140 70]);
            SeeClicksUppLeft.Title = 'Click train';
            SeeClicksUppLeft.TitlePosition = 'centertop';
            uilabel(SeeClicksUppLeft, 'Text', 'CT Num', 'Position', [15 25 80 20]);
            CTNum3D = uilabel(SeeClicksUppLeft, 'Position', [20 5 50 20]);
            CTNum3D.Text = num2str(app.CTNumD.Value);
            uilabel(SeeClicksUppLeft, 'Text', 'Length', 'Position', [80 25 45 20]);
            LengthCT = uilabel(SeeClicksUppLeft, 'Position', [85 5 50 20]);
            LengthCT.Text = app.LengthLabel.Text;
            
            % Middle (Clicks)
            SeeClicksUppMiddle = uipanel('Parent',SeeClicksUpp,'Units','pixels',...
                  'Position',[160 10 140 70]);
            SeeClicksUppMiddle.Title = 'Click';
            SeeClicksUppMiddle.TitlePosition = 'centertop';
            uilabel(SeeClicksUppMiddle, 'Text', 'Type', 'Position', [15 25 80 20]);
            ClickType = uilabel(SeeClicksUppMiddle, 'Text', 'HQ', 'Position', [17 5 50 20]);
            uilabel(SeeClicksUppMiddle, 'Text', 'Number', 'Position', [73 25 80 20]);
             % Back button
            BackButton = uibutton(SeeClicksUppMiddle, 'push',...
               'ButtonPushedFcn', @(BackButton,event) PushBackButton(BackButton));
            BackButton.Position = [60 5 20 20];
            BackButton.Text = '<';
            % edit - selected folder to be read
            ClickNumEdit= uieditfield('Parent', SeeClicksUppMiddle,...
                'HorizontalAlignment', 'center',...
                'Position', [80 5 30 20], ...
                "Enable","on",...
                "Value", '1');
            ClickNum = 1;%ClickNumEdit.Value;
            % forward button
            ForwButton = uibutton(SeeClicksUppMiddle, 'push',...
               'ButtonPushedFcn', @(ForwButton,event) PushForwButton(ForwButton));
            ForwButton.Position = [110 5 20 20];
            ForwButton.Text = '>';
            
            % Right side (delete/save)
            SeeClicksUppRight = uipanel('Parent',SeeClicksUpp,'Units','pixels',...
                  'Position',[310 10 140 70]);
            SeeClicksUppRight.Title = 'Delete/Save';
            SeeClicksUppRight.TitlePosition = 'centertop';
            DelClick = uibutton(SeeClicksUppRight, 'push',...
               'ButtonPushedFcn', @(DelClick,event) DelClickFn(DelClick));
            DelClick.Position = [10 10 55 30];
            DelClick.Text = 'DEL';
            
             function DelClickFn(DelClick, event)
                ClickNum = str2double(ClickNumEdit.Value);
                ID = app.CTTemp.ID(ClickNum,1);
                app.CTTemp(ClickNum,:) = [];
                app.CTInfo([app.CTInfo.CTNum] == CTNum).Length = [app.CTInfo([app.CTInfo.CTNum] == CTNum).Length] - 1;
                app.CTTemp = NewICI(app, app.CTTemp, app.Fs);
                app.ClickParameters.ID(app.ClickParameters.ID == ID, 1) = -CTNum;
                UpdateIndClick()
             end
           
            SaveCTb = uibutton(SeeClicksUppRight, 'push',...
               'ButtonPushedFcn', @(SaveCTb,event) PushSaveButton(SaveCTb));
            SaveCTb.Position = [75 10 55 30];
            SaveCTb.Text = 'SAVE';
            

            function PushSaveButton(SaveCTb, event)
                ClickParameters = app.ClickParameters;
                save(app.ClickParFile, 'ClickParameters');
                ClickParameters = []; 
            end
                        
            %% Create axes as in Cosentino et al 2019
            % [ L U W H ]
            % Amplitude
            AmpAx = uiaxes(SeeClicksPan);
            title(AmpAx, 'Amplitude (dB)')
            xlabel(AmpAx, '')
            ylabel(AmpAx, '')
            %AmpAx.XTick = [];
            AmpAx.Position = [30 280 450 125];
            %disableDefaultInteractivity(AmpAx)
            % top
            WignerPlot = uiaxes(SeeClicksPan);
            WignerPlot.TickDirMode = 'manual';
            WignerPlot.XTick = [];
       %     WignerPlot.YLim = [1, 250];
            WignerPlot.Position = [30 150 350 120];
            WignerPlot.YDir = 'reverse';
         %   disableDefaultInteractivity(WignerPlot)
            %zoom(WignerPlot, 'off')
            % right
            PowerSpec = uiaxes(SeeClicksPan);
            PowerSpec.XTick = [];
            PowerSpec.YLim = [1,250];
            PowerSpec.Position = [380 150 80 115];
          %  disableDefaultInteractivity(PowerSpec)
            %zoom(PowerSpec, 'off')
            % bottom
            WaveformC = uiaxes(SeeClicksPan);
            %WaveformC.XTick = [];
            WaveformC.YTick = 0;
            WaveformC.Position = [50 30 330 120];
         %   disableDefaultInteractivity(WaveformC)
            %zoom(WaveformC, 'off')
            
            %% WATERFALL
            WaterfallPan = uipanel('Parent',SeeClicksFig,'Units','pixels',...
                   'Position',[510 10 480 510]);
            WaterfallAx = uiaxes(WaterfallPan);
            WaterfallAx.Position = [30 30 400 350];
            disableDefaultInteractivity(WaterfallAx)
            
            % Labels
            uilabel(WaterfallPan, 'Position', [10 460 80 30], 'Text', 'Min');
            uilabel(WaterfallPan, 'Position', [10 420 80 30], 'Text', 'Max');
            
            
            % Slider X
            XLimMin = 0;
            SliderX = uislider(WaterfallPan, ...
                'ValueChangingFcn',@(SliderX,event) UpdateX(event,XLimMin));
            SliderX.Position = [50 460 80 30];
            SliderX.Limits = [0, 250];
            SliderX.Value = 0;
            function UpdateX(SliderX,event)
                XLimMin = SliderX.Value;
                    UpdateWaterfall(XLimMin, YLimMin, ZLimMin, XLimMax, YLimMax, ZLimMax)
            end
            
            XLimMax = 250;
            SliderX1 = uislider(WaterfallPan, ...
                'ValueChangingFcn',@(SliderX1,event) UpdateX1(event,XLimMax));
            SliderX1.Position = [50 420 80 30];
            SliderX1.Limits = [0, 250];
            SliderX1.Value = 250;
            uilabel(WaterfallPan, 'Position', [50 475 100 30], 'Text', 'Frequency in kHz (X)');
            
            % Create ValueChangedFcn callback
            function UpdateX1(SliderX1,event)
                XLimMax = SliderX1.Value;
                    UpdateWaterfall(XLimMin, YLimMin, ZLimMin, XLimMax, YLimMax, ZLimMax)
            end
            
            % Slider Y
            YLimMin = 0;
            SliderY1 = uislider(WaterfallPan, ...
                'ValueChangingFcn',@(SliderY1,event) UpdateY1(event,YLimMin));
            SliderY1.Position = [200 460 100 30];
            SliderY1.Limits = [0, 10];
            SliderY1.Value = 0;
           % Create ValueChangedFcn callback
            function UpdateY1(SliderY1,event)
                YLimMin = SliderY1.Value;
                    UpdateWaterfall(XLimMin, YLimMin, ZLimMin, XLimMax, YLimMax, ZLimMax)
            end
            
            YLimMax = 5;
            LengthT = 25;%app.CTInfo([app.CTInfo.CTNum] == CTNum).LengthTime;
            SliderY = uislider(WaterfallPan, ...
                'ValueChangingFcn',@(SliderY,event) UpdateY(event,YLimMax));
            SliderY.Position = [200 420 100 30];
            SliderY.Limits = [0, LengthT];
            SliderY.Value = LengthT;
            uilabel(WaterfallPan, 'Position', [200 475 100 30], 'Text', 'Time in sec (Y)');

            % Create ValueChangedFcn callback
            function UpdateY(SliderY,event)
                YLimMax = SliderY.Value;
                    UpdateWaterfall(XLimMin, YLimMin, ZLimMin, XLimMax, YLimMax, ZLimMax)
            end
            
            % Slider Z
            ZLimMin = 0;
            SliderZ = uislider(WaterfallPan, ...
                'ValueChangingFcn',@(SliderZ,event) UpdateZ(event,ZLimMin));
            SliderZ.Position = [350 460 80 30];
            SliderZ.Limits = [0, 1];
            SliderZ.Value = 0;
            uilabel(WaterfallPan, 'Position', [350 475 100 30], 'Text', 'Amplitude (Z)');
            
            % create function
            function UpdateZ(SliderZ,event)
                ZLimMax = SliderZ.Value;
                    UpdateWaterfall(XLimMin, YLimMin, ZLimMin, XLimMax, YLimMax, ZLimMax)
            end
            ZLimMax = 1;
            SliderZ1 = uislider(WaterfallPan, ...
                'ValueChangingFcn',@(SliderZ1,event) UpdateZ1(event,ZLimMax));
            SliderZ1.Position = [350 420 80 30];
            SliderZ1.Limits = [0, 1];
            SliderZ1.Value = 1;
            
            % create function
            function UpdateZ1(SliderZ1,event)
                ZLimMax = SliderZ1.Value;
                    UpdateWaterfall(XLimMin, YLimMin, ZLimMin, XLimMax, YLimMax, ZLimMax)
            end
            
            UpdateIndClick()
           
            %% functions of buttons
            function PushBackButton(BackButton, event)
               ClickNum = str2double(ClickNumEdit.Value);
               if ClickNum > 1
                   ClickNum = ClickNum - 1;
                   UpdateIndClick()
                   ClickNumEdit.Value = num2str(ClickNum);
               else
                   UpdateIndClick()
                   ClickNumEdit.Value = num2str(ClickNum);
               end
            end

            function PushForwButton(ForwButton, event)
               LengthCT = length(app.signalCT);
               ClickNum = str2double(ClickNumEdit.Value);
               if ClickNum < LengthCT
                   ClickNum = ClickNum + 1;
                   ClickNumEdit.Value = num2str(ClickNum);
                   UpdateIndClick()
               else
                   ClickNumEdit.Value = num2str(ClickNum);
                   UpdateIndClick()
               end
            end

            function UpdateIndClick               
                % CLICK TRAIN
                % Amplitude
                CT1HQ = app.CTTemp(app.CTTemp.Class == 1,:);
                CT1LQ = app.CTTemp(app.CTTemp.Class == 2,:);
                
                % plot amplitude
                cla(AmpAx, 'reset')
                stem(AmpAx, CT1HQ.Date, CT1HQ.Amp, 'marker', '.', 'color', 'red')
                hold(AmpAx, 'on');
                stem(AmpAx, CT1LQ.Date, CT1LQ.Amp, 'marker', '.', 'color', [0.0, 0.502, 1.00]) %[0.5843 0.8157 0.9882])
                hold(AmpAx, 'on');
                plot(AmpAx, app.CTTemp.Date(ClickNum,1),app.CTTemp.Amp(ClickNum,1), 'o')
                hold(AmpAx, 'off');
                YLimAmpMin = 80;
                MaxAmp = max(app.CTTemp.Amp) + 15;
                YLimAmpMax = MaxAmp;
                ylim(AmpAx, [YLimAmpMin, YLimAmpMax])
                XLimMinAmp = app.CTTemp.Date(1,1);
                XLimMaxAmp = app.CTTemp.Date(end,1);
                xlim(AmpAx, [XLimMinAmp, XLimMaxAmp])
                datetick(AmpAx, 'x', 'HH:MM:SS', 'keeplimits')
                
                
                %% INDIVIDUAL CLICKS
                FullSignal = app.signalCT(ClickNum).wave;
                [PSD, f] = periodogram(FullSignal,[],[],app.Fs,'power');
                assignin('base', 'FullSignal', FullSignal)

                % waveform
                int = 1000000/app.Fs;
                dur = int*length(FullSignal);
                t = 1:int:dur;
                cla(WaveformC, 'reset')
                plot(WaveformC, t, FullSignal)
             %   WaveformC.XLim = [0,size(FullSignal,1)];
                WaveformC.XLabel.String = 'Time (µs)';
                WaveformC.YTick = [];
                
                % power spectrum
                cla(PowerSpec, 'reset')
                plot(PowerSpec, PSD, f/1000)
                PowerSpec.XTick = [];
                PowerSpec.YTick = [];
               % PowerSpec.YLim = [0, 250000];
                
                % Wigner plot
                FFT = 512;
                windowSize=32;
                overlap=round(windowSize*0.80);
                [s, f1, t] = spectrogram(FullSignal(:,1),windowSize,overlap,FFT,app.Fs,'yaxis');
                imagesc(WignerPlot, t, f1/1000, log(1+abs(s)))
              %  WignerPlot.XLim = [0,(size(FullSignal,1)-50)];
                
                Type = app.CTTemp.Class(ClickNum,1);
               if Type == 1
                   Type = 'HQ';
               elseif Type == 2
                   Type = 'LQ';
               end
                ClickType.Text = Type;
            end
            
           %% WATERFALL PLOT
            FFT = 512;
            
            click = app.signalCT(1).wave;
            [~,f] = periodogram(click,[],FFT,app.Fs,'power');
            
            %new cumulativive ici
            app.CTTemp.iciSum(1,1) = 0;
            for i = 2:length(app.signalCT)
                app.CTTemp.iciSum(i,1) = app.CTTemp.ICI(i,1) + app.CTTemp.iciSum(i-1,1);
            end
            
            % CREATE empty x, y, and z for the waterfall plot
            x1(length(app.signalCT),257) =0; % frequency in every row
            y1(length(app.signalCT),257) =0; % 1:97 in each column
            z1(length(app.signalCT),257) =0; % power of each click in each row
            
            % FILL the variables
            % X = frequency
            clear i
            for i = 1:length(app.signalCT)
                 x1(i,:) = (f)/1000;
            end
            % Y = time (in secs)
            clear i
            for i = 1:257
                 y1(:,i) = [app.CTTemp.iciSum(1:end,1)];
            end
            y1 = y1/1000;
            % Normalised Amplitude
            clear i
            for i = 1:length(app.signalCT)
                 signal = app.signalCT(i).wave;
                 [PSD,~]=periodogram(signal(:,1),[],FFT,app.Fs,'power');
                 z1(i,:) = PSD;
            end
            a = max(z1) ;
            a = max(a) ;
            z1 = z1./a;
            
            waterfall(WaterfallAx, x1, y1, z1)
            WaterfallAx.Title.String = 'Waterfall plot - Click train';
            WaterfallAx.XLabel.String = 'Frequency (kHz)';
            WaterfallAx.YLabel.String = 'Time (s)';
            WaterfallAx.ZLabel.String = 'Relative amplitude';
                    
            function UpdateWaterfall(XLimMin, YLimMin, ZLimMin, XLimMax, YLimMax, ZLimMax)
                WaterfallAx.XLim = [XLimMin, XLimMax];
                WaterfallAx.YLim = [YLimMin, YLimMax];
                WaterfallAx.ZLim = [ZLimMin, ZLimMax];
                WaterfallAx.ZTick = [];
            end
                        
                        %% PAMGuard functions
                        function [filenames]= findBinaryFiles(folder, containsName)
                            %FINDBINARYFILES finds all binary menufile paths within a folder and subfolders
                            %   [FILENAMES]= FINDBINARYFILES(FOLDER) finds all .pgdf files within a
                            %   folder and it's sub folders. The function returns a cell array of
                            %   FILENAMES.
                            %   [FILENAMES]= FINDBINARYFILES(FOLDER, CONTAINSNAME) finds all .pgdf files
                            %   within a folder that all also contain a string CONTAINSNAME e.g. this
                            %   could be 'clicks' which would find all click binary files.
                            
                            %if nargin &lt; 2
                         containsName=[];
                            %end
                           
                            filenames={};
                            subFiles=dir(folder);
                            for i=1:length(subFiles)
                                   
                                 if (strcmp(subFiles(i).name,'.')==1 || strcmp(subFiles(i).name,'..')==1)
                                       continue;
                                 end
                                   
                                 if (subFiles(i).isdir==1)
                                       subFolderName=[folder,'\', subFiles(i).name];
                                       filenames=cat(2, filenames,findBinaryFiles(subFolderName,containsName));
                                 else
                                       binaryFileName=[folder,'\',subFiles(i).name];
                                       binaryChar=char(binaryFileName);
                                       fileEnd=binaryChar(length(binaryChar)-3: length(binaryChar));
                                         %add to binary menufile list
                                           
                                       if (strcmp(fileEnd,'pgdf')==1)
                                             if (isempty(containsName))
                                                  % if no contansName specified then add to list
                                                filenames=cat(2,filenames ,binaryFileName(1,:));
                                             elseif (~isempty(strfind(subFiles(i).name, containsName)))
                                                  %if a contansName is specified only add menufile if it contains
                                                  %the specified string
                                                  filenames=cat(2,filenames ,binaryFileName(1,:));
                                             end
                                       end
                                 end
                                    
                            end
                        end
                        
                        function header = readFileHeader(file, readExtra)
                            
                            if nargin < 2
                                readExtra = 0;
                            end
                            header.length = fread(file, 1, 'int32');
                            header.identifier = fread(file, 1, 'int32');
                            header.fileFormat = fread(file, 1, 'int32');
                            header.pamguard = char(fread(file, 12, 'uchar')');
                            header.version = readJavaUTFString(file);
                            header.branch = readJavaUTFString(file);
                            header.dataDate = millisToDateNum(fread(file, 1, 'int64'));
                            header.analysisDate = millisToDateNum(fread(file, 1, 'int64'));
                            header.startSample = fread(file, 1, 'int64');
                            header.moduleType = readJavaUTFString(file);
                            header.moduleName = readJavaUTFString(file);
                            header.streamName = readJavaUTFString(file);
                            header.extraInfoLen = fread(file, 1, 'int32');
                            if readExtra
                                header.extraInfo = fread(file, header.extraInfoLen, 'int8');
                            else
                                fseek(file, header.extraInfoLen, 'cof');
                            end
                        end
                        
                        function [str, len] = readJavaUTFString(file)
                            % function [str len] = readJavaUTFString(menufile);
                            % read a string written in Java UTF-8 format from a menufile.
                            % The first two bytes are the length of the string, then
                            % it's the string.
                            len = fread(file, 1, 'int16');
                            str = char(fread(file, len, 'uchar')');
                        end
                        
                        function datenum = millisToDateNum(millis)
                            datenum = double(millis)/86400000.0+719529;
                        end
                        
                        function header = readStdModuleHeader(file)
                            % reads the module header information common to all modules.  Differs from
                            % the legacy code in that it does not read in or skip any information
                            % specific to a module.
                            header.length = fread(file, 1, 'int32');
                            header.identifier = fread(file, 1, 'int32');
                            header.version = fread(file, 1, 'int32');
                            header.binaryLength = fread(file, 1, 'int32');
                        end
                        
                        function [data hasAnnotation] = readPamData(fid, fileInfo)
                            % Reads in the object data that is common to all modules.  This reads up to
                            % (but not including) the object binary length, and then calls a function
                            % to read the module-specific data.
                            % Inputs:
                            %   fid = menufile identifier
                            %   fileInfo = structure holding the menufile header, module header, a handle
                            %   to the function to read module-specific data, etc.
                            % Output:
                            %   data = structure containing data from a single object
                            % set constants to match flag bitmap constants in class
                            % DataUnitBaseData.java.  The following contstants match header version 4.
                            TIMEMILLIS           = hex2dec('1');
                            TIMENANOS            = hex2dec('2');
                            CHANNELMAP           = hex2dec('4');
                            UID                  = hex2dec('8');
                            STARTSAMPLE          = hex2dec('10');
                            SAMPLEDURATION       = hex2dec('20');
                            FREQUENCYLIMITS      = hex2dec('40');
                            MILLISDURATION       = hex2dec('80');
                            TIMEDELAYSSECS       = hex2dec('100');
                            HASBINARYANNOTATIONS = hex2dec('200');
                            
                            % initialize a new variable to hold the data
                            data=[];
                            data.flagBitmap=0;
                            hasAnnotation = 0;
                            
                            % calculate where the next object starts, in case there is an error trying
                            % to read this one
                            objectLen = fread(fid, 1, 'int32');
                            curObj = ftell(fid);
                            nextObj = curObj + objectLen;
                            
                            % first thing to check is that this is really the type of object we think
                            % it should be, based on the menufile header.  If not, warn the user, move the
                            % pointer to the next object, and exit
                            data.identifier = fread(fid, 1, 'int32');
                            if (any(data.identifier==fileInfo.objectType))
                                % do nothing here - couldn't figure out a clean way of checking if
                                % number wasn't in array
                            else
                                disp(['Error - Object Identifier does not match ' fileInfo.fileHeader.moduleType ' type.  Aborting data read.']);
                                fseek(fid, nextObj, 'bof');
                                return;
                            end
                        
                            % read the data, starting with the standard data that every data unit has
                            version=fileInfo.fileHeader.fileFormat;
                            try
                                data.millis = fread(fid, 1, 'int64');
                                
                                if (version == 2 || (bitand(data.flagBitmap, TIMENANOS)~=0) )
                                    data.timeNanos = fread(fid,1,'int64');
                                end
                                
                                if (bitand(data.flagBitmap, UID)==UID)
                                    data.UID = fread(fid,1,'int64');
                                end
                                
                                if (bitand(data.flagBitmap, STARTSAMPLE)~=0)
                                    data.startSample = fread(fid,1,'int64');
                                end
                                
                                if (bitand(data.flagBitmap, MILLISDURATION)~=0)
                                    data.millisDuration = fread(fid,1,'float');
                                end
                                
                                if (bitand(data.flagBitmap, TIMEDELAYSSECS)~=0)
                                    data.numTimeDelays = fread(fid,1,'int16');
                                    td=zeros(1, data.numTimeDelays);
                                    for i = 1:data.numTimeDelays
                                        td(i)=fread(fid,1,'float');
                                    end
                                    data.timeDelays=td;
                                end
                                
                                % set date, to maintain backwards compatibility
                                data.date = millisToDateNum(data.millis);
                                
                                % now read the module-specific data
                                if(isa(fileInfo.readModuleData,'function_handle'))
                                    [data, error] = fileInfo.readModuleData(fid, fileInfo, data);
                                    if (error)
                                        disp(['Error - cannot retrieve ' fileInfo.fileHeader.moduleType ' data properly.']);
                                        fseek(fid, nextObj, 'bof');
                                        return;
                                    end
                                end
                                % now check to see if there are standard annotations to the main data.
                                if (bitand(data.flagBitmap, HASBINARYANNOTATIONS)~=0)
                                    hasAnnotation = 1;
                                    anStart = ftell(fid);
                                    anTotLength = fread(fid, 1, 'int16');
                                    nAn = fread(fid, 1, 'int16');
                                    for i = 1:nAn
                                        filePos = ftell(fid);
                                         anLength = fread(fid, 1, 'int16')-2; %tis length does no tinclude itself !
                                         anId = readJavaUTFString(fid);
                                         anVersion = fread(fid, 1, 'int16');
                                         switch (anId)
                                             case 'Beer'
                                                 data.annotations.beamAngles = readBeamFormerAnnotation(fid, anId, anLength, fileInfo, anVersion);
                                             case 'TMAN'
                                                 data.annotations.targetMotion = readTMAnnotation(fid, anId, anLength, fileInfo, anVersion);
                                             case 'TDBL'
                                                 data.annotations.toadAngles = readTDBLAnnotation(fid, anId, anLength, fileInfo, anVersion);
                                             otherwise
                                                 fprintf('Unknown anotation type "%s" length %d version %d in file\n', ...
                                                     anId, anLength, anVersion);
                                                 fseek(fid, filePos+anLength, 'bof');
                                         end
                                         endPos = ftell(fid);
                                         if (endPos ~= filePos+anLength)
                                              disp('Possible annotation read size error in file')
                                              fseek(fid, filePos+anLength, 'bof');
                                              endPos = ftell(fid);
                                         end
                                    end
                                    if (endPos ~= anStart+anTotLength)
                                        fseek(fid, anStart+anTotLength, 'bof');
                                    end
                                else
                                    data.annotations = [];
                                end
                                
                            catch mError
                                disp('Error loading object data');
                                disp(data);
                                disp(getReport(mError));
                                fseek(fid, nextObj, 'bof');
                            end
                        end
                        
                        function array = checkArrayAllocation(array, reqLength, sampleObject)
                            %global array
                            if isempty(array)
                                currentLength = 0;
                                array = [];
                            else
                                currentLength = numel(array);
                            end
                            if (currentLength >= reqLength)
                                return;
                            end
                            allocStep = round(sqrt(reqLength));
                            allocStep = max(10, min(allocStep, 10000));
                            array(reqLength + allocStep) = sampleObject;
                            return;
                        end
                        
                        function [data, error] = readClickData(fid, fileInfo, data)
                            error=false;
                            
                            try
                                
                                % read click detector specific data
                                dataLength = fread(fid, 1, 'int32');
                                if (dataLength==0)
                                    return;
                                end
                                
                                if (fileInfo.moduleHeader.version<=3)
                                    data.startSample = fread(fid, 1, 'int64');
                                    data.channelMap = fread(fid, 1, 'int32');
                                end
                                
                                data.triggerMap = fread(fid, 1, 'int32');
                                data.type = fread(fid, 1, 'int16');
                                if (fileInfo.moduleHeader.version >= 2)
                                    data.flags = fread(fid, 1, 'int32');
                                else
                                    data.flags = 0;
                                end
                                
                                if (fileInfo.moduleHeader.version <= 3)
                                    nDelays = fread(fid, 1, 'int16');
                                    if (nDelays)
                                        data.delays = fread(fid, nDelays, 'float');
                                    end
                                end
                                
                                nAngles = fread(fid, 1, 'int16');
                                if (nAngles)
                                    data.angles = fread(fid, nAngles, 'float');
                                end
                                
                                if (fileInfo.moduleHeader.version >= 3)
                                    nAngleErrors = fread(fid, 1, 'int16');
                                    data.angleErrors = fread(fid, nAngleErrors, 'float');
                                else
                                    data.angleErrors = [];
                                end
                                
                                if (fileInfo.moduleHeader.version <= 3)
                                    data.duration = fread(fid, 1, 'int16');
                                else
                                    data.duration = data.sampleDuration;    % duplicate the value to maintain backwards compatibility
                                end
                                
                                data.nChan = countChannels(data.channelMap);
                                maxVal = fread(fid, 1, 'float');
                                data.wave = fread(fid, [data.duration,data.nChan], 'int8') * maxVal / 127;
                                
                            catch mError
                                disp(['Error reading ' fileInfo.fileHeader.moduleType '  data object.  Data read:']);
                                disp(data);
                                disp(getReport(mError));
                                error=true;
                            end
                        end
                        
                        function nC = countChannels(channelMap)
                            % function nC = countChannels(channelMap)
                            % count the numebr of set bits in the channel map
                            nC = 0;
                            j = 1;
                            for i = 1:32
                                if (bitand(channelMap, j))
                                    nC = nC + 1;
                                end
                                j = j * 2;
                            end
                        end
                        
                        function footer = readClickFooter(file)
                            % reads module footer information for the Click Detector module.  Note that
                            % sometimes there is no additional footer information, so check first
                            % whether or not the binaryLength variable is 0.
                            
                            footer=readStdModuleFooter(file);
                            if (footer.binaryLength ~= 0)
                                footer.typesCountLength = fread(file, 1, 'int16');
                                footer.typesCount = fread(file, footer.typesCountLength, 'int32');
                            end
                        end
                        
                        function footer = readStdModuleFooter(file)
                            % reads the module footer information common to all modules.  Differs from
                            % the legacy code in that it does not read in or skip any information
                            % specific to a module.
                            
                            footer.length = fread(file, 1, 'int32');
                            footer.identifier = fread(file, 1, 'int32');
                            footer.binaryLength = fread(file, 1, 'int32');
                        end
                        
                        function footer = readFileFooterInfo(fid,version)
                            footer.length = fread(fid, 1, 'int32');
                            footer.identifier = fread(fid, 1, 'int32');
                            footer.nObjects = fread(fid, 1, 'int32');
                            footer.dataDate = millisToDateNum(fread(fid, 1, 'int64'));
                            footer.analysisDate = millisToDateNum(fread(fid, 1, 'int64'));
                            footer.endSample = fread(fid, 1, 'int64');
                            if (version>=3)
                                footer.lowestUID = fread(fid, 1, 'int64');
                                footer.highestUID = fread(fid, 1, 'int64');
                            end
                            footer.fileLength = fread(fid, 1, 'int64');
                            footer.endReason = fread(fid, 1, 'int32');
                        end
                      
        end

        % Menu selected function: NewprojectMenu
        function NewCTSet(app, event)
            % CREATE THE MAIN MENU FIGURE
            OpenCTFig = uifigure('Position', [150 150 260 400]);
            OpenCTFig.Name = 'New Project';
            OpenCTFig.MenuBar = 'none';    % Hide standard menu bar menus.
            OpenCTFig.Resize='off';
            OpenCTFig.Visible = 'on';
            
               % create a panel inside the figure
            OpenCTPan = uipanel('Parent', OpenCTFig,'Units','pixels', 'Position',[10 10 240 380]);
            SelectFolderText = uilabel(OpenCTPan,'Position', [10 360 80 20]);
            SelectFolderText.Text = 'Select Folder';
            
               % edit - selected folder to be read
            FolderPathOpenCT = uieditfield('Parent', OpenCTPan,...
                'Position', [10 330 220 20], ...
                "Enable","on",...
                "Value",'C:\Users\');
            InclSubFolders = uicheckbox('Parent', OpenCTPan,'Text', 'Include subfolders',...
                'Position', [10 300 220 20]);
            
              % LATITUDE AND LONGITUDE PANEL       
            LocationPan = uipanel('Parent', OpenCTPan,'Units','pixels', 'Position',[10 200 220 70]);
               % Latitude
            uilabel('Parent', LocationPan,'Text', 'Latitude', 'Position', [10 40 80 20]);
            LatEdit = uieditfield('Parent', LocationPan,'Value', '55.576197', 'Position',[110 40 90 20]);
            LatEdit.HorizontalAlignment = 'right';
               
               % Longitude
            uilabel('Parent', LocationPan,'Text', 'Longitude', 'Position',[10 10 80 20]);
            LongEdit = uieditfield('Parent', LocationPan,'Value', '9.848555', 'Position',[110 10 90 20]);
            LongEdit.HorizontalAlignment = 'right';
            
               % CLICK TRAINS PANEL
            ParPanCT = uipanel('Parent', OpenCTPan,'Units','pixels', 'Position',[10 50 220 140]);

            uilabel('Parent', ParPanCT, "Text",'Separation time (ms)', "Position", [10 110 120 20]);
            ICISepEdit = uieditfield('Parent', ParPanCT, "Value", '1000', 'Position', [150 110 50 20]);
            ICISepEdit.HorizontalAlignment = 'right';
               
            uilabel('Parent', ParPanCT,'Text', 'Min Length', 'Position', [10 85 80 20]);
            MinLengthEdit = uieditfield('Parent', ParPanCT,'Value', '16', 'Position',[150 85 50 20]);
            MinLengthEdit.HorizontalAlignment = 'right';

            uilabel('Parent', ParPanCT,'Text', 'Max Length', 'Position',[10 60 80 20]);
            MaxLengthEdit = uieditfield('Parent', ParPanCT,'Value', '1500', 'Position',[150 60 50 20]);
            MaxLengthEdit.HorizontalAlignment = 'right';
            
                        
               % browse button
            BrowseButton = uibutton(OpenCTPan, 'push',...
                'ButtonPushedFcn', @(BrowseButton,event) PushBrowseButton(BrowseButton));
            BrowseButton.Position = [150 280 80 20];
            BrowseButton.Text = 'Browse';
            
            function PushBrowseButton(BrowseButton, event)
                 % Where it opens
                folder = fullfile(matlabroot, 'C:\Users\');
                % Selected folder that has subfolders
                topLevelFolder = uigetdir(folder);
                if topLevelFolder == 0
                return;
                end
                % Get list of all subfolders.
                allSubFolders = genpath(topLevelFolder);
                % Parse into a cell array.
                remain = allSubFolders;
                app.listOfFolderNames = {};
                while true
                [singleSubFolder, remain] = strtok(remain, ';');
                if isempty(singleSubFolder)
                    break;
                end
                app.listOfFolderNames = [app.listOfFolderNames singleSubFolder];
                end
                FolderPathOpenCT.Value = topLevelFolder;
            end
              % Apply values button
            ApplyButtonCT = uibutton(ParPanCT, 'push',...
                'ButtonPushedFcn', @(ApplyButtonCT,event) PushApplyButtonCT(ApplyButtonCT));
            ApplyButtonCT.Position = [120 25 80 25];
            ApplyButtonCT.Text = 'Apply values';
           
              % Apply the values needed to create click trains
            function PushApplyButtonCT(ApplyButtonCT, event)
                app.ICISep = str2double(ICISepEdit.Value);
                app.MinLength = str2double(MinLengthEdit.Value);
                app.MaxLength = str2double(MaxLengthEdit.Value);
                OKButtonNew.Enable = 'on';
                app.LAT = str2double(LatEdit.Value);
                app.LONG = str2double(LongEdit.Value);
             end
                               
              % OK button
            OKButtonNew = uibutton(OpenCTPan, 'push',...
                'ButtonPushedFcn', @(OKButtonNew,event) PushOKButton(app,OKButtonNew));
            OKButtonNew.Position = [10 10 90 25];
            OKButtonNew.Text = 'Prepare CT';
            OKButtonNew.Enable = 'off';
            
            function PushOKButton(OKButtonNew, event)
                % Close menu window
               OpenCTFig.Visible = 'off';
               
               if InclSubFolders == 1
                StartHere = 2;
                numberOfFolders = length(app.listOfFolderNames);
               else % if == 0
                StartHere = 1;
                numberOfFolders = 1;
               end
               for FolderN = StartHere:numberOfFolders
                    % Load ClickParameters
                      % Get this folder and print it out.
                    clear thisFolder CTInfoName ClickParNames ClickParFilesInFolder...
                           ClickParFile ClickParameters
                    thisFolder = app.listOfFolderNames{FolderN}; 
                    ClickParNames = strcat(thisFolder,'\ClickParameters*.mat');
                    ClickParFilesInFolder = dir(ClickParNames);
                    assignin('base', "ClickParFilesInFolder", ClickParFilesInFolder)
                    ClickParFileName = ClickParFilesInFolder(1).name;
                    app.ClickParFile = strcat(thisFolder,'\', ClickParFileName);
                    TempClickParameters = load(app.ClickParFile);
                    app.ClickParameters = TempClickParameters.ClickParameters;
                    TempClickParameters = [];
                    PosICI = find(app.ClickParameters.ICI ~= 0);
                    app.Fs = app.ClickParameters.ICI(PosICI(3),1)/(1000*(app.ClickParameters.startSample(PosICI(3),1) - app.ClickParameters.startSample(PosICI(3)-1,1)));
                    app.Fs = 1/app.Fs;
                    assignin('base', 'ClickParameters', app.ClickParameters)
                    assignin('base', 'Fs', app.Fs)
                    Today = app.ClickParameters.Date(1,1);
                    srise = []; sset = [];
                    app.LAT = 55.564364;
                    app.LONG = 11.552536;
                    [srise, sset, ~] = sunrise(app.LAT, app.LONG, 0, [], Today);
                    clear LAT LONG Today
                    
                %% pre-cleaning - deleting loose clicks  
                L1 = size(app.ClickParameters,1); 
                L2 = 1;
                while L2 ~= L1
                    L1 = size(app.ClickParameters, 1);    
                    app.ClickParameters.ICI(1,1) = 0;
                    app.ClickParameters.ICI(2:end,1) = diff(app.ClickParameters.startSample)/(app.Fs/1000);
                    app.ClickParameters.ICI(app.ClickParameters.ICI < 0, 1) = 0;
                    app.ClickParameters.CPS = 1000./app.ClickParameters.ICI;
                    app.ClickParameters.CPS(app.ClickParameters.ICI == 0, 1) = 0;
                    L = size(app.ClickParameters,1);
                    clear AmpDiff NegAmp LowICI ICIOverZero RowsToDelete
                    AmpDiff(1,1) = 0;
                    AmpDiff(2:L,1) = diff(app.ClickParameters.Amp);
                    app.ClickParameters(app.ClickParameters.ICI < 0.75 & app.ClickParameters.ICI > 0 & AmpDiff < -6, :) = [];
                    clear AmpDiff
                    AmpDiff(1,1) = 0;
                    L = size(app.ClickParameters,1);
                    AmpDiff(2:L,1)= diff(app.ClickParameters.Amp);
                    app.ClickParameters(app.ClickParameters.ICI < 0.5 & AmpDiff < -2 & app.ClickParameters.Class == 2 &  app.ClickParameters.ICI > 0, :) = [];
                    L2 = size(app.ClickParameters, 1);
                end
                
                app.ClickParameters(app.ClickParameters.ICI < 0.25 & app.ClickParameters.ICI > 0, :) = [];
                % New ICI
                app.ClickParameters.ICI(1,1) = 0;
                app.ClickParameters.ICI(2:end,1) = diff(app.ClickParameters.startSample)/(app.Fs/1000);
                app.ClickParameters.ICI(app.ClickParameters.ICI < 0, 1) = 0;
                app.ClickParameters.CPS(1:size(app.ClickParameters,1),1) = 1000./app.ClickParameters.ICI(1:end,1);
                app.ClickParameters.CPS(app.ClickParameters.ICI == 0, 1) = 0;  
                % set CT to 0
                app.ClickParameters.CT(1:end,1) = 0;
                % ASSIGN CLICK TRAIN NUMBERS and create CTInfo
                app.CTInfo = struct();
                app.MaxLength = 1500;
                app.ClickParameters.ICI(app.ClickParameters.ICI > app.ICISep, 1) = 0;
                ICISepLoc = find(app.ClickParameters.ICI == 0);
                CTNum = 1; % click train number
                Zb = size(ICISepLoc,1);
                
                while Zb > 0
                  % If the CT would have a length smaller than the max length
                  % of it selected by the user, continue without any issues
                	if Zb == 1
                      Start = ICISepLoc(1); % First row 
                      app.ClickParameters.ICI(Start, 1) = 0;
                      app.ClickParameters.CT(Start:end,1) = CTNum;
                      CTLength = End - Start + 1; 
                      if CTLength < 16
                         % nothing
                      else % CTLength >= 15 % && CTLength < MaxLength
                         app.CTTemp = app.ClickParameters(app.ClickParameters.CT == CTNum, :);
                         % Estimates and to CTInfo
                         [RecalculateCTNum, DeleteLQ, Type, sp] = Species(app.CTTemp, app.Fs, app.ICISep);
                         if DeleteLQ == 1 
                           app.ClickParameters(app.ClickParameters.CT == CTNum & app.app.ClickParameters.Class == 2, :) = [];
                           app.CTTemp(app.CTTemp.Class == 2,:) = [];
                             if RecalculateCTNum == 1
                              [app.ClickParameters, app.CTTemp, CTNum, End, app.CTInfo] = Recalculate(app.CTTemp, app.ClickParameters, CTNum, app.Fs, app.ICISep, app.CTInfo, srise, sset, sp, Type);
                             else % if RecalculateCTNum == 0
                              app.CTTemp = NewICI(app, app.CTTemp, app.Fs);
                              app.ClickParameters.ICI(app.ClickParameters.CT == CTNum,1) = app.CTTemp.ICI;
                                if size(app.CTTemp,1) > 15
                                  app.CTInfo = CTInfoMaker(app.CTInfo, app.CTTemp, srise, sset, sp, Type);
                                end 
                             end            
                         else % if DeleteLQ == 0
                             app.CTInfo = CTInfoMaker(app.CTInfo, app.CTTemp, srise, sset, sp, Type);
                         end % if DeleteLQ
                      end % If LengthCT < 16
                     Zb = 0; 
                    else % if Zb >= 1
                      End = ICISepLoc(2)-1; % First row with a 0
                      Start = ICISepLoc(1); % First row 
                      app.ClickParameters.ICI(Start,1) = 0;
                      app.ClickParameters.CT(Start:End,1) = CTNum; 
                      CTLength = End - Start + 1; 
                      if CTLength < 16
                         CTNum = CTNum + 1;
                      else % CTLength >= 15 % && CTLength < MaxLength
                          clear CTTemp
                          app.CTTemp = app.ClickParameters(app.ClickParameters.CT == CTNum, :);
                          % Estimates and to CTInfo
                          [RecalculateCTNum, DeleteLQ, Type, sp] = Species(app.CTTemp, app.Fs, app.ICISep);
                          if DeleteLQ == 1 
                            app.ClickParameters(app.ClickParameters.CT == CTNum & app.ClickParameters.Class == 2, :) = [];
                            app.CTTemp(app.CTTemp.Class == 2,:) = [];
                            
                             if RecalculateCTNum == 1
                              [app.ClickParameters, app.CTTemp, CTNum, End, app.CTInfo] = Recalculate(app.CTTemp, app.ClickParameters, CTNum, app.Fs, app.ICISep, app.CTInfo, srise, sset, sp, Type);
                             else % if RecalculateCTNum == 0
                              app.CTTemp = NewICI(app, app.CTTemp, app.Fs);
                              [~, ~, Type, sp] = Species(app.CTTemp, app.Fs, app.ICISep);
                              IDs = app.CTTemp.ID;
                              End = find(app.ClickParameters.ID(1:end,1) == IDs(end));
                              app.ClickParameters.ICI(app.ClickParameters.CT == CTNum,1) = app.CTTemp.ICI;
                                if size(app.CTTemp,1) > 15
                                  app.CTInfo = CTInfoMaker(app.CTInfo, app.CTTemp, srise, sset, sp, Type);
                                end 
                              CTNum = CTNum + 1;
                             end            
                          else % if DeleteLQ == 0
                             IDs = app.CTTemp.ID;
                             End = find(app.ClickParameters.ID(1:end,1) == IDs(end));
                             if size(app.CTTemp,1) > 15
                               app.CTInfo = CTInfoMaker(app.CTInfo, app.CTTemp, srise, sset, sp, Type);
                             end 
                             CTNum = CTNum + 1;
                          end 
                      end % If LengthCT < 16
                    clear ICISepLoc
                    ICISepLoc = find(app.ClickParameters.ICI(End+1:end,1) == 0);
                    ICISepLoc = ICISepLoc + End;
                    Zb = size(ICISepLoc,1);
                    clear CTTemp1
                	end 
                end % End loop m = 1:Zb
                    % SAVE CLICK PARAMETERS
                    CTInfo = app.CTInfo;
                    ClickParameters = app.ClickParameters;
                    ClickParName = strcat(thisFolder, '\ClickParametersTested.mat');
                    save(ClickParName, 'ClickParameters'); % n = 1012989
                    CTInfoName = strcat(thisFolder, '\CTInfoNew.mat');
                    save(CTInfoName, 'CTInfo');

               end                
           end % OK Button - Prepare CT
                        
           % Cancel button
        CancelButton = uibutton(OpenCTPan, 'push',...
            'ButtonPushedFcn', @(CancelButton,event) PushCancelButton(app,CancelButton));
        CancelButton.Position = [140 10 90 25];
        CancelButton.Text = 'Cancel';
      function PushCancelButton(CancelButton, event)
      OpenCTFig.Visible = 'off';
      end
      
      % Create a function I can call in the other iterations
      function CTInfo = CTInfoMaker(CTInfo, CTTemp, srise, sset, sp, Type)
          CTNum = CTTemp.CT(1,1);
          CheckEmpty = isfield(CTInfo, 'CTNum');
          if CheckEmpty == 0
             RowN = 1;
          else
             RowN = length(CTInfo) + 1;   
          end
            
         % Store in CTInfo
         CTInfo(RowN).CTNum = CTNum;  % new CT to separate
         CTInfo(RowN).Date = datestr(CTTemp.Date(1,1)); % Start date
         % Location
         CTInfo(RowN).InFile = CTTemp.InFile(1,1);
         % Length
         CTInfo(RowN).Length = size(CTTemp,1);
             % day/night
             if CTTemp.Date(1,1) >= srise && CTTemp.Date(1,1) <= sset
                 CTInfo(RowN).DayNight = 'Day';
             else % if DATE >= sset && DATE <= srise
                 CTInfo(RowN).DayNight = 'Night';
             end
         % Type
          CTInfo(RowN).Species = Type;
          if sp == 1 || sp == 2 % Find patterns - cleaning and all 
            [app.CTTemp2, app.NewCT] = ExtractPatterns(CTTemp, app.Fs);
            CPSForBehav = CTTemp.CPS;
              if size(CPSForBehav,1) > 20
                CTInfo(RowN).Behav = Behaviour(app, CPSForBehav, sp);
              else
                CTInfo(RowN).Behav = '-';
              end
            % Calf  
%              MeanCF = mean(CTTemp.CF);
%              MeanRMSBW = mean(CTTemp.RMSBW);
%              MeanBW = mean(CTTemp.BW);
%              if MeanCF > 136000 && MeanRMSBW > 10   || MeanCF > 136000 && MeanBW > 10
%                    CTInfo(RowN).Calf = 'Yes';
%              else
%                    CTInfo(RowN).Calf = 'No';
%              end 
              
          else 
             CTInfo(RowN).Behav = '-';
            % CTInfo(RowN).Calf = '-';
          end
          
         CTInfo(RowN).Saved = 0;
         CTInfo(RowN).Notes = ' ';
      end
      
      %% SPECIESS
      function [RecalculateCTNum, DeleteLQ, Type, sp] = Species(CTTemp, Fs, ICISep)
         CFDiff = diff(CTTemp.CF);
         MeanPF = mean(CTTemp.PF);
         PercChangeCF = (CFDiff./CTTemp.CF(1:end-1))*100;
         MeanPercChangeCF = mean(abs(PercChangeCF));
         CPSDiff = diff(CTTemp.CPS);
         PercChange = (CPSDiff./CTTemp.CPS(1:end-1))*100;
         MedianPercChangeCPS = median(abs(PercChange(2:end)));
         if size(CTTemp,1) > 1450
             % Large click trains (in noisy environment)
             if sum(CTTemp.Class == 1) > 5 % Potential porpoise clicks
               CTTemp(CTTemp.Class == 2, :) = []; % Remove LQ
               CTTemp = NewICI(app, CTTemp, Fs);
               CPSDiff = diff(CTTemp.CPS);
               PercChange = (CPSDiff./CTTemp.CPS(1:end-1))*100;
               MedianPercChangeCPS = median(abs(PercChange(2:end)));
               MoreThanOneCT = sum(CTTemp.ICI > ICISep); % 
                if MedianPercChangeCPS > 40
                   Type = 'LQ-NBHF';
                   sp = 2;  
                   DeleteLQ = 1;
                   if MoreThanOneCT > 0
                      RecalculateCTNum = 1;
                   else
                      RecalculateCTNum = 0;
                   end
                else
                    MedianCPS = median(CTTemp.CPS);
                    if MeanPF > 140000 && MedianCPS < 8.7 && MedianCPS > 7 && MeanPercChangeCF < 0.5
                        % Sonar
                       Type = 'Sonar';
                       sp = 3;
                       DeleteLQ = 0;
                    else  
                       Type = 'NBHF';
                       sp = 1;
                       DeleteLQ = 1;
                       if MoreThanOneCT > 0
                          RecalculateCTNum = 1;
                       else
                          RecalculateCTNum = 0;
                       end
                   end
                end 
             else
               Type = 'Non-NBHF';
               sp = 3;   
               DeleteLQ = 0;
               RecalculateCTNum = 0;
             end 
         elseif size(CTTemp,1) < 15  
           Type = 'Non-NBHF';
           sp = 3;   
           DeleteLQ = 0;
           RecalculateCTNum = 0;
         elseif MedianPercChangeCPS >= 150  
             if sum(CTTemp.Class == 1) > 5
               CTTemp(CTTemp.Class == 2, :) = [];
               CTTemp = NewICI(app, CTTemp, Fs);
               CPSDiff = diff(CTTemp.CPS);
               PercChange = (CPSDiff./CTTemp.CPS(1:end-1))*100;
               MedianPercChangeCPS = median(abs(PercChange(2:end)));
               MoreThanOneCT = sum(CTTemp.ICI > 1000);
                if MedianPercChangeCPS > 40
                   Type = 'LQ-NBHF';
                   sp = 2;  
                   DeleteLQ = 1;
                   if MoreThanOneCT > 0
                      RecalculateCTNum = 1;
                   else
                      RecalculateCTNum = 0;
                   end
                else
                    MedianCPS = median(CTTemp.CPS);
                    if MeanPF > 140000 && MedianCPS < 8.7 && MedianCPS > 7 && MeanPercChangeCF < 0.5
                        % Sonar
                       Type = 'Sonar';
                       sp = 3;
                       DeleteLQ = 0;
                    else
                       Type = 'NBHF';
                       sp = 1;
                       DeleteLQ = 1;
                       if MoreThanOneCT > 0
                          RecalculateCTNum = 1;
                       else
                          RecalculateCTNum = 0;
                       end
                    end
                end 
             else
               Type = 'Non-NBHF';
               sp = 3;
               DeleteLQ = 0;
               RecalculateCTNum = 0;
             end 
         elseif MedianPercChangeCPS <= 40  
             MedianCPS = median(CTTemp.CPS);
                if MeanPF > 140000 && MedianCPS < 8.7 && MedianCPS > 7 && MeanPercChangeCF < 0.5
                    % Sonar
                   Type = 'Sonar';
                   sp = 3;
                   DeleteLQ = 0;
                   RecalculateCTNum = 0;
                else 
                   Type = 'NBHF';
                   sp = 1;
                   DeleteLQ = 0;
                   RecalculateCTNum = 0;
                end 
         elseif MedianPercChangeCPS > 40 && MedianPercChangeCPS < 150 && MeanPercChangeCF <= 8
            if     sum(CTTemp.Class == 1) > 5
                   CTTemp(CTTemp.Class == 2, :) = [];
                   CTTemp = NewICI(app, CTTemp, Fs);
                   CPSDiff = diff(CTTemp.CPS);
                   PercChange = (CPSDiff./CTTemp.CPS(1:end-1))*100;
                   MedianPercChangeCPS = median(abs(PercChange(2:end)));
                   MoreThanOneCT = sum(CTTemp.ICI > 1000);
                 if MedianPercChangeCPS > 40
                       Type = 'LQ-NBHF';
                       sp = 2;  
                       DeleteLQ = 1;
                       if MoreThanOneCT > 0
                          RecalculateCTNum = 1;
                       else
                          RecalculateCTNum = 0;
                       end
                 else % MedianPercChangeCPS < 40
                       MedianCPS = median(CTTemp.CPS);
                    if MeanPF > 140000 && MedianCPS < 8.7 && MedianCPS > 7 && MeanPercChangeCF < 0.5
                        % Sonar
                       Type = 'Sonar';
                       sp = 3;
                       DeleteLQ = 0;
                    else
                       Type = 'NBHF';
                       sp = 1;
                       DeleteLQ = 1;
                       if MoreThanOneCT > 0
                          RecalculateCTNum = 1;
                       else
                          RecalculateCTNum = 0;
                       end   
                    end 
                 end 
            else
               Type = 'Non-NBHF';
               sp = 3;
               DeleteLQ = 0;
               RecalculateCTNum = 0;
            end 
         else 
           Type = 'Non-NBHF';
           sp = 3;
           DeleteLQ = 0;
           RecalculateCTNum = 0;
         end
      end 
      
      %% EXTRACT PATTERNS
      function [CTTemp2, NewCT] = ExtractPatterns(CTTemp, Fs)
        % add rown numbers to see if what we removed is another click train
        CTTemp.RowN(1,1) = 1;
        CTTemp.RowN(1:end,1) = 1:size(CTTemp,1);
        % Save in another variable
        CTTemp1 = CTTemp;  
        % A large difference indicates echoes
        SortedCPS = sort(CTTemp.CPS);
        DiffSorted = diff(SortedCPS);
        [MaxDiffSorted, ~] = max(DiffSorted);
        % Good click trains that only have echoes 
        if MaxDiffSorted < 28
            % Do nothing
        else % if MaxDiffSorted < 500
            Outlier = isoutlier(CTTemp.CPS);
            HighCPS = CTTemp.CPS > 50;
            Both = Outlier + HighCPS;
            CTTemp(Both == 2,:) = [];
            CTTemp = NewICI(app, CTTemp,Fs);
            SortedCPS = sort(CTTemp.CPS);
            DiffSorted = diff(SortedCPS);
            [MaxDiffSorted, ~] = max(DiffSorted);
        end
        if MaxDiffSorted < 28 && MaxDiffSorted > 10 && size(CTTemp,1) < 100
           NewCT = CTTemp; % it ends here
           NewCT = NewICI(app, NewCT, Fs);
        else
            % remove local min
            S1 = size(CTTemp,1);
            S2 = 1;
            while S1 ~= S2
                clear DeleteRows LargeDiff LocMin DiffAmps
                S1 = size(CTTemp,1);
                DiffAmps(1,1) = 0;
                L = size(CTTemp,1);
                DiffAmps(2:L,1) = diff(CTTemp.Amp);
                LocMin = islocalmin(CTTemp.Amp);
                LargeDiff = DiffAmps < -5;
                DeleteRows = LocMin + LargeDiff;
                CTTemp(DeleteRows == 2,:) = [];
                CTTemp = NewICI(app, CTTemp,Fs);
                S2 = size(CTTemp,1);
            end 
        end
        SortedCPS = sort(CTTemp.CPS);
        DiffSorted = diff(SortedCPS);
        [MaxDiffSorted, ~] = max(DiffSorted);
        
        if MaxDiffSorted > 35 && size(CTTemp,1) > 100
             % Finding the stable areas
             StartRow = [];
              % Bit by bit
             CPSDiff = diff(CTTemp.CPS);
             PercChangeS = (CPSDiff./CTTemp.CPS(1:end-1))*100;
             PercChangeS = abs(PercChangeS(2:end));
             PercCPSDiff = movmean(PercChangeS,4);
             StartRow = find(PercCPSDiff < 5);
             StartRow = StartRow + 2; % because I removed the first value == 0
             DiffStartRow = diff(StartRow);
             Here = find(DiffStartRow == 1);
             k = []; L = []; StdCPS1 = []; StdCPS2 = [];
             assignin('base', 'StartRow', StartRow)
             % go into the CT
             clear RowN 
             RowsToKeep = [];
             Keep = 1;
             if size(StartRow,1) == 0 
               RowN = 1;
             else
               RowN = StartRow(Here(1));  % Low variability in CPS (for the next 4) 
             end 
             RowsToKeep(1,1) = RowN;
             FirstRowN = RowN;
             
             % Look backwards
             while RowN > 10
             ClickCPS = CTTemp.CPS(RowN,1);
             ClickAmp = CTTemp.Amp(RowN,1);
             ClickStartSample = CTTemp.startSample(RowN,1);
             ICIs = abs(ClickStartSample - CTTemp.startSample(RowN-9:RowN-1,1))/(Fs/1000);
             CPSs = 1000./ICIs;
             Amps = CTTemp.Amp(RowN-9:RowN-1,1);
             Amps = abs(ClickAmp - Amps);
             DiffCPSs = abs(ClickCPS - CPSs);
             [~, ixCPS] = min(DiffCPSs);
                 if Amps(ixCPS) < 5
                   DiffCPSs(ixCPS) = 1000;
                   [~, ixCPS] = min(DiffCPSs);
                   RowN = RowN - ixCPS;
                 else
                   RowN = RowN - ixCPS;  
                 end 
             Keep = Keep + 1;  
             RowsToKeep(Keep,1) = RowN;
             end 
             
             % Look forwards
             RowN = FirstRowN;
             while RowN < size(CTTemp,1)-10
             ClickCPS = CTTemp.CPS(RowN,1);
             ClickAmp = CTTemp.Amp(RowN,1);
             ClickStartSample = CTTemp.startSample(RowN,1);
             ICIs = abs(CTTemp.startSample(RowN+1:RowN+9,1) - ClickStartSample)/(Fs/1000);
             CPSs = 1000./ICIs;
             Amps = CTTemp.Amp(RowN+1:RowN+9,1);
             Amps = abs(Amps - ClickAmp);
             DiffCPSs = abs(CPSs - ClickCPS);
             [~, ixCPS] = min(DiffCPSs);
                 if Amps(ixCPS) < 5
                   DiffCPSs(ixCPS) = 1000;
                   [~, ixCPS] = min(DiffCPSs);
                   RowN = RowN + ixCPS;
                 else
                   RowN = RowN + ixCPS;  
                 end 
             Keep = Keep + 1;  
             RowsToKeep(Keep,1) = RowN;
             end 
         RowsToKeep(end+1:end+20,1) = [1:10, size(CTTemp,1)-9:size(CTTemp,1)];
         RowsToKeep = sort(unique(RowsToKeep));
         
         NewCT = CTTemp(RowsToKeep,:);
         NewCT = NewICI(app, NewCT, Fs);
         % Delete loose clicks
        L1 = size(NewCT,1);
        L2 = 1;
           while L2 ~= L1
            L1 = size(NewCT,1);           
            NewCT = NewICI(app, NewCT, Fs);   
            LooseClicks = find(NewCT.ICI > 250);
            Positions = find(diff(LooseClicks) == 1);
            RowsToDelete = LooseClicks(Positions);
            NewCT(RowsToDelete, :) = [];
            L2 = size(NewCT,1);
           end

        else 
         NewCT = CTTemp; 
         NewCT = NewICI(app, NewCT, Fs);
        end 
        RowsLeft = NewCT.RowN(:,1);
        CTTemp2 = CTTemp1;
        CTTemp2(RowsLeft,:) = [];
      end % end function
      
      %% RECALCULATE
      function [ClickParameters, CTTemp, CTNum, End, CTInfo] = Recalculate(CTTemp, ClickParameters, CTNum, Fs, ICISep, CTInfo, srise, sset, sp, Type)
          ClickParameters.CT(ClickParameters.CT == CTNum, 1) = 0;
          CTTemp = NewICI(app, CTTemp, Fs);
          SplitIn = find(CTTemp.ICI > ICISep);
          CTTemp.ICI(SplitIn,1) = 0;
          CTTemp1 = CTTemp;
          clear CTTemp
          SplitIn(end+1,1) = 1;
          SplitIn = sort(SplitIn);
          for j = 1:size(SplitIn,1)
              if j < size(SplitIn,1) 
                 CTTemp = CTTemp1(SplitIn(j):SplitIn(j+1)-1,:);
              else % if j == size(SplitIn,1)
                 CTTemp = CTTemp1(SplitIn(j):end,:); 
              end 
             CTTemp.CT(1:end,1) = CTNum;
             CTTemp = NewICI(app, CTTemp, Fs);
             IDs = CTTemp.ID;
             % Fill in ClickParameters with correct CTNum
             Row1 = find(ClickParameters.ID(1:end,1) == IDs(1));
             Row2 = find(ClickParameters.ID(1:end,1) == IDs(end));
             End = find(ClickParameters.ID(1:end,1) == IDs(end));
             ClickParameters.CT(Row1:Row2,1) = CTNum;
             ClickParameters.ICI(ClickParameters.CT == CTNum,1) = CTTemp.ICI;
             if size(CTTemp, 1) > 15
                 [~, ~, Type, sp] = Species(CTTemp, Fs, ICISep);
                 CTInfo = CTInfoMaker(CTInfo, CTTemp, srise, sset, sp, Type);      
             end
             CTNum = CTNum + 1;
          end 
end 
      %%% Estimate sunset and sunrise
            %% Citation
            % François Beauducel (2019). SUNRISE: sunrise and sunset times
            % (https://www.mathworks.com/matlabcentral/fileexchange/64692-sunrise-sunrise-and-sunset-times),
            % MATLAB Central File Exchange. Retrieved October 28, 2019
            
            function varargout=sunrise(varargin)
                %SUNRISE Computes sunset and sunrise time.
                %	SUNRISE(LAT,LON,ALT,TZ) displays time of sunrise and sunset at
                %	location latitude LAT (decimal degrees, positive towards North),
                %	longitude LON (decimal degrees, positive towards East), altitude ALT
                %	(in meters above sea level) for today. Time zone TZ (in hour) is
                %	recommanded but optional (default is the computer system time zone).
                %
                %	SUNRISE(LAT,LON,ALT,TZ,DATE) computes sunrise and sunset time for
                %	date DATE (in any datenum compatible format). DATE can be scalar,
                %	vector or matrix of dates (strings or datenum).
                %
                %	SUNRISE by itself displays time of sunrise and sunset at your
                %	approximate location (needs Java activated and internet connection).
                %
                %	[SRISE,SSET,NOON]=SUNRISE(...) returns time of sunrise, sunset and noon
                %	in datenum format. To get only hours, try datestr(srise,'HH:MM').
                %
                %	DAYLENGTH=SUNRISE(...) returns the day length of light expressed in
                %	fraction of day. Multiply by 24 to get hours.
                %
                %   LAT=SUNRISE(DAYLENGTH,ALT,DATE,'day2lat') estimates the latitude from
                %	day length (in fraction of day) and altitude ALT (in meters) for the
                %	day DATE. If ALT is ommitted it uses sea level (0 m). If DATE is
                %	ommitted it computes for today.
                %
                %   [LAT,LON]=SUNRISE(SRISE,SSET,ALT,'sun2ll') estimates the latitude and
                %	longitude from sunrise and sunset dates/time, both in GMT time zone
                %	(TZ = 0), and altitude ALT (in meters). If ALT is ommitted it uses sea
                %	level (0 m).
                %
                %	Note: reverse function does not try to fit the sunrise and sunset times,
                %	but it uses both noon time (average of sunrise/sunset) and day length
                %	(difference between sunrise/sunset) values to look for the best
                %	latitude and longitude.
                %
                %
                %	Examples:
                %		>> sunrise(37.71,15.03,2031,2,'2017-07-17')
                %		Sunrise: 17-Jul-2017 05:43:10 +02
                %		Sunset:  17-Jul-2017 20:30:39 +02
                %		Day length: 14h 47mn 30s
                %
                %		>> sunrise
                %		Location: -8.65°N, 115.2167°E, 0m
                %		Sunrise: 16-Oct-2017 05:57:07 +08
                %		Sunset:  16-Oct-2017 18:14:31 +08
                %		Day length: 12h 17mn 24s
                %
                %       >> sunrise(14/24,0,'2019-04-21','day2lat')
                %       Estimated latitude: 49.076°N
                %
                %		>> sunrise('2019-04-22 04:52:12','2019-04-22 18:51:04',0,'sun2ll')
                %		Estimated location: 47.9995°N, 2.00142°E
                %
                %		Plot sunrise and sunset time variation for the year:
                %		days = datenum(2017,1,1:365);
                %		[srise,sset,noon]=sunrise(48.8,2.3,0,2,days);
                %		plot(days,rem([srise;sset;noon]',1)*24,'linewidth',4)
                %		datetick('x')
                %
                %
                %	Reference:
                %	    https://en.wikipedia.org/wiki/Sunrise_equation
                %
                %	Author: Francois Beauducel, IPGP
                %	Created: 2017-10-10 in Paris, France
                %	Updated: 2019-08-18
                %	Release history:
                %   [2019-08-18] v1.4
                %       - fix an issue in reverse function for western longitudes
                %	[2019-04-21] v1.3
                %		- adds reverse functions to compute lat/lon from sunrise/sunset
                %		  (thanks to a suggestion by Jaechan Lim)
                %	[2018-10-17] v1.2
                %		- changes to ip-api.com for automatic geolocalisation
                %	[2017-10-16] v1.1
                %		- removes dependency from jsondecode function
                %		- automatic detection of local timezone (needs Java)
                %	[2017-10-10] v1.0
                %		- initial function
                %
                %	Copyright (c) 2019, François Beauducel, covered by BSD License.
                %	All rights reserved.
                %
                %	Redistribution and use in source and binary forms, with or without
                %	modification, are permitted provided that the following conditions are
                %	met:
                %
                %	   * Redistributions of source code must retain the above copyright
                %	     notice, this list of conditions and the following disclaimer.
                %	   * Redistributions in binary form must reproduce the above copyright
                %	     notice, this list of conditions and the following disclaimer in
                %	     the documentation and/or other materials provided with the
                %	     distribution
                %
                %	THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS
                %	IS" AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED
                %	TO, THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A
                %	PARTICULAR PURPOSE ARE DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT
                %	OWNER OR CONTRIBUTORS BE LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL,
                %	SPECIAL, EXEMPLARY, OR CONSEQUENTIAL DAMAGES (INCLUDING, BUT NOT
                %	LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR SERVICES; LOSS OF USE,
                %	DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER CAUSED AND ON ANY
                %	THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY, OR TORT
                %	(INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                %	OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
                day2lat = any(strcmpi(varargin,'day2lat'));
                sun2ll = any(strcmpi(varargin,'sun2ll'));
                % --- Forward problem: sunrise(lat,lon,alt,tz,dte)
                if ~day2lat && ~sun2ll
                   	if nargin < 5
                      		dte = floor(now);
                   	else
                      		dte = floor(datenum(varargin{5}));
                   	end
                   	if nargin < 4 || isempty(varargin{4})
                      		if exist('java.util.Date','class')
                         			tz = -java.util.Date().getTimezoneOffset/60;
                      		else
                         			tz = 0;
                      		end
                   	elseif nargin > 3
                      		tz = varargin{4};
                   	end
                   	if nargin < 3
                      		alt = 0;
                   	else
                      		alt = varargin{3};
                   	end
                   	% try to guess the location...
                   	if nargin < 2 || isempty(varargin{1}) || isempty(varargin{2})
                      		%api = 'http://freegeoip.net/json/';
                      		api = 'http://ip-api.com/json';
                      		if exist('webread','file')
                         			S = webread(api);
                         			if isfield(S,'lat') && isfield(S,'lon')
                            				lat = S.lat;
                            				lon = S.lon;
                         			else
                            				lat = NaN;
                            				lon = NaN;
                         			end
                      		else
                         			% for Matlab release < 2014b
                         			s = urlread(api);
                         			x = textscan(regexprep(s,'[{}"]',''),'%s','delimiter',',');
                         			lat = jsd(x{1},'lat:');
                         			lon = jsd(x{1},'lon:');
                      		end
                      		if isnan(lat) || isnan(lon)
                         			error('Cannot determine automatic location... sorry!')
                      		end
                      		autoloc = 1;
                   	else
                      		lat = varargin{1};
                      		lon = varargin{2};
                      		autoloc = 0;
                   	end
                   	% main computation
                   	[omega,noon] = omeganoon(lat,lon,alt,tz,dte);
                   	sset = noon + omega/360;
                   	srise = noon - omega/360;
                   	dayl = omega/180;
                   	switch nargout
                      		case 0
                         			if autoloc
                            				fprintf('Location: %g°N, %g°E, %gm\n',lat,lon,alt);
                         			end
                         			for n = 1:numel(dte)
                            				fprintf('Sunrise: %s %+03d\nSunset:  %s %+03d\nDay length: %gh %gmn %gs\n\n', ...
                               					datestr(srise(n)),tz,datestr(sset(n)),tz, ...
                               					floor(24*dayl),floor(mod(24*dayl,1)*60),round(mod(1440*dayl,1)*60));
                         			end
                      		case 1 % daylength
                         			varargout{1} = dayl;
                      		case 2 % sunrise, sunset
                         			varargout{1} = srise;
                         			varargout{2} = sset;
                      		case 3 % sunrise, sunset, noon
                         			varargout{1} = srise;
                         			varargout{2} = sset;
                         			varargout{3} = noon;
                   	end
                end
                % --- Inverse problem: sunrise(daylength,alt,dte,'day2lat') to latitude
                if day2lat
                    if nargin > 1
                      		dayl = varargin{1};
                      		if dayl < 0 || dayl > 1
                         			error('DAYLENGTH must be a value between 0 and 1.');
                      		end
                      		if nargin < 3
                         			alt = 0;
                      		else
                         			alt = varargin{2};
                      		end
                      		if nargin < 4
                         			dte = floor(now);
                      		else
                         			dte = floor(datenum(varargin{3}));
                      		end
                      		vlat = -90:90;
                      		vomega = omeganoon(vlat,0,alt,0,dte); % supposes longitude = 0
                      		[vdl,k] = unique(vomega/180);
                      		lat = interp1(vdl,vlat(k),dayl);
                      		if nargout > 0
                         			varargout{1} = lat;
                      		else
                         			fprintf('Estimated latitude: %g°N\n',lat);
                      		end
                   	else
                      		error('DAYLENGTH must be defined for reverse computation.')
                    end
                end
                % --- Inverse problem: sunrise(srise,sset,alt,'sun2ll') to lat/lon
                if sun2ll
                    if nargin > 2
                      		srise = datenum(varargin{1});
                      		sset = datenum(varargin{2});
                      		dayl = sset - srise;
                      		if dayl < 0 || dayl > 1
                         			error('SUNRISE and SUNSET times must be within 24 hours.')
                      		end
                      		if nargin < 4
                         			alt = 0;
                      		else
                         			alt = varargin{3};
                      		end
                      		
                      		% longitude from noon date and time
                      		vlon = -180:180;
                      		[~,vnoon] = omeganoon(0,vlon,alt,0,floor(srise)); % supposes latitude = 0
                      		[vno,k] = unique(vnoon);
                      		lon = interp1(vno,vlon(k),(srise+sset)/2);
                      		
                      		% latitude from daylength
                      		vlat = -90:90;
                      		vomega = omeganoon(vlat,lon,alt,0,floor(srise));
                      		[vdl,k] = unique(vomega/180);
                      		lat = interp1(vdl,vlat(k),dayl);
                      		if nargout > 1
                         			varargout(1:2) = {lat,lon};
                      		else
                         			fprintf('Estimated location: %g°N, %g°E\n',lat,lon);
                      		end
                   	else
                      		error('SUNRISE and SUNSET must be defined for reverse computation.')
                    end
                end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            function [omega,noon] = omeganoon(lat,lon,alt,tz,dte)
                % main function that computes daylength and noon time
                % https://en.wikipedia.org/wiki/Sunrise_equation
                % number of days since Jan 1st, 2000 12:00 UT
                n2000 = dte - datenum(2000,1,1,12,0,0) + 68.184/86400;
                % mean solar moon
                Js = n2000 - lon/360;
                % solar mean anomaly
                M = mod(357.5291 + 0.98560028*Js,360);
                % center
                C = 1.9148*sind(M) + 0.0200*sind(2*M) + 0.0003*sind(3*M);
                % ecliptic longitude
                lambda = mod(M + C + 180 + 102.9372,360);
                % solar transit
                Jt = 2451545.5 + Js + 0.0053*sind(M) - 0.0069*sind(2*lambda);
                % Sun declination
                delta = asind(sind(lambda)*sind(23.44));
                % hour angle (day expressed in geometric degrees)
                h = (sind(-0.83 - 2.076*sqrt(alt)/60) - sind(lat).*sind(delta))./(cosd(lat).*cosd(delta));
                omega = acosd(h);
                % to avoid meaningless complex angles: forces omega to 0 or 12h
                omega(h<-1) = 180;
                omega(h>1) = 0;
                omega = real(omega);
                % noon
                noon = Jt + datenum(2000,1,1,12,0,0) - 2451545 + tz/24;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            function y=jsd(X,f)
                % very simple interpretation of JSON string
                k = find(~cellfun(@isempty,strfind(X,f)),1);
                if ~isempty(k)
                   	y = str2double(regexprep(X{k},'.*:(.*)','$1'));
                else
                   	y = NaN;
                end
            end
        end

        % Callback function
        function OpenCleanedCT(app, event)
            OpenClCTFig = uifigure('Position', [350 350 260 150]);
            OpenClCTFig.Name = 'Open CT Project';
            OpenClCTFig.MenuBar = 'none';    % Hide standard menu bar menus.
            OpenClCTFig.Resize='off';
            OpenClCTFig.Visible = 'on';
            % create a panel inside the figure
            OpenClCTPan = uipanel('Parent', OpenClCTFig,'Units','pixels', 'Position',[10 10 240 130]);
            % label "Select folder"
            SelectFolderText = uilabel(OpenClCTPan,'Position', [10 100 120 20]);
            SelectFolderText.Text = 'Select Folder';
            % edit - selected folder to be read
            FolderPathOpenCT = uieditfield('Parent', OpenClCTPan,...
                'Position', [10 80 220 20], ...
                "Enable","on",...
                "Value",'C:\Users\');
            % browse button
            BrowseButton = uibutton(OpenClCTPan, 'push',...
                'ButtonPushedFcn', @(BrowseButton,event) PushBrowseButton(BrowseButton));
            BrowseButton.Position = [150 55 80 20];
            BrowseButton.Text = 'Browse';
            
       function PushBrowseButton(BrowseButton, event)
           folder = fullfile(matlabroot, 'C:\Users\');
           app.SelectedFolder = uigetdir(folder);
       if app.SelectedFolder == 0
       	           return;
       end
           FolderPathOpenCT.Value = app.SelectedFolder;
       end
            
            % OK button
            OKButton = uibutton(OpenClCTPan, 'push',...
                'ButtonPushedFcn', @(OKButton,event) PushOKButton(app,OKButton));
            OKButton.Position = [10 10 80 20];
            OKButton.Text = 'OK';
            
       function PushOKButton(OKButton, event)
            OpenClCTFig.Visible = 'off';
            CleanedCTPath = strcat(app.SelectedFolder,'\CleanedCT.mat');
            TempClickParameters = load(CleanedCTPath);
            app.ClickParameters = TempClickParameters.CleanedCT;
            TempClickParameters = [];
            CTInfoFile = strcat(app.SelectedFolder, '\CTInfo.mat');
            %load(ClickParFile);
            CTInfoTemp = load(CTInfoFile);
            app.CTInfo = CTInfoTemp.CTInfo;
                                
             CTNum = 1;
             UpdateCT(app, CTNum)
       end
                        
            % Cancel button
            CancelButton = uibutton(OpenClCTPan, 'push',...
                'ButtonPushedFcn', @(CancelButton,event) PushCancelButton(app,CancelButton));
            CancelButton.Position = [150 10 80 20];
            CancelButton.Text = 'Cancel';
      function PushCancelButton(CancelButton, event)
      OpenClCTFig.Visible = 'off';
      end
                        
            
        end

        % Button pushed function: BrowseButton_2
        function MetricBrowse(app, event)
            folder = fullfile(matlabroot, 'C:\Users\');
            % Selected folder that has subfolders
            app.topLevelFolderMetrics = uigetdir(folder);
            if app.topLevelFolderMetrics == 0
                return;
            end
            % Get list of all subfolders.
            allSubFoldersMetrics = genpath(app.topLevelFolderMetrics);
            % Parse into a cell array.
            remain = allSubFoldersMetrics;
            app.listOfFolderNamesMetrics = {};
            while true
            [singleSubFolder, remain] = strtok(remain, ';');
            if isempty(singleSubFolder)
                break;
            end
            app.listOfFolderNamesMetrics = [app.listOfFolderNamesMetrics singleSubFolder];
            end
            
            app.SelectFolderMetricEdit.Value = app.topLevelFolderMetrics;
            app.UploadData.Enable = 'on';
        end

        % Callback function
        function MetricsOK(app, event)
            % search dates within the selected folder
            % Name of folders are 20150812 -> 12 Aug 2015
            % Create a list of days and display in drop down button
            % Create table with dates
            Metrics = table();
            app.FoldersIn = uigetdir(app.MetricSelectedFolder);
            Folders = app.FoldersIn([app.FoldersIn.isdir] == 1);
            if length(Folders) > 3
                for d = 3:length(Folders)
                    NameDate = Folders(d).name;
                    yyyy = str2double(NameDate(1:4)); mmm = str2double(NameDate(5:6)); dd = str2double(NameDate(7:8));
                    Metrics.Date{d-2,1} = datestr(datetime(yyyy,mmm,dd));
                    % Continue later getting more metrics
                    %Metrics(d-2,2) =
                    %Metrics(d-2,3) =
                end
            else
                NameDate = app.MetricSelectedFolder(end-7:end);
                yyyy = str2double(NameDate(1:4)); mmm = str2double(NameDate(5:6)); dd = str2double(NameDate(7:8));
                Metrics.Date{1,1} = datestr(datetime(yyyy,mmm,dd));
            end
            
        end

        % Button pushed function: UploadData
        function UploadDataMetrics(app, event)
        SelectedProject = uigetdir(app.topLevelFolderMetrics);
        FileToSearch = {SelectedProject.name};
        b = ismember(FileToSearch, 'PosPorMin.mat'); %upload if it exists
        % if the data is already prepared
        c = ismember(FileToSearch, 'SummaryTable.mat'); %upload if it exists
        if sum(c) == 1 && sum(b) == 1
          FileToOpenCT = strcat(app.topLevelFolderMetrics, '\SummaryTable.mat'); 
          CTFileCT = load(FileToOpenCT);
          app.SummaryTable = CTFileCT.SummaryTable; 
          FileToOpenPPM = strcat(app.topLevelFolderMetrics, '\PosPorMin.mat'); 
          CTFilePPM = load(FileToOpenPPM);
          app.PosPorMin = CTFilePPM.PosPorMin; 
          app.numberOfFoldersMetrics = length(app.listOfFolderNamesMetrics);
          app.Minutes(1,1) = 1440;
          app.Minutes(2:1440,1) = 1:1439; % double
          app.Minutes = minutes(app.Minutes); % char with 5 col
        app.Minutes = datestr(app.Minutes, 'HH:MM');
        app.Minutes = datenum(app.Minutes);
        % If the data needs to be prepared
        else 

        app.Minutes(1,1) = 1440;
        app.Minutes(2:1440,1) = 1:1439; % double
        app.Minutes = minutes(app.Minutes); % char with 5 col
        app.Minutes = datestr(app.Minutes, 'HH:MM');
        app.Minutes = datenum(app.Minutes);
        % Put in Clicks Per Minute
        clear a ClickPerMin
        a = table();
        a.Min(1:1440,1) = app.Minutes;
        app.PosPorMin = table();
        app.PosPorMin.Min(1:1440,1:5) = datestr(a.Min(1:1440,1), 'HH:MM');
        app.PosPorMin.Min(1:1440,1) = convertCharsToStrings(app.PosPorMin.Min(1:1440,1));
        app.PosPorMin.Positive(1:1440,1) = 0;
        app.PosPorMin.NumCT(1:1440,1) = 0;
        app.PosPorMin.NumClicks(1:1440,1) = 0;
        TimesRec = table();
        app.numberOfFoldersMetrics = length(app.listOfFolderNamesMetrics);

        for FolderN = 2:app.numberOfFoldersMetrics
            assignin('base', 'FolderN', FolderN)
            % load file
            thisFolder = app.listOfFolderNamesMetrics{FolderN}; 
            CTInfoNames = strcat(thisFolder,'\CTInfoNew.mat');
            CTInfoFilesInFolder = uigetdir(CTInfoNames);
            CTInfoFileName = CTInfoFilesInFolder(1).name;
            CTInfoTemp = load(CTInfoNames);
            app.CTInfo = CTInfoTemp.CTInfo;            
            % Load ClickParameters for time estimations
            ClickParNames = strcat(thisFolder,'\ClickParametersTested.mat');
            ClickParFilesInFolder = uigetdir(ClickParNames);
            ClickParFileName = ClickParFilesInFolder(1).name;
            app.ClickParFile = strcat(thisFolder,'\', ClickParFileName);
            TempClickParameters = load(app.ClickParFile);
            app.ClickParameters = TempClickParameters.ClickParameters;
            TempClickParameters = [];
            
            TimesRec.Date(FolderN-1,1) = app.ClickParameters.Date(10,1);
            TimesRec.Start(FolderN-1,1) = app.ClickParameters.Date(1,1);
            TimesRec.End(FolderN-1,1) = app.ClickParameters.Date(end,1);
            TimesRec.Min(FolderN-1,1) = TimesRec.End(FolderN-1,1) - TimesRec.Start(FolderN-1,1);
            
            % Create new columns
            app.PosPorMin.Positive(1:1440,FolderN-1) = 0;
            app.PosPorMin.NumCT(1:1440,FolderN-1) = 0;
            app.PosPorMin.NumClicks(1:1440,FolderN-1) = 0;
            
            % check the positive min
            AllNBHF = app.CTInfo(strcmp({app.CTInfo.Species}, 'NBHF'));% | strcmp({CTInfo.Species}, 'LQ-NBHF'));
            for i = 1:length(AllNBHF)
              Min = datenum(AllNBHF(i).Date);
              Min = datestr(Min, 'HH:MM');
            %  Min = convertCharsToStrings(Min);
            %  Min = Min{2:3};
              Hour = str2double(Min(1:2))*60;
              Min = str2double(Min(4:5));
              RowN = Hour+Min+1;
              app.PosPorMin.Positive(RowN,FolderN-1) = 1;
              app.PosPorMin.NumCT(RowN,FolderN-1) = app.PosPorMin.NumCT(RowN,FolderN-1) + 1;
              app.PosPorMin.NumClicks(RowN,FolderN-1) = app.PosPorMin.NumClicks(RowN,FolderN-1) + AllNBHF(i).Length;
            end 
        
        end

        %% SAVE POSITIVE PORPOISE MINUTES
        AllPPMFile = strcat(app.topLevelFolderMetrics, '\PosPorMin.mat');
        PosPorMin = app.PosPorMin;
        save(AllPPMFile, 'PosPorMin');

          RowNCT = -1;
          app.SummaryTable = table();
          for FolderN = 2:app.numberOfFoldersMetrics
                   % Get this folder and print it out.
                clear thisFolder CTInfoName ClickParNames ClickParFilesInFolder...
                       ClickParFile ClickParameters
                thisFolder = app.listOfFolderNamesMetrics{FolderN}; 
                ClickParNames = strcat(thisFolder,'\ClickParameters*.mat');
                ClickParFilesInFolder = uigetdir(ClickParNames);
                ClickParFileName = ClickParFilesInFolder(1).name;
                app.ClickParFile = strcat(thisFolder,'\', ClickParFileName);
                TempClickParameters = load(app.ClickParFile);
                app.ClickParameters = TempClickParameters.ClickParameters;
                
                TempClickParameters = [];
                LDate = size(app.ClickParameters,1);
                app.DATE = app.ClickParameters.Date(round(LDate/2),1);
                
             if app.LAT ~= 0 && app.LONG ~= 0
                    [srise, sset, ~] = sunrise(app.LAT, app.LONG, 0, [], app.DATE);
                
                    % Sunrise
                    SunriseTime = datestr(srise);
                    HourSunR = SunriseTime(13:14);
                    HourSunR = str2double(HourSunR);
                    MinSunR = SunriseTime(16:17);
                    MinSunR = str2double(MinSunR);
                    SunRSum = (HourSunR*60) + MinSunR;
                    % Sunset
                    SunsetTime = datestr(srise);
                    HourSunS = SunsetTime(13:14);
                    HourSunS = str2double(HourSunS);
                    MinSunS = SunsetTime(16:17);
                    MinSunS = str2double(MinSunS);
                    SunSSum = (HourSunS*60) + MinSunS;
            else
                % nothing
            end 
                         
            % Open CTInfo and extract them
               CTInfoName = strcat(thisFolder, '\CTInfoNew.mat');
               CTInfoTemp = load(CTInfoName);
               app.CTInfo = CTInfoTemp.CTInfo;
            
               %% CTInfo NBHF decision
                 clear RowsNBHF NewNBHF
                 RowNCT = RowNCT + 2;
                 % CT types
                 app.NBHF = app.CTInfo(strcmp({app.CTInfo.Species}, 'NBHF'));
                 app.LQNBHF = app.CTInfo(strcmp({app.CTInfo.Species}, 'LQ-NBHF'));
                 app.NonNBHF = app.CTInfo(strcmp({app.CTInfo.Species}, 'Non-NBHF'));
                 app.Sonar = app.CTInfo(strcmp({app.CTInfo.Species}, 'Sonar'));
                 app.Orient = app.CTInfo(strcmp({app.CTInfo.Behav}, 'Orientation'));
                 app.Forage = app.CTInfo(strcmp({app.CTInfo.Behav}, 'Foraging'));
                 app.Comm = app.CTInfo(strcmp({app.CTInfo.Behav}, 'Socialising'));
                 app.Unknown = app.CTInfo(strcmp({app.CTInfo.Behav}, 'Unknown'));
     
                % app.Calves = app.CTInfo(strcmp({app.CTInfo.Calf}, 'Yes'));
                % app.NoCalves = length(app.NBHF) - length(app.Calves);% app.CTInfo(strcmp({app.CTInfo.Calf}, 'No'));
                 cla(app.CPMAxesMetrics, 'reset')  

                 % Day time
                 app.SummaryTable.Date(RowNCT,1) = app.DATE;
                 app.SummaryTable.RealDate(RowNCT,1) = {datestr(app.SummaryTable.Date(RowNCT,1), 'dd-mmm-yyyy')};
                 app.SummaryTable.NBHF(RowNCT,1) = sum(strcmp({app.NBHF.DayNight}, 'Day'));
                 app.SummaryTable.LQNBHF(RowNCT,1) = sum(strcmp({app.LQNBHF.DayNight}, 'Day'));
                 app.SummaryTable.NonNBHF(RowNCT,1) = sum(strcmp({app.NonNBHF.DayNight}, 'Day'));
                 app.SummaryTable.Sonar(RowNCT,1) = sum(strcmp({app.Sonar.DayNight}, 'Day'));
                 app.SummaryTable.Orient(RowNCT,1) = sum(strcmp({app.Orient.DayNight}, 'Day'));
                 app.SummaryTable.Forage(RowNCT,1) = sum(strcmp({app.Forage.DayNight}, 'Day'));
                 app.SummaryTable.Social(RowNCT,1) = sum(strcmp({app.Comm.DayNight}, 'Day'));
                 app.SummaryTable.Unknown(RowNCT,1) = sum(strcmp({app.Unknown.DayNight}, 'Day'));
                 app.SummaryTable.Day(RowNCT,1) = 1;
                 app.SummaryTable.Night(RowNCT,1) = 0;
%                 ClickTrains.Calves(RowNCT,1) = sum(strcmp({app.Calves.DayNight}, 'Day'));
                 % night time
                 app.SummaryTable.Date(RowNCT+1,1) = app.DATE;
                 app.SummaryTable.RealDate(RowNCT+1,1) = {datestr(app.SummaryTable.Date(RowNCT+1,1), 'dd-mmm-yyyy')};
                 app.SummaryTable.NBHF(RowNCT+1,1) = sum(strcmp({app.NBHF.DayNight}, 'Night'));
                 app.SummaryTable.LQNBHF(RowNCT+1,1) = sum(strcmp({app.LQNBHF.DayNight}, 'Night'));
                 app.SummaryTable.NonNBHF(RowNCT+1,1) = sum(strcmp({app.NonNBHF.DayNight}, 'Night'));
                 app.SummaryTable.Sonar(RowNCT+1,1) = sum(strcmp({app.Sonar.DayNight}, 'Night'));
                 app.SummaryTable.Orient(RowNCT+1,1) = sum(strcmp({app.Orient.DayNight}, 'Night'));
                 app.SummaryTable.Forage(RowNCT+1,1) = sum(strcmp({app.Forage.DayNight}, 'Night'));
                 app.SummaryTable.Social(RowNCT+1,1) = sum(strcmp({app.Comm.DayNight}, 'Night'));
                 app.SummaryTable.Unknown(RowNCT+1,1) = sum(strcmp({app.Unknown.DayNight}, 'Night'));
                 app.SummaryTable.Day(RowNCT+1,1) = 0;
                 app.SummaryTable.Night(RowNCT+1,1) = 1;
               %  ClickTrains.Calves(RowNCT+1,1) = sum(strcmp({app.Calves.DayNight}, 'Night'));
                 
          end % For - going into each folder
        end % If it already exists or not
            ClickTrainsName = strcat(app.topLevelFolderMetrics,'\SummaryTable.mat');
            SummaryTable = app.SummaryTable;
            save(ClickTrainsName, 'SummaryTable');
            
             %   app.DataDislplayLabel.Text = OriginalText;
            DaysList = app.SummaryTable.RealDate(:,1);
            DaysList = unique(DaysList);
            DaysList = sort(DaysList);

                 
            % list of days
            DropDown = vertcat('All days', DaysList);
            app.SelectadateDropDown.Items = DropDown;
            app.SelectadateDropDown.Enable = 'on';
            app.SelectadateDropDown.Enable = 'on';
            app.DisplayButton.Enable = 'on';
   
            %% Variables I need to display
            app.NBHF = sum(app.SummaryTable.NBHF);
            app.LQNBHF = sum(app.SummaryTable.LQNBHF);
            app.NonNBHF = sum(app.SummaryTable.NonNBHF);
            app.Sonar = sum(app.SummaryTable.Sonar);
            app.Orient = sum(app.SummaryTable.Orient);
            app.Forage = sum(app.SummaryTable.Forage);
            app.Comm = sum(app.SummaryTable.Social);
            app.Unknown = sum(app.SummaryTable.Unknown);
            % app.Calves = sum(ClickTrains.Calves);
            %app.NoCalves = length(app.NoCalves);
            
            % Day vs Night
            app.Night = app.SummaryTable(app.SummaryTable.Night == 1,:);
            app.Day = app.SummaryTable(app.SummaryTable.Day == 1,:);
                % Day
            app.NBHFd = sum(app.Day.NBHF);
            app.LQNBHFd = sum(app.Day.LQNBHF);
            app.NonNBHFd = sum(app.Day.NonNBHF);
            app.NoiseD = sum(app.Day.Sonar);
            app.TravelD = sum(app.Day.Orient);
            app.ForageD = sum(app.Day.Forage);
            app.CommD = sum(app.Day.Social);
            app.UnknownD = sum(app.Day.Unknown);
          %  app.CalvesD = sum(app.Day.Calves);
                % Night
            app.NBHFn = sum(app.Night.NBHF);
            app.LQNBHFn = sum(app.Night.LQNBHF);
            app.NonNBHFn = sum(app.Night.NonNBHF);
            app.NoiseN = sum(app.Night.Sonar);
            app.TravelN = sum(app.Night.Orient);
            app.ForageN = sum(app.Night.Forage);
            app.CommN = sum(app.Night.Social);
            app.UnknownN = sum(app.Night.Unknown);
         %   app.CalvesN = sum(app.Night.Calves);
            
            %%% Estimate sunset and sunrise
            %% Citation
            % François Beauducel (2019). SUNRISE: sunrise and sunset times
            % (https://www.mathworks.com/matlabcentral/fileexchange/64692-sunrise-sunrise-and-sunset-times),
            % MATLAB Central File Exchange. Retrieved October 28, 2019
            

            function varargout=sunrise(varargin)
                %SUNRISE Computes sunset and sunrise time.
                %	SUNRISE(LAT,LON,ALT,TZ) displays time of sunrise and sunset at
                %	location latitude LAT (decimal degrees, positive towards North),
                %	longitude LON (decimal degrees, positive towards East), altitude ALT
                %	(in meters above sea level) for today. Time zone TZ (in hour) is
                %	recommanded but optional (default is the computer system time zone).
                %
                %	SUNRISE(LAT,LON,ALT,TZ,DATE) computes sunrise and sunset time for
                %	date DATE (in any datenum compatible format). DATE can be scalar,
                %	vector or matrix of dates (strings or datenum).
                %
                %	SUNRISE by itself displays time of sunrise and sunset at your
                %	approximate location (needs Java activated and internet connection).
                %
                %	[SRISE,SSET,NOON]=SUNRISE(...) returns time of sunrise, sunset and noon
                %	in datenum format. To get only hours, try datestr(srise,'HH:MM').
                %
                %	DAYLENGTH=SUNRISE(...) returns the day length of light expressed in
                %	fraction of day. Multiply by 24 to get hours.
                %
                %   LAT=SUNRISE(DAYLENGTH,ALT,DATE,'day2lat') estimates the latitude from
                %	day length (in fraction of day) and altitude ALT (in meters) for the
                %	day DATE. If ALT is ommitted it uses sea level (0 m). If DATE is
                %	ommitted it computes for today.
                %
                %   [LAT,LON]=SUNRISE(SRISE,SSET,ALT,'sun2ll') estimates the latitude and
                %	longitude from sunrise and sunset dates/time, both in GMT time zone
                %	(TZ = 0), and altitude ALT (in meters). If ALT is ommitted it uses sea
                %	level (0 m).
                %
                %	Note: reverse function does not try to fit the sunrise and sunset times,
                %	but it uses both noon time (average of sunrise/sunset) and day length
                %	(difference between sunrise/sunset) values to look for the best
                %	latitude and longitude.
                %
                %
                %	Examples:
                %		>> sunrise(37.71,15.03,2031,2,'2017-07-17')
                %		Sunrise: 17-Jul-2017 05:43:10 +02
                %		Sunset:  17-Jul-2017 20:30:39 +02
                %		Day length: 14h 47mn 30s
                %
                %		>> sunrise
                %		Location: -8.65°N, 115.2167°E, 0m
                %		Sunrise: 16-Oct-2017 05:57:07 +08
                %		Sunset:  16-Oct-2017 18:14:31 +08
                %		Day length: 12h 17mn 24s
                %
                %       >> sunrise(14/24,0,'2019-04-21','day2lat')
                %       Estimated latitude: 49.076°N
                %
                %		>> sunrise('2019-04-22 04:52:12','2019-04-22 18:51:04',0,'sun2ll')
                %		Estimated location: 47.9995°N, 2.00142°E
                %
                %		Plot sunrise and sunset time variation for the year:
                %		days = datenum(2017,1,1:365);
                %		[srise,sset,noon]=sunrise(48.8,2.3,0,2,days);
                %		plot(days,rem([srise;sset;noon]',1)*24,'linewidth',4)
                %		datetick('x')
                %
                %
                %	Reference:
                %	    https://en.wikipedia.org/wiki/Sunrise_equation
                %
                %	Author: Francois Beauducel, IPGP
                %	Created: 2017-10-10 in Paris, France
                %	Updated: 2019-08-18
                %	Release history:
                %   [2019-08-18] v1.4
                %       - fix an issue in reverse function for western longitudes
                %	[2019-04-21] v1.3
                %		- adds reverse functions to compute lat/lon from sunrise/sunset
                %		  (thanks to a suggestion by Jaechan Lim)
                %	[2018-10-17] v1.2
                %		- changes to ip-api.com for automatic geolocalisation
                %	[2017-10-16] v1.1
                %		- removes dependency from jsondecode function
                %		- automatic detection of local timezone (needs Java)
                %	[2017-10-10] v1.0
                %		- initial function
                %
                %	Copyright (c) 2019, François Beauducel, covered by BSD License.
                %	All rights reserved.
                %
                %	Redistribution and use in source and binary forms, with or without
                %	modification, are permitted provided that the following conditions are
                %	met:
                %
                %	   * Redistributions of source code must retain the above copyright
                %	     notice, this list of conditions and the following disclaimer.
                %	   * Redistributions in binary form must reproduce the above copyright
                %	     notice, this list of conditions and the following disclaimer in
                %	     the documentation and/or other materials provided with the
                %	     distribution
                %
                %	THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS
                %	IS" AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED
                %	TO, THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A
                %	PARTICULAR PURPOSE ARE DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT
                %	OWNER OR CONTRIBUTORS BE LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL,
                %	SPECIAL, EXEMPLARY, OR CONSEQUENTIAL DAMAGES (INCLUDING, BUT NOT
                %	LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR SERVICES; LOSS OF USE,
                %	DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER CAUSED AND ON ANY
                %	THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY, OR TORT
                %	(INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                %	OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
                day2lat = any(strcmpi(varargin,'day2lat'));
                sun2ll = any(strcmpi(varargin,'sun2ll'));
                % --- Forward problem: sunrise(lat,lon,alt,tz,dte)
                if ~day2lat && ~sun2ll
                   	if nargin < 5
                      		dte = floor(now);
                   	else
                      		dte = floor(datenum(varargin{5}));
                   	end
                   	if nargin < 4 || isempty(varargin{4})
                      		if exist('java.util.Date','class')
                         			tz = -java.util.Date().getTimezoneOffset/60;
                      		else
                         			tz = 0;
                      		end
                   	elseif nargin > 3
                      		tz = varargin{4};
                   	end
                   	if nargin < 3
                      		alt = 0;
                   	else
                      		alt = varargin{3};
                   	end
                   	% try to guess the location...
                   	if nargin < 2 || isempty(varargin{1}) || isempty(varargin{2})
                      		%api = 'http://freegeoip.net/json/';
                      		api = 'http://ip-api.com/json';
                      		if exist('webread','file')
                         			S = webread(api);
                         			if isfield(S,'lat') && isfield(S,'lon')
                            				lat = S.lat;
                            				lon = S.lon;
                         			else
                            				lat = NaN;
                            				lon = NaN;
                         			end
                      		else
                         			% for Matlab release < 2014b
                         			s = urlread(api);
                         			x = textscan(regexprep(s,'[{}"]',''),'%s','delimiter',',');
                         			lat = jsd(x{1},'lat:');
                         			lon = jsd(x{1},'lon:');
                      		end
                      		if isnan(lat) || isnan(lon)
                         			error('Cannot determine automatic location... sorry!')
                      		end
                      		autoloc = 1;
                   	else
                      		lat = varargin{1};
                      		lon = varargin{2};
                      		autoloc = 0;
                   	end
                   	% main computation
                   	[omega,noon] = omeganoon(lat,lon,alt,tz,dte);
                   	sset = noon + omega/360;
                   	srise = noon - omega/360;
                   	dayl = omega/180;
                   	switch nargout
                      		case 0
                         			if autoloc
                            				fprintf('Location: %g°N, %g°E, %gm\n',lat,lon,alt);
                         			end
                         			for n = 1:numel(dte)
                            				fprintf('Sunrise: %s %+03d\nSunset:  %s %+03d\nDay length: %gh %gmn %gs\n\n', ...
                               					datestr(srise(n)),tz,datestr(sset(n)),tz, ...
                               					floor(24*dayl),floor(mod(24*dayl,1)*60),round(mod(1440*dayl,1)*60));
                         			end
                      		case 1 % daylength
                         			varargout{1} = dayl;
                      		case 2 % sunrise, sunset
                         			varargout{1} = srise;
                         			varargout{2} = sset;
                      		case 3 % sunrise, sunset, noon
                         			varargout{1} = srise;
                         			varargout{2} = sset;
                         			varargout{3} = noon;
                   	end
                end
                % --- Inverse problem: sunrise(daylength,alt,dte,'day2lat') to latitude
                if day2lat
                    if nargin > 1
                      		dayl = varargin{1};
                      		if dayl < 0 || dayl > 1
                         			error('DAYLENGTH must be a value between 0 and 1.');
                      		end
                      		if nargin < 3
                         			alt = 0;
                      		else
                         			alt = varargin{2};
                      		end
                      		if nargin < 4
                         			dte = floor(now);
                      		else
                         			dte = floor(datenum(varargin{3}));
                      		end
                      		vlat = -90:90;
                      		vomega = omeganoon(vlat,0,alt,0,dte); % supposes longitude = 0
                      		[vdl,k] = unique(vomega/180);
                      		lat = interp1(vdl,vlat(k),dayl);
                      		if nargout > 0
                         			varargout{1} = lat;
                      		else
                         			fprintf('Estimated latitude: %g°N\n',lat);
                      		end
                   	else
                      		error('DAYLENGTH must be defined for reverse computation.')
                    end
                end
                % --- Inverse problem: sunrise(srise,sset,alt,'sun2ll') to lat/lon
                if sun2ll
                    if nargin > 2
                      		srise = datenum(varargin{1});
                      		sset = datenum(varargin{2});
                      		dayl = sset - srise;
                      		if dayl < 0 || dayl > 1
                         			error('SUNRISE and SUNSET times must be within 24 hours.')
                      		end
                      		if nargin < 4
                         			alt = 0;
                      		else
                         			alt = varargin{3};
                      		end
                      		
                      		% longitude from noon date and time
                      		vlon = -180:180;
                      		[~,vnoon] = omeganoon(0,vlon,alt,0,floor(srise)); % supposes latitude = 0
                      		[vno,k] = unique(vnoon);
                      		lon = interp1(vno,vlon(k),(srise+sset)/2);
                      		
                      		% latitude from daylength
                      		vlat = -90:90;
                      		vomega = omeganoon(vlat,lon,alt,0,floor(srise));
                      		[vdl,k] = unique(vomega/180);
                      		lat = interp1(vdl,vlat(k),dayl);
                      		if nargout > 1
                         			varargout(1:2) = {lat,lon};
                      		else
                         			fprintf('Estimated location: %g°N, %g°E\n',lat,lon);
                      		end
                   	else
                      		error('SUNRISE and SUNSET must be defined for reverse computation.')
                    end
                end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            function [omega,noon] = omeganoon(lat,lon,alt,tz,dte)
                % main function that computes daylength and noon time
                % https://en.wikipedia.org/wiki/Sunrise_equation
                % number of days since Jan 1st, 2000 12:00 UT
                n2000 = dte - datenum(2000,1,1,12,0,0) + 68.184/86400;
                % mean solar moon
                Js = n2000 - lon/360;
                % solar mean anomaly
                M = mod(357.5291 + 0.98560028*Js,360);
                % center
                C = 1.9148*sind(M) + 0.0200*sind(2*M) + 0.0003*sind(3*M);
                % ecliptic longitude
                lambda = mod(M + C + 180 + 102.9372,360);
                % solar transit
                Jt = 2451545.5 + Js + 0.0053*sind(M) - 0.0069*sind(2*lambda);
                % Sun declination
                delta = asind(sind(lambda)*sind(23.44));
                % hour angle (day expressed in geometric degrees)
                h = (sind(-0.83 - 2.076*sqrt(alt)/60) - sind(lat).*sind(delta))./(cosd(lat).*cosd(delta));
                omega = acosd(h);
                % to avoid meaningless complex angles: forces omega to 0 or 12h
                omega(h<-1) = 180;
                omega(h>1) = 0;
                omega = real(omega);
                % noon
                noon = Jt + datenum(2000,1,1,12,0,0) - 2451545 + tz/24;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            function y=jsd(X,f)
                % very simple interpretation of JSON string
                k = find(~cellfun(@isempty,strfind(X,f)),1);
                if ~isempty(k)
                   	y = str2double(regexprep(X{k},'.*:(.*)','$1'));
                else
                   	y = NaN;
                end
            end
            
        end

        % Value changed function: SelectadateDropDown
        function DropDownDays(app, event)
            app.SelectedDays = app.SelectadateDropDown.Value;
        end

        % Button pushed function: DisplayButton
        function DisplayMetrics(app, event)
          % clear axis
          cla(app.CPMAxesTimes, 'reset') 
          cla(app.CPMAxesTot, 'reset') 
          cla(app.CPMAxesMetrics, 'reset')        
          hold(app.CPMAxesMetrics, 'off')
          app.CPMAxesMetrics.XLabel.String = [];
          app.CPMAxesMetrics.XTick = [];
          
         % What to display   
          TypeCT = app.TypeofCTButton.Value;
          BehavCT = app.BehaviourButton.Value;
          CPM = app.PositiveporpoiseminuteButton.Value;
         % All vs Day/Night
          All = app.AllButtonMetrics.Value;
          DayNight = app.NightDayButton.Value;
            % Which day(s) to display
                
         % Table        
         app.MetricsTable.Data = app.SummaryTable(:,2:12);
         % If all is selected
         DaysToPlot = app.SelectadateDropDown.Value;
         
         if strcmp(DaysToPlot, 'All days')
            ClickTrainsToDisplay = app.SummaryTable(:,2:12); 
            app.NBHF = sum(ClickTrainsToDisplay.NBHF);
            app.LQNBHF = sum(ClickTrainsToDisplay.LQNBHF);
            app.NonNBHF = sum(ClickTrainsToDisplay.NonNBHF);
            app.Sonar = sum(ClickTrainsToDisplay.Sonar);
            app.Orient = sum(ClickTrainsToDisplay.Orient);
            app.Forage = sum(ClickTrainsToDisplay.Forage);
            app.Comm = sum(ClickTrainsToDisplay.Social);
            app.Unknown = sum(ClickTrainsToDisplay.Unknown);
             
            % Day vs Night
            app.Night = ClickTrainsToDisplay(ClickTrainsToDisplay.Night == 1,:);
            app.Day = ClickTrainsToDisplay(ClickTrainsToDisplay.Day == 1,:);
                % Day
            app.NBHFd = sum(app.Day.NBHF);
            app.LQNBHFd = sum(app.Day.LQNBHF);
            app.NonNBHFd = sum(app.Day.NonNBHF);
            app.NoiseD = sum(app.Day.Sonar);
            app.TravelD = sum(app.Day.Orient);
            app.ForageD = sum(app.Day.Forage);
            app.CommD = sum(app.Day.Social);
            app.UnknownD = sum(app.Day.Unknown);
          %  app.CalvesD = sum(app.Day.Calves);
                % Night
            app.NBHFn = sum(app.Night.NBHF);
            app.LQNBHFn = sum(app.Night.LQNBHF);
            app.NonNBHFn = sum(app.Night.NonNBHF);
            app.NoiseN = sum(app.Night.Sonar);
            app.TravelN = sum(app.Night.Orient);
            app.ForageN = sum(app.Night.Forage);
            app.CommN = sum(app.Night.Social);
            app.UnknownN = sum(app.Night.Unknown);
            TotalClickTrains = sum(app.NBHF) + sum(app.LQNBHF)...
                    + sum(app.NonNBHF) + sum(app.Sonar);
         else
            DaysToPlot = datenum(DaysToPlot);
            Candidates = find(app.SummaryTable.Date < DaysToPlot+1);
            ClickTrainsToDisplay = app.SummaryTable(Candidates(end-1:end),2:12);
            app.NBHF = sum(ClickTrainsToDisplay.NBHF);
            app.LQNBHF = sum(ClickTrainsToDisplay.LQNBHF);
            app.NonNBHF = sum(ClickTrainsToDisplay.NonNBHF);
            app.Sonar = sum(ClickTrainsToDisplay.Sonar);
            % behaviour
            app.Orient = sum(ClickTrainsToDisplay.Orient);
            app.Forage = sum(ClickTrainsToDisplay.Forage);
            app.Comm = sum(ClickTrainsToDisplay.Social);
            app.Unknown = sum(ClickTrainsToDisplay.Unknown);
             
            % Day vs Night
            app.Night = ClickTrainsToDisplay(ClickTrainsToDisplay.Night == 1,:);
            app.Day = ClickTrainsToDisplay(ClickTrainsToDisplay.Day == 1,:);
            % Day
            app.NBHFd = sum(app.Day.NBHF);
            app.LQNBHFd = sum(app.Day.LQNBHF);
            app.NonNBHFd = sum(app.Day.NonNBHF);
            app.NoiseD = sum(app.Day.Sonar);
            % behaviour
            app.TravelD = sum(app.Day.Orient);
            app.ForageD = sum(app.Day.Forage);
            app.CommD = sum(app.Day.Social);
            app.UnknownD = sum(app.Day.Unknown);
          %  app.CalvesD = sum(app.Day.Calves);
                % Night
            app.NBHFn = sum(app.Night.NBHF);
            app.LQNBHFn = sum(app.Night.LQNBHF);
            app.NonNBHFn = sum(app.Night.NonNBHF);
            app.NoiseN = sum(app.Night.Sonar);
            app.TravelN = sum(app.Night.Orient);
            app.ForageN = sum(app.Night.Forage);
            app.CommN = sum(app.Night.Social);
            app.UnknownN = sum(app.Night.Unknown);
            TotalClickTrains = sum(app.NBHF) + sum(app.LQNBHF)...
                    + sum(app.NonNBHF) + sum(app.Sonar);
         end
 
         %% All days together
         if All == 1             
             if TypeCT == 1
                % Plot bars
                app.DISPLAYPanelCPM.Visible = 'off';
                app.DISPLAYPanelMetrics.Visible = 'on';
                cla(app.CPMAxesMetrics, 'reset')
                app.CPMAxesMetrics.YLabel.String = 'Percentage';
                app.CPMAxesMetrics.XLabel.String = 'Type of Click Train';
                app.CPMAxesMetrics.XTickMode = 'auto';
                x = {'NBHF', 'LQ-NBHF', 'Non-NBHF', 'Sonar'};
                x = categorical(x);
                if app.Sonar ~= 0
                    y = [(app.NBHF/TotalClickTrains)*100 (app.LQNBHF/TotalClickTrains)*100 ...
                        (app.NonNBHF/TotalClickTrains)*100 (app.Sonar/TotalClickTrains)*100];
                else
                    y = [(app.NBHF/TotalClickTrains)*100 (app.LQNBHF/TotalClickTrains)*100 ...
                        (app.NonNBHF/TotalClickTrains)*100 (app.Sonar/TotalClickTrains)*100];
                end
                bar(app.CPMAxesMetrics, x(1), y(1))
                hold(app.CPMAxesMetrics, 'on')
                bar(app.CPMAxesMetrics, x(2), y(2))
                bar(app.CPMAxesMetrics, x(3), y(3))
                bar(app.CPMAxesMetrics, x(4), y(4))
                app.CPMAxesMetrics.XLabel.String = 'Click train type';
             elseif BehavCT == 1
                app.DISPLAYPanelCPM.Visible = 'off';
                app.DISPLAYPanelMetrics.Visible = 'on';
                cla(app.CPMAxesMetrics, 'reset')
                app.CPMAxesMetrics.YLabel.String = 'Number';
                app.CPMAxesMetrics.XLabel.String = 'Behaviour';
                app.CPMAxesMetrics.XTickMode = 'auto';
                x = {'Orientation', 'Foraging', 'Socialising', 'Unknown'};
                x = categorical(x);
                y = [app.Travel app.Forage app.Comm app.Unknown];
                bar(app.CPMAxesMetrics, x(1), y(1))
                hold(app.CPMAxesMetrics, 'on')
                bar(app.CPMAxesMetrics, x(2), y(2))
                bar(app.CPMAxesMetrics, x(3), y(3))
                bar(app.CPMAxesMetrics, x(4), y(4))
                hold(app.CPMAxesMetrics, 'off')
                app.CPMAxesMetrics.XLabel.String = 'Behaviour';
             elseif CPM == 1
                app.DISPLAYPanelCPM.Visible = 'on';
                app.DISPLAYPanelMetrics.Visible = 'off';
                app.CPMAxesTimes.Visible = 'on';
                app.CPMAxesTot.Visible = 'on';
                cla(app.CPMAxesTimes, 'reset')
                cla(app.CPMAxesTot, 'reset')
                %% Positive Porpoise Minutes
                % Plotting
                 for j = 1:app.numberOfFoldersMetrics-1
                     for i = 1:1440
                         if app.PosPorMin.Positive(i,j)
                           plot(app.CPMAxesTimes, app.Minutes(i,1), j, '.', 'color', 'black')
                           hold(app.CPMAxesTimes, 'on')
                         else
                             % nothing
                         end
                     end
                 end
                 app.CPMAxesTimes.YTick = [1:app.numberOfFoldersMetrics-1];
                 for j = 1:app.numberOfFoldersMetrics-1
                     Folder = app.listOfFolderNamesMetrics{j+1};
                     DateName = Folder(end-7:end);
                     FDay = DateName(7:8);
                     Month = DateName(5:6);
                     FDate = strcat(FDay,'/',Month);
                     FDate = {FDate};
                     app.CPMAxesTimes.YTickLabel(j) = FDate;
                 end
                 xlabel(app.CPMAxesTimes,'Min of the day')
                 hold(app.CPMAxesTimes,'off')
                 datetick(app.CPMAxesTimes,'x', 'HH:MM')
                 grid(app.CPMAxesTimes,'minor')
                 
                 for j =1:app.numberOfFoldersMetrics-1
                   stem(app.CPMAxesTot, j , sum(app.PosPorMin.Positive(:,j)) ,'.', 'black')
                   hold(app.CPMAxesTot, 'on')
                 end
                 ylabel(app.CPMAxesTot,'Positive minutes')
                 app.CPMAxesTot.XTick = [];
                 view(app.CPMAxesTot, 90,-90)
             end
         % if Day vs Night is selected    
         elseif DayNight == 1
             
             if TypeCT == 1 % CT type
                app.DISPLAYPanelCPM.Visible = 'off';
                app.DISPLAYPanelMetrics.Visible = 'on';
                cla(app.CPMAxesMetrics)
                app.CPMAxesMetrics.YLabel.String = 'Percentage';
                app.CPMAxesMetrics.XLabel.String = 'Type of Click Train';
                app.CPMAxesMetrics.XTickMode = 'auto';
                clear x y
                x = {'NBHF', 'LQ-NBHF', 'Non-NBHF', 'Sonar'};
                x = categorical(x);
                y = [(app.NBHFd/app.NBHF)*100 (app.NBHFn/app.NBHF)*100; (app.LQNBHFd/app.LQNBHF)*100 ...
                    (app.LQNBHFn/app.LQNBHF)*100; (app.NonNBHFd/app.NonNBHF)*100 (app.NonNBHFn/app.NonNBHF)*100;...
                    (app.NoiseD/app.Sonar)*100 (app.NoiseN/app.Sonar)*100];
                bar(app.CPMAxesMetrics, x, y)
                hold(app.CPMAxesMetrics, 'off')
                app.CPMAxesMetrics.XLabel.String = 'Activity at Day (blue) vs Night (Orange)';

             elseif BehavCT == 1
                app.DISPLAYPanelCPM.Visible = 'off';
                app.DISPLAYPanelMetrics.Visible = 'on';
                cla(app.CPMAxesMetrics)
                app.CPMAxesMetrics.YLabel.String = 'Percentage';
                app.CPMAxesMetrics.XLabel.String = 'Behaviour';
                app.CPMAxesMetrics.XTickMode = 'auto';
                clear x y
                x = {'Orientation', 'Foraging', 'Socialising', 'Unknown'}; 
                x = categorical(x);
                y = [(app.TravelD/app.Travel)*100 (app.TravelN/app.Travel)*100;...
                    (app.ForageD/app.Forage)*100 (app.ForageN/app.Forage)*100;...
                    (app.CommD/app.Comm)*100 (app.CommN/app.Cmm)*100;...
                    (app.UnknownD/app.Unknown)*100 (app.UnknownN/app.Unknown)*100];
                bar(app.CPMAxesMetrics, x, y)
                hold(app.CPMAxesMetrics, 'off')
                app.CPMAxesMetrics.XLabel.String = 'Activity at Day (blue) vs Night (Orange)';
             elseif CPM == 1
                app.DISPLAYPanelCPM.Visible = 'on';
                app.DISPLAYPanelMetrics.Visible = 'off';
                cla(app.CPMAxesMetrics, 'reset') 
                %% Positive Porpoise Minutes
                % Plotting
                 for j = 1:app.numberOfFoldersMetrics-1
                     for i = 1:1440
                         if app.PosPorMin.Positive(i,j)
                           plot(app.CPMAxesTimes, app.Minutes(i,1), j, '.', 'color', 'black')
                           hold(app.CPMAxesTimes, 'on')
                         else
                             % nothing
                         end
                     end
                 end
                 app.CPMAxesTimes.YTick = [1:app.numberOfFoldersMetrics-1];
                 for j = 1:app.numberOfFoldersMetrics-1
                     Folder = app.listOfFolderNamesMetrics{j+1};
                     DateName = Folder(end-7:end);
                     FDay = DateName(7:8);
                     Month = DateName(5:6);
                     FDate = strcat(FDay,'/',Month);
                     FDate = {FDate};
                     app.CPMAxesTimes.YTickLabel(j) = FDate;
                 end
                 xlabel(app.CPMAxesTimes,'Min of the day')
                 hold(app.CPMAxesTimes,'off')
                 datetick(app.CPMAxesTimes,'x', 'HH:MM')
                 grid(app.CPMAxesTimes,'minor')
                 
                 for j =1:app.numberOfFoldersMetrics-1
                   stem(app.CPMAxesTot, j , sum(app.PosPorMin.Positive(:,j)) ,'.', 'black')
                   hold(app.CPMAxesTot, 'on')
                 end
                 ylabel(app.CPMAxesTot,'Positive minutes')
                 app.CPMAxesTot.XTick = [];
                 view(app.CPMAxesTot, 90,-90)
             end
             
         end
         
        end

        % Value changed function: CTNumD
        function ChangeCTNum(app, event)
            CTNum = app.CTNumD.Value;
            UpdateCT(app, CTNum)
        end

        % Menu selected function: SettingsDetectorMenu
        function FigSetDet(app, event)
                   % CREATE MENU FIGURE
                MenuPorCCF = uifigure('Position', [200 200 260 400]);
                % left bottom width height
                MenuPorCCF.Name = 'Select folder';
                MenuPorCCF.MenuBar = 'none';    % Hide standard menu bar menus.
                MenuPorCCF.Resize='off';
                MenuPorCCF.Visible = 'on';
                % Create panel within figure
                DetSetPan = uipanel('Parent', MenuPorCCF,'Units','pixels', 'Position',[10 10 240 380]);
                SelectFolderText = uilabel(DetSetPan,'Position', [10 350 80 20]);
                SelectFolderText.HorizontalAlignment = 'center';
                SelectFolderText.Text = 'Select Folder';
                
                CheckAllFolders = uicheckbox('Parent', DetSetPan,'Position',[10 305 180 20]);
                CheckAllFolders.Text = 'Include subfolders';
                % Browse button
                FolderPathDet = uieditfield('Parent', DetSetPan,'Position', [10 330 220 20],...
                    'value', 'C:\');
                BrowseDet = uibutton(DetSetPan, 'push', 'Position',[150 300 80 20], "Text", 'Browse', ...
                    'ButtonPushedFcn', @(BrowseDet,event) PushBrowseButton(BrowseDet));
                function PushBrowseButton(BrowseDet,event)
                        % Selects the folder or folder/subfolder where pgdf files are
                   folder = fullfile(matlabroot, 'C:\Users\');
                   app.SelectedFolder = uigetdir(folder);
               if app.SelectedFolder == 0
               	           return;
               end
                   FolderPathDet.Value = app.SelectedFolder;
                end


                % PARAMETERS PANEL
                SelectFolderText = uilabel(DetSetPan,'Position', [10 350 80 20]);
                SelectFolderText.HorizontalAlignment = 'center';
                SelectFolderText.Text = 'Select Folder';
                ParPan = uipanel('Parent', DetSetPan,'Units','pixels', 'Position',[10 150 220 115]);
                % Hydrophones section
                uilabel('Parent', ParPan, "Text",'Channel', "Position", [10 85 80 20]);
                EditHydN = uieditfield('Parent', ParPan, "Value", '1', 'Position', [120 85 50 20]);
                EditHydN.HorizontalAlignment = 'right';
                % Sensitivity
                uilabel('Parent', ParPan,'Text', 'Hyd Sensitivity', 'Position',[10 60 80 20]);
                SensEdit = uieditfield('Parent', ParPan,'Value', '-182', 'Position',[120 60 50 20]);
                SensEdit.HorizontalAlignment = 'right';
                uilabel('Parent', ParPan,'Text', 'dB', 'Position',[180 60 50 20]);
                % Gain
                uilabel('Parent', ParPan,'Text', 'Gain', 'Position',[10 35 80 20]);
                GainEdit = uieditfield('Parent', ParPan,'Value', '0', 'Position',[120 35 50 20]);
                GainEdit.HorizontalAlignment = 'right';
                uilabel('Parent', ParPan,'Text', 'dB', 'Position',[180 35 50 20]);
                % DAQ Peak to Peak (Clipping level)
                uilabel('Parent', ParPan,'Text', 'Peak to Peak', 'Position',[10 10 80 20]);
                DAQppEdit = uieditfield('Parent', ParPan,'Value', '2', 'Position',[120 10 50 20]);
                DAQppEdit.HorizontalAlignment = 'right';
                uilabel('Parent', ParPan,'Text', 'Volts', 'Position',[180 10 50 20]);
                
                % Classifier panel
                ProbPan = uipanel('Parent', DetSetPan,'Units','pixels', 'Position',[10 60 220 80]);
                % HQ
                uilabel('Parent', ProbPan,'Text', 'Prob threshold', 'Position',[10 45 80 20]);
                HQThres = uieditfield('Parent', ProbPan,'Value', '0.999999', 'Position',[120 45 65 20]);
                HQThres.HorizontalAlignment = 'right';
                uilabel('Parent', ProbPan,'Text', 'HQ', 'Position',[190 45 50 20]);
                % LQ
                uilabel('Parent', ProbPan,'Text', 'Prob threshold', 'Position',[10 15 80 20]);
                LQThres = uieditfield('Parent', ProbPan,'Value', '0.6', 'Position',[120 15 65 20]);
                LQThres.HorizontalAlignment = 'right';
                uilabel('Parent', ProbPan,'Text', 'LQ', 'Position',[190 15 50 20]);
                
                % Buttons
                ButPan = uipanel('Parent', DetSetPan,'Units','pixels', 'Position',[10 10 220 45]);
                        
                % Apply Values
                ApplyButton = uibutton(ButPan,'push', 'Position',[10 10 90 25], 'Text', 'Apply Values', ...
                   'ButtonPushedFcn', @(ApplyButton,event) PushApplyButton(ApplyButton));
                        
                %% PORCC - STEPS INCLUDE
                % 1) Getting the necessary values for PorCC
                % 2) Accessing individual clicks in .wav files
                % 3) Estimating probabilities and all other parameters
                % 4) Saving if classified as LQ or HQ
                % 5) Remove clicks with the same date keeping the best one
                
            function PushApplyButton(ApplyButton, event)
                    %% Apply the values in order to run PorCC with these
                app.SubFoldersChecked = CheckAllFolders.Value;
                app.HydSens = str2double(SensEdit.Value);
                app.Gain = str2double(GainEdit.Value);
                app.ThresHQ = str2double(HQThres.Value);
                app.ThresLQ = str2double(LQThres.Value);
                app.HydN = str2double(EditHydN.Value);
                app.DAQpp = str2double(DAQppEdit.Value);
                PushDetMenu.Enable = 'on';
            end
                 
                %% RUN Detector and PorCC
                PushDetMenu = uibutton(ButPan, 'push', 'Position', [120 10 90 25], 'Text', 'Run Detector/PorCC', ...
                    'ButtonPushedFcn', @(PushPorCCMenu,event) PushDetButton(PushPorCCMenu));
                PushDetMenu.Enable = 'off';
                
                function PushDetButton(PushDetMenu, event)
                       MenuPorCCF.Visible = 'off';
                     %% Create a table with 0s to make it faster and more effective
                     app.ClickParameters = table();
                     app.ClickParameters.ID(1:1000000, 1) = 0;
                     app.ClickParameters.Date(1:1000000,1) = 0;
                     app.ClickParameters.startSample(1:1000000,1) = 0;
                     app.ClickParameters.Duration(1:1000000,1) = 0; % % DURATION
                     app.ClickParameters.Amp(1:1000000,1) = 0; % MAXIMUM AMPLITUDE
                     app.ClickParameters.PF(1:1000000,1) = 0; % PEAK FREQUENCY
                     app.ClickParameters.CF(1:1000000,1) = 0; % CENTROID FREQUENCY
                     app.ClickParameters.BW(1:1000000,1) = 0; % BANDWIDTH -3dB
                     app.ClickParameters.RMSBW(1:1000000,1) = 0; % RMS-BW
                     app.ClickParameters.Ratio(1:1000000,1) = 0;
                    if app.HydN > 1 % && exist(dataPoint.delays)
                          app.ClickParameters.Bearing(1:1000000,1) = 0;
                    end
                     app.ClickParameters.ICI(1:1000000,1) = 0;
                     app.ClickParameters.XC(1:1000000,1) = 0; % Max XC
                     app.ClickParameters.Q(1:1000000,1) = 0; % Q VALUE
                     app.ClickParameters.CT(1:1000000,1) = 0;
                     app.ClickParameters.InFile{1,1} = 'a';
                     app.ClickParameters.ClickN(1:1000000,1) = 0;
                     app.ClickParameters.Class(1:1000000,1) = 0;
                
                     % Get .wav files list in the folder
                     WavFilesNames = strcat(app.SelectedFolder,'\*.wav');
                     WavFilesInFolder = dir(WavFilesNames);
                     NumWavFiles = length(WavFilesInFolder);
                     RowN = 0;
                     % Get list of all subfolders.
                     fprintf('Processing folder %s\n', app.SelectedFolder);
                       % Running the classifier
                     RowN = 0;
                     click_id = 0;
                                for Fil = 1:NumWavFiles
                                    PathName = WavFilesInFolder(Fil).folder;
                                    FileName = WavFilesInFolder(Fil).name;
                                    File1 = fullfile(PathName,FileName);
                                    Info = audioinfo(File1);
                                    NCh = Info.NumChannels;
                                    nBits = Info.BitsPerSample;
                                    totSamp = Info.TotalSamples;
                                    Info2 = dir(File1);
                                    % created date
                                        %     dosStr = char(strcat('dir /T:C', {' '}, filename));
                                        %     [~,str] = dos(dosStr);
                                        %     c = textscan(str,'%s');
                                        %
                                        %     fileInfo.CreationDate = c{1}{15};
                                        %     fileInfo.CreationTime = datestr(...
                                        %     datenum(strcat(c{1}{16},{' '}, c{1}{17}), 'HH:MM AM'), 'HH:MM');


                                    Date = Info2.date;
                                    app.Fs = Info.SampleRate;
                                    DateNum = datenum(Date);
                                    Millis = (DateNum - 719529)*86400000.0;
                                    SampleEvery = 1000000/app.Fs;
                                    SamplesUntilNow = 0;
                                    %fftw('planner','estimate');
                                    clear f ysig
                                    %parameters
                                    ButtOrd=6;
                                    %Thres=2;
                                    %Filter
                                    clear a b wn
                                    CutOffL = 20000;
                                    CutOffR = 200000;%(app.Fs/2)*0.95;
                                    wn = [CutOffL CutOffR]/(app.Fs/2);   %design bandpass
                                    [b,a] = butter(ButtOrd, wn);
                                    Start = 1;
                                    if totSamp > app.Fs*60
                                          Cycles = floor(totSamp/(app.Fs*10));
                                          ChunkL = floor(totSamp/Cycles);
                                    else
                                          ChunkL = totSamp;
                                          Cycles = 1;
                                    end
                                    for fn = 1:Cycles
                                   % When the end
                                    if fn == Cycles
                                         End = totSamp;
                                    else
                                         End = ChunkL*fn;
                                    end
                                    
                                    if End > totSamp
                                        End = totSamp;
                                    else
                                    end
                                    
                                    OriginalSignal = audioread(File1, [Start, End]);
                                      
                                    if NCh > 1
                                        [MaxVals ixMax] = max(OriginalSignal);
                                        OriginalSignal = OriginalSignal(:,NCh);
                                    else
                                        %nothing
                                    end
                                    MeanOrSig = mean(OriginalSignal);
                                    OriginalSignal = OriginalSignal - MeanOrSig;
                                    FiltSig = filter(b, a, OriginalSignal); % filter the signal
                                    OriginalSignal = []; 
                                    LocM = [];
                                    Thres(1:10000,1) = 0;
                                for i = 1:10000
                                    if(FiltSig(i) < 0.1)
                                        Thres(i) = max(abs(FiltSig(1:i)));
                                    end
                                end
                                    Thres = 2*max(Thres);
                                    % Find impulsive signals above threshold
                                    post = 200;
                                    peta = [];
                                    [peta]=findpeaksholdoff(FiltSig,Thres,post);
                                        for i = 1:size(peta,1)
                                              s = peta(i,1); % sample where the click is
                                              clear click
                                              if s < 121
                                                    click = FiltSig(1:s+120);
                                              elseif size(FiltSig,1) < s+120
                                                    click = FiltSig(s-120:size(FiltSig,1));
                                              else
                                                    click = FiltSig(s-120:s+120);
                                              end
                                                FFT = 512;
                                                click_id = click_id + 1;
                                                [PSD, f1, CF_Val, PF_Val] = CentroidAndPeakFrq1(app, click, FFT, app.Fs);
                                                ClickCalves(click_id).id = click_id;
                                                ClickCalves(click_id).wave = click;

                                                % PorCC Classification
                                                % PF = peak frequency , CF = centroid frequency
                                                % PSD = power spectrum, f = frequency
                                                [RMSBW_Val] = (sqrt(sum((f1-CF_Val).^2.*PSD.^2 ) / sum(PSD.^2)))/1000;
                                                durationV = clickDuration(app, click);
                                                Q_Val = (CF_Val/RMSBW_Val)/1000;
                                                Ratio_Val = PF_Val/CF_Val;
                                                [XCoeff, ~] = xcorr(click, app.click_model); XC_Val = max(XCoeff);
                                                BW_Val = powerbw(click, app.Fs)/1000;
                                              if PF_Val > 100000 && PF_Val < 160000 && CF_Val > 100000 && CF_Val < 160000 % Møhl & Andersen, 1973
                                                     % RMS-BW = RMS bandwidth, ratio = PF / CF, BW = -3dB bandwidth
                                                     % duration = estimated using 80% energy of the clip
                                                     % Q = CF/RMSBW, XC = peak cross-correlation coeficient with a model click
                                                 if Q_Val > 4 && durationV < 450
                                                       % estimate probabilities for HQ
                                                       ProbHQ = glmval(app.logitCoefHQ,[Q_Val durationV],'logit');
                                                       % assign each clip to a category
                                                       if ProbHQ >= app.ThresHQ
                                                             Porps_Val = 1;  % HQ click
                                                       else
                                                            ProbLQ = glmval(app.logitCoefLQ,[Q_Val durationV Ratio_Val XC_Val CF_Val BW_Val],'logit'); % Log Model 2
                                                             if ProbLQ > app.ThresLQ
                                                                    Porps_Val = 2; % LQ click
                                                             else
                                                                    Porps_Val = 3; % HF noise
                                                             end
                                                       end
                                                  else
                                                      Porps_Val = 3;
                                                  end
                                              else
                                                  Porps_Val = 3;
                                              end % if Porps_Val...
                                              if Porps_Val == 1 || Porps_Val == 2 %|| Porps_Val == 3
                                                 RowN = RowN + 1; % our ID
                                                 %%%%%% save HQ & LQ clicks  %%%%%%
                                                 app.ClickParameters.ID(RowN,1) = RowN;
                                                % calculate the date
                                                NumOfSamples = SamplesUntilNow + s;
                                                MilSec = NumOfSamples*(SampleEvery/1000);
                                                NewMil = Millis + MilSec;
                                                NewDate = (double(NewMil)/86400000.0)+719529;
                                                 app.ClickParameters.Date(RowN,1) = NewDate;
                                                 app.ClickParameters.startSample(RowN,1) = SamplesUntilNow + s;
                                                 app.ClickParameters.Duration(RowN,1) = durationV; % % DURATION
                                               Amp_Val = clickAmplitude(app, click, app.HydSens, app.Gain, app.DAQpp);
                                                 app.ClickParameters.Amp(RowN,1) = Amp_Val; % MAXIMUM AMPLITUDE
                                                 app.ClickParameters.PF(RowN,1) = PF_Val; % PEAK FREQUENCY
                                                 app.ClickParameters.CF(RowN,1) = CF_Val; % CENTROID FREQUENCY
                                                 app.ClickParameters.BW(RowN,1) = BW_Val; % BANDWIDTH -3dB
                                                 app.ClickParameters.RMSBW(RowN,1) = RMSBW_Val; % RMS-BW
                                                 app.ClickParameters.Ratio(RowN,1) = Ratio_Val;
                                                if app.HydN > 1 %&& exist(dataPoint.delays)
                                                      app.ClickParameters.Bearing(RowN,1) = dataPoint.angles*57.2958;
                                                end
                                                if RowN == 1
                                                      ici = 0;
                                                else
                                                      ici = (app.ClickParameters.startSample(RowN,1) - app.ClickParameters.startSample(RowN-1,1))/(app.Fs/1000); % in microseconds
                                                     if ici < 0
                                                            ici = (app.ClickParameters.Date(RowN,1) - app.ClickParameters.Date(RowN-1,1))*120*24; % in milliseconds
                                                     end
                                                end
                                                app.ClickParameters.ICI(RowN,1) = ici;
                                                app.ClickParameters.XC(RowN,1) = XC_Val; % Max XC
                                                app.ClickParameters.Q(RowN,1) = Q_Val; % Q VALUE
                                                % PORPOISE OR NOT?
                                                app.ClickParameters.CT(RowN,1) = 0;
                                                app.ClickParameters.InFile{RowN,1} = FileName;
                                                app.ClickParameters.ClickN(RowN,1) = click_id;
                                                app.ClickParameters.Class(RowN,1) = Porps_Val;
                                             end % if Porps_Val ~= 3
                                       end % peta
                                       Start = End;
                                        SamplesUntilNow = size(FiltSig,1) + SamplesUntilNow;
                                    end  % cycles
                                        
                               end  % for Wav files
                        
                        app.ClickParameters(RowN + 1:end,:) = [];
                        
                        %% REMOVE CLICKS WITH THE SAME DATE
                        ZeroRep = find(diff(app.ClickParameters.Date)==0);
                        app.ClickParameters(ZeroRep, :) = [];
                        
%                     if size(ZeroRep,1) > 0
%                         d = 1;
%                    while size(d,1) > 0
%                         a = find(diff(app.ClickParameters.Date)==0);
%                         b = find(diff(app.ClickParameters.Amp) < 0);
%                         c=arrayfun(@(x) find(b==x,1),a,'UniformOutput',false);
%                         c = cell2mat(c);
%                         d = b(c);
%                         d = d + 1;
%                         % remove the same date but amp < first
%                         app.ClickParameters(d,:) = [];
%                         clear a b c e
%                    end
%                         d = 1;
%                    while size(d,1) > 0
%                         a = find(diff(app.ClickParameters.Date)==0);
%                         b = find(diff(app.ClickParameters.Amp) > 0);
%                         c=arrayfun(@(x) find(b==x,1),a,'UniformOutput',false);
%                         c = cell2mat(c);
%                         d = b(c);
%                         % remove the same date but amp < first
%                         app.ClickParameters(d,:) = [];
%                         clear a b c e
% %                    end
%                          ICI = diff(app.ClickParameters.startSample)/(app.Fs/1000);
%                          app.ClickParameters.ICI(1,1) = 0;
%                          app.ClickParameters.ICI(2:end,1) = ICI;
%                     else
                         ICI = diff(app.ClickParameters.startSample)/(app.Fs/1000);
                         app.ClickParameters.ICI(1,1) = 0;
                         app.ClickParameters.ICI(2:end,1) = ICI;
                         
%                    end
                        % SAVE CLICK PARAMETERS
                        DateTime = datestr(app.ClickParameters.Date(1,1));
                        yyyy = DateTime(8:11); mmm = DateTime(4:6); dd = DateTime(1:2);
                        hh = DateTime(13:14); mm = DateTime(16:17); ss = DateTime(19:20);
                        DateTimeEnd = datestr(app.ClickParameters.Date(end,1));
                        hhEnd = DateTimeEnd(13:14); mmEnd = DateTimeEnd(16:17); ssEnd = DateTimeEnd(19:20);
                        FileDateTimePar = strcat(app.SelectedFolder,'\ClickParameters',yyyy,mmm,dd,'_',hh,mm,ss,'_',hhEnd,mmEnd,ssEnd,'.mat');
                        ClickParameters = app.ClickParameters;
                        save(FileDateTimePar, 'ClickParameters');
                        FileClicks = strcat(app.SelectedFolder,'\Clicks.mat');
                        save(FileClicks, 'ClickCalves');
                       
                 end
                        
                 function [peta]=findpeaksholdoff(arr,thr,post)
                    % (c) Kristian Beedholm
                    % This function detects treshold crossings in array arr and subsequently
                    % holds of detecting other crossings for a number of indexes (post)
                    
                    % Returns array peta with two columsn
                    % first column : peak amplitude values
                    % second column : indices where peak values occur
                    
                    L=length(arr);
                    m=1;np=0;
                    p(round(L/post))=0;
                    a(round(L/post))=0;
                    while m<=L
                        if arr(m)<thr
                            m=m+1;
                        else
                            np=np+1;
                            try
                            [a(np), p(np)]=max(arr(m:m+post));
                            catch
                            [a(np), p(np)]=max(arr(m:end));
                            end
                            p(np)=p(np)+m-1;
                            m=m+post;
                        end
                    end
                    
                    a(np+1:end)=[];
                    p(np+1:end)=[];
                    peta=[p;a]';
                 end
        end

        % Callback function
        function DisplayAll(app, event)
%             Alltick = app.ALLCheckBox.Value;
%             NBHFtick = app.NBHFCheckBox.Value;
%             if Alltick == 1
%               app.NBHFCheckBox.Value = 0;
%                % nothing
%             elseif NBHFtick == 1
%                app.ALLCheckBox.Value = 0;
%                app.CTInfo = app.CTInfo([app.CTInfo.Species] ~= 'Non-NBHF');
%             end
        end

        % Button pushed function: SelectButton
        function SelectCT(app, event)
            CTNum = app.CTNumD.Value;
            app.NumCT = find([app.CTInfo.CTNum] == CTNum);
            app.SelectedCTCheckBox.Value = 1;
            app.CTInfo(app.NumCT).Saved = 1;
            FullName = strcat(app.SelectedFolder, '\CTInfo.mat');
            CTInfo = app.CTInfo;
            save(FullName, 'CTInfo');
            CTInfo  = [];
        end

        % Selection changed function: ButtonGroupICICPS
        function CPSvsICI(app, event)
          %  selectedButton = app.ButtonGroupICICPS.SelectedObject;
            %app.NumCT = str2double(app.CTNumD.Value);
            UpdateCT(app, app.NumCT)
        end

        % Selection changed function: ButtonGroupFreqBear
        function FreqvsBearing(app, event)
            %selectedButton = app.ButtonGroupFreqBear.SelectedObject;
            UpdateCT(app, app.NumCT)
        end

        % Button pushed function: UnselectButton
        function UnselectCT(app, event)
            CTNum = app.CTNumD.Value;
            app.NumCT = find([app.CTInfo.CTNum] == CTNum);
            app.SelectedCTCheckBox.Value = 0;
            app.CTInfo(app.NumCT).Saved = 0;
            FullName = strcat(app.SelectedFolder, '\CTInfo.mat');
            CTInfo = app.CTInfo;
            save(FullName, 'CTInfo');
            CTInfo  = [];
        end

        % Selection changed function: DisplayButtonGroup
        function AllvsNBHFDisplay(app, event)
           % selectedButton = app.DisplayButtonGroup.SelectedObject;
            if app.AllButton.Value == 1
              app.CTInfo = app.CTInfo2;
              app.NumCT = app.CTInfo(1).CTNum;
              UpdateCT(app, app.NumCT)         
            else
              % app.CTInfo2 = app.CTInfo;
              RowsNBHF = strcmp({app.CTInfo.Species}, 'NBHF');
              app.CTInfo = app.CTInfo(RowsNBHF);
              app.NumCT = app.CTInfo(1).CTNum;
              UpdateCT(app, app.NumCT)
            end
        end

        % Menu selected function: AboutDPorCCAMenu
        function AboutDPorCCAMenuSelected(app, event)
            % About D-PorCCA
            AboutFig = uifigure;
            AboutFig.Position = [100 100 300 250];
            AboutFig.Name = 'About D-PorCCA';
            AboutPanel = uipanel('Parent', AboutFig,'Units','pixels', 'Position',[10 10 280 230]);
            AboutText = uitextarea(AboutPanel);
            AboutText.Enable = 'on';
            AboutText.Editable = 'off';
            %AboutText.BackgroundColor = 'white';
            AboutText.Position = [10 10 260 210];
            AboutText.Value = {'D-PorCCA was developed By Mel Cosentino.'
                'D-PorCCA stands for: "D" = detector. "Por" = Porpoise. "CC" = Click Classifier. "A" = application. Porpoises are known as "sea pigs", PorCC then is pronounced as "pork".'
                'D-PorCCA is a standalone application with a user-friendly interface. The application was developed using App Designer, a development environment that allows to create a standalone desktop or web app that can be used with free MATLAB Compiler. App Designer was introduced with the 2016a version of MATLAB (Mathworks MA).'};
        end

        % Button pushed function: UpdateButton
        function Validate(app, event)
            Species = app.CTTypeDropDown.Value;
            Behaviour = app.BehaviourDropDown.Value;
            CTNum = app.CTNumD.Value;
            app.NumCT = find([app.CTInfo.CTNum] == CTNum);
            
            TypeChg = strcmpi('Select one', Species);
            BehavChg = strcmpi('Select one', Behaviour);

            if TypeChg == 1 && BehavChg == 1
                % do nothing
            elseif TypeChg == 0 && BehavChg == 1
               app.CTInfo(app.NumCT).Species = Species; 
               app.CTTypeLabel.Text = Species;
            elseif TypeChg == 1 && BehavChg == 0
               app.CTInfo(app.NumCT).Behav = Behaviour; 
               app.BehavLabel.Text = Behaviour;
            elseif TypeChg == 0 && BehavChg == 0
               app.CTInfo(app.NumCT).Species = Species;
               app.CTInfo(app.NumCT).Behav = Behaviour;
               app.BehavLabel.Text = Behaviour;
               app.CTTypeLabel.Text = Species;
            end 

%             FullName = strcat(app.SelectedFolder, '\CTInfo.mat');
%             CTInfo = app.CTInfo;
%             save(FullName, 'CTInfo');
%             CTInfo  = [];
        end

        % Button pushed function: SpectrogramButton
        function SpectrogramFig(app, event)
            InFile = app.CTTemp.InFile(1,1);
            InFile = InFile{1,1};
            InFile = InFile(end-4:end);
            PGDF = strcmp(InFile, '.pgdf');
                if PGDF == 1 % Saved in PAMGuard files
                    
                    % Get all dates of wav files in folder
                    WavList = uigetdir(fullfile(app.SelectedFolder, '*.wav'));
                    [~,index] = sortrows([WavList.datenum].'); WavList = WavList(index); clear index
                    CumSamples = 0;
                    for i = 1:length(WavList)
                        FileSam = WavList(i).name;
                        FileSamName =strcat(app.SelectedFolder, '\', FileSam);
                        InfoWavFile = audioinfo(FileSamName);
                        TotSamples = InfoWavFile.TotalSamples;
                        CumSamples = CumSamples + TotSamples;
                        WavList(i).CumSamples = CumSamples; 
                    end 
                    StartSample = app.CTTemp.startSample(1,1); % Where my CT begins
                    % Find file recorded after our sample 
                    CumSamples = [WavList.CumSamples]; % Cumulative number of samples (as PAMGuard seem to do)
                    IxWavFile = find(CumSamples > StartSample);
                    % Name of wav file to update
                    NameFile = WavList(IxWavFile(1)).name;
                    AudioFile = strcat(app.SelectedFolder, '\', NameFile);
                    if IxWavFile(1) > 1
                        StartSampleInWavFile = app.CTTemp.startSample(1,1) - WavList(IxWavFile(1)-1).CumSamples -20000;
                        EndSampleInWavFile = app.CTTemp.startSample(end,1) - WavList(IxWavFile(1)-1).CumSamples + 20000;
                    else
                        StartSampleInWavFile = app.CTTemp.startSample(1,1) - 20000;
                        EndSampleInWavFile = app.CTTemp.startSample(end,1) + 20000;
                    end
                else 
                    
                    % See later what I'll do
                end

            % Make the spectrogram figure
            FigSpect = uifigure;
            FigSpect.Position = [200 200 700 350];
            FigSpect.Name = 'Spectrogram';
            FigSpect.MenuBar = 'none';
            FigSpect.Resize = 'off';
            FigSpect.Visible = 'on';
            FigSpecPan = uipanel(FigSpect, 'Position', [10 10 680 330]);
            SpecAxes = uiaxes(FigSpecPan, 'Position', [190 30 480 250]);
            % Spectrogram parameters
            FsEdit = uieditfield(FigSpecPan, 'Position', [110 260 50 20]);
            FsEdit.Value = '500'; % In kHz
            FsEdit.HorizontalAlignment = 'right';
            uilabel(FigSpecPan, 'Position', [10 260 80 20], 'Text', 'S. freq (kHz)');
            WindowSize = uieditfield(FigSpecPan, 'Position', [110 230 50 20]);
            WindowSize.Value = '30';
            WindowSize.HorizontalAlignment = 'right';
            uilabel(FigSpecPan, 'Position', [10 230 80 20], 'Text', 'Window size');
            Overlap = uieditfield(FigSpecPan, 'Position', [110 200 50 20]);
            Overlap.Value = '80'; % overlap - need to transform it when calling it
            Overlap.HorizontalAlignment = 'right';
            uilabel(FigSpecPan, 'Position', [10 200 80 20], 'Text', 'Overlap (%)');
            FFT = uieditfield(FigSpecPan, 'Position', [110 170 50 20]);
            FFT.Value = '512'; 
            FFT.HorizontalAlignment = 'right';
            uilabel(FigSpecPan, 'Position', [10 170 80 20], 'Text', 'FFT size');
            % Channels
            Channels = uieditfield(FigSpecPan, 'Position', [120 100 40 20]);
            Channels.Value = '4'; 
            Channels.HorizontalAlignment = 'right';
            uilabel(FigSpecPan, 'Position', [10 100 100 20], 'Text', 'No of channels');
            ChannelSpec = uieditfield(FigSpecPan, 'Position', [120 70 40 20]);
            ChannelSpec.Value = '3'; 
            ChannelSpec.HorizontalAlignment = 'right';
            uilabel(FigSpecPan, 'Position', [10 70 100 20], 'Text', 'Channel for Spect');

            UpdateSpect = uibutton(FigSpecPan, 'Position', [510 285 155 30], 'Text', 'Update Spectrogram',...
                'ButtonPushedFcn', @(UpdateSpect,event) PushSpecButton(UpdateSpect));

                function PushSpecButton(UpdateSpect,event)
                    ChannelNum = str2double(ChannelSpec.Value); 
                    WindowSizeVal = str2double(WindowSize.Value);    
                    OverlapVal = (str2double(Overlap.Value)*WindowSizeVal)/100;
                    app.Fs = str2double(FsEdit.Value)*1000;   
                    FFTVal = str2double(FFT.Value);       
                    % Load wav file
                    SamplesForSpectrogram = audioread(AudioFile, [StartSampleInWavFile, EndSampleInWavFile], 'native');
                    SamplesForSpectrogram = double(SamplesForSpectrogram(:,ChannelNum));
                    % Plot spectrogram
                    [s,f,t]=spectrogram(SamplesForSpectrogram,WindowSizeVal,OverlapVal,FFTVal,app.Fs,'yaxis');
                    surf(SpecAxes, t, f, 20*log10(abs(s)));
                    % imagesc(SpecAxes, abs(s)) 
                end


        end

        % Button pushed function: SeeAddButton
        function NotesCT(app, event)
            CTNum = app.CTNumD.Value;
            app.NumCT = find([app.CTInfo.CTNum] == CTNum);
            Notes = app.CTInfo(app.NumCT).Notes;
            
            NotesFig = uifigure('Position', [200 200 260 330]);
            NotesFig.Name = 'Notes';
            NotesFig.MenuBar = 'none';    % Hide standard menu bar menus.
            NotesFig.Resize='off';
            NotesFig.Visible = 'on';
            % Create panel within figure
            NotesPan = uipanel('Parent', NotesFig,'Units','pixels', 'Position',[10 10 240 310]);
            NotesText = uitextarea(NotesPan);
            NotesText.Enable = 'on';
            NotesText.Editable = 'on';
            NotesText.BackgroundColor = 'white';
            NotesText.Position = [10 45 220 210];
            SaveNotes = uibutton(NotesPan, 'push', 'Position', [10 10 90 25], 'Text', 'OK', ...
                  'ButtonPushedFcn', @(SaveNotes,event) PushSaveNButton(SaveNotes));
            CancelNotes = uibutton(NotesPan, 'push', 'Position', [140 10 90 25], 'Text', 'Cancel', ...
                  'ButtonPushedFcn', @(CancelNotes,event) PushCanNButton(CancelNotes));  
              
            if size(Notes,1) > 0
              NotesText.Value = Notes;  
            else
              NotesText.Value = ' ';
            end
                  
            function PushSaveNButton(SaveNotes, event)
                NotesUpdate = NotesText.Value;
                app.CTInfo(app.NumCT).Notes = NotesUpdate;
                NotesFig.Visible = 'off';
%                 FullName = strcat(app.SelectedFolder, '\CTInfo.mat');
%                 CTInfo = app.CTInfo;
%                 save(FullName, 'CTInfo');
%                 CTInfo  = [];
            end    
        
            function PushCanNButton(CancelNotes, event)
                NotesFig.Visible = 'off';
            end
            
        end

        % Menu selected function: SearchMenu
        function SearchMenuSelected(app, event)
             %% Create table with the information
             SearchInfo = table();
             SearchInfo.Topic{1,1} = 'Amplitude (dB re: 1µPa)';
             SearchInfo.Topic(2:20,1) = {'Centroid frequency (CF, kHz)', 'Click train', 'Clicks per second (CPS)', 'Cross correlation', ...
                 'DAQ', 'Date and time', 'Duration', 'Fs - Sampling Frequency', 'Gain', 'HQ - High quality', 'ID', 'Inter-click interval (ICI)', ...
                 'LQ - Low quality', 'NBHF', 'Peak frequency (PF, kHz)', 'Q (RMS)', 'RMS Bandwidth (RMSBW, kHz) ', 'Setup', 'Start sample'};
             SearchInfo.Information{1,1} = 'Peak-to-peak amplitude of the signal (dB re: 1µPa) is estimated from the sensitivity of the hydrophone and gain, information provided by the user';
             SearchInfo.Information(2:20,1) = {'The centroid frequency (CF, kHz) the point dividing the spectrum in halves of equal energy', ...
                 ['A click train is a series of clicks separated from each other by less than a given time period.' ...
                 ' The default (and recommended values) are: minimum of 16 clicks, maximum of 1500 clicks, and minimum' ...
                 'separation time of or 1 second.'],...
                 'The number of clicks per second is estimated as 1000 divided the inter-click interval (ICI).', ...
                 'Maximum cross-correlation coefficient between the sample and a typical harbour porpoise click.', ...
                 'DAQ - data acquisition card', ...
                 'Date and time in text format (e.g., 25 Jul 2017 15:28:34).', ...
                 'Duration (arbitrary unit) of the signal is estimated as the 80% energy of the clip that contains the signal (Madsen and Wahlberg, 2007).', ...
                 'Sampling frequency (Fs) is the number of samples collected in 1 second and is embedded in the metadata of the .wav files.', ...
                 'Gain (dB)', 'HQ - Harbour porpoise click of high quality.', 'ID or identification numberis a unique identification number was assigned to each click in chronological order.', ...
                 'Inter-click interval (ms) - Time difference between the click and the previous one. The inter-click interval for the first click classified (click ID = 1) is 0.',...
                 'LQ - Harbour porpoise click of low quality. LQ can also refer to low quality harbour porpoise click trains, which are labelled LQ-NBHF.', ...
                 'NBHF stands for narrow-band high-frequency. It can be in reference to individual clicks or click train types.', ...
                 'Peak frequency (PF, kHz) - Frequency with the highest amplitude in the frequency domain (FFT = 512 points) (Madsen and Wahlberg, 2007).', ...
                 'Q(RMS) - Relative width of the signal, and is estimated as the ratio between BWRMS and centroid frequency.', ...
                 'RMS Bandwidth (BW, kHz) is the root mean square (RMS) bandwidth, defined as the spectral standard deviation around the centroid frequency in a linear scale (Madsen and Wahlberg, 2007).', ...
                 'There are no setup files to start using D-PorCCA. More information about how to use the menu can be found in other Help Files', ...
                 'Start sample - Sample number where the click begins per day (in PAMGuard) or within the .wav file for the detector developed in this project'};

             %% MENU
             FigHelp = uifigure;
             FigHelp.Position = [200 200 450 330];
             FigHelp.Name = 'Help Search';
             FigHelp.MenuBar = 'none';
             FigHelp.Resize = 'off';
             FigHelp.Visible = 'on';
             % Text area
             FigHelpPan = uipanel(FigHelp, 'Position', [10 10 430 250]);
             HelpText = uitextarea(FigHelpPan);
             HelpText.Enable = 'on';
             HelpText.Editable = 'off';
             HelpText.BackgroundColor = 'white';
             HelpText.Position = [170 10 240 230];
             % Create list
             HelpRes = uilistbox(FigHelpPan, 'ValueChangedFcn', @updateEditField);
             HelpRes.Position = [10 10 150 230];
             HelpRes.Items = SearchInfo.Topic;
             HelpRes.ItemsData = SearchInfo.Information;
             HelpRes.BackgroundColor = 'white';
 
             FigSearchPan = uipanel(FigHelp, 'Position', [10 270 430 50]);
             SearchButton = uibutton(FigSearchPan, 'Position', [310 10 100 30], 'Text', 'Search',...
                 'ButtonPushedFcn', @(SearchButton,event) PushSearchButton(SearchButton));
             SearchEdit = uieditfield(FigSearchPan, 'Position', [10 10 290 30]);
  
             function PushSearchButton(SearchButton, event)
                 HelpText.Value = {''};
                 TextSearch = {};
                 TextResponse = {};
                 WordToSearch = SearchEdit.Value;
                 RowN = 0;
                 for i = 1:size(SearchInfo,1)
                     Info = SearchInfo.Information(i,1);
                     k = strfind(Info, WordToSearch);
                     k = cell2mat(k);
                     if isempty(k) == 0
                        RowN = RowN + 1;
                        TextSearch(RowN,1) = SearchInfo.Topic(i,1);
                        TextResponse(RowN,1) = SearchInfo.Information(i,1);                    
                     end  
                 end
                 HelpRes.Items = TextSearch;  
                 HelpRes.ItemsData = TextResponse;
                 if size(TextSearch,1) == 1
                     HelpText.Value = HelpRes.ItemsData;
                 elseif size(TextSearch,1) == 0
                     HelpText.Value = 'Opps. No items were found with the word you searched! The search is case sensitive. Try other combinations';
                 end
             end
             % Create list box
 
         % ValueChangedFcn callback
         function updateEditField(src,event) 
             HelpText.Value = src.Value;
         end

        end

        % Button pushed function: SaveupdatesButton
        function SaveUpdates(app, event)
            FullName = strcat(app.SelectedFolder, '\CTInfo.mat');
            CTInfo = app.CTInfo;
            save(FullName, 'CTInfo');
            CTInfo  = [];
        end

        % Menu selected function: TablesMenu
        function MenuDownloadTable(app, event)
            DownloadFig = uifigure('Position', [150 150 260 360]);
            DownloadFig.Name = 'Download';
            DownloadFig.MenuBar = 'none';    % Hide standard menu bar menus.
            DownloadFig.Resize='off';
            DownloadFig.Visible = 'on';
            
            % create a panel inside the figure
            DownloadPan = uipanel('Parent', DownloadFig,'Units','pixels', 'Position',[10 10 240 340]);
            SelectFolderText = uilabel(DownloadPan,'Position', [10 312 240 20]);
            SelectFolderText.Text = 'Project folder';
            
            % edit - selected folder to be read
            FolderPathProject = uieditfield('Parent', DownloadPan,...
                'Position', [10 290 220 20], ...
                "Enable","on",...
                "Value",'C:\Users\');
            
            % browse button
            BrowseButton = uibutton(DownloadPan, 'push',...
                'ButtonPushedFcn', @(BrowseButton,event) PushBrowseButton(BrowseButton));
            BrowseButton.Position = [150 265 80 20];
            BrowseButton.Text = 'Browse'; 
            function PushBrowseButton(BrowseButton, event)
                com.mathworks.mwswing.MJFileChooserPerPlatform.setUseSwingDialog(1)
                topLevelFolderProj = [];
                folderProj = [];
                 % Where it opens
                folderProj = 'C:\Users\';
                % Selected folder that has subfolders
                topLevelFolderProj = uigetdir(folderProj);
                if topLevelFolderProj == 0
                return;
                end
                FolderPathProject.Value = topLevelFolderProj;
                % Get list of all subfolders.
                allSubFoldersProj = genpath(topLevelFolderProj);
                % Parse into a cell array.
                remain = allSubFoldersProj;
                app.listOfFolderNamesProj = {};
                while true
                [singleSubFolder, remain] = strtok(remain, ';');
                if isempty(singleSubFolder)
                    break;
                end
                app.listOfFolderNamesProj = [app.listOfFolderNamesProj singleSubFolder];
                end
                topLevelFolderProj = [];
                folderProj = [];
            end
            
            % create a panel inside the figure
            SelectFolderText = uilabel(DownloadPan,'Position', [10 257 240 20]);
            SelectFolderText.Text = 'Where to Save';
            % edit - selected folder to download
            FolderPathDownload = uieditfield('Parent', DownloadPan,...
                'Position', [10 235 220 20], ...
                "Enable","on",...
                "Value",'C:\Users\');
            
            % browse button
            BrowseButton2 = uibutton(DownloadPan, 'push',...
                'ButtonPushedFcn', @(BrowseButton,event) PushBrowseButton2(BrowseButton));
            BrowseButton2.Position = [150 210 80 20];
            BrowseButton2.Text = 'Browse'; 
            function PushBrowseButton2(BrowseButton2, event)
                com.mathworks.mwswing.MJFileChooserPerPlatform.setUseSwingDialog(1)
                 % Where it opens
                folderDL = 'C:\Users\';
                % Selected folder that has subfolders
                topLevelFolderDL = uigetdir(folderDL);
                if topLevelFolderDL == 0
                return;
                end
                FolderPathDownload.Value = topLevelFolderDL;
                topLevelFolderDL = [];
                folderDL = [];
            end

              % Tick boxes panel       
            TickPan = uipanel('Parent', DownloadPan,'Units','pixels', 'Position',[10 50 220 150]);
            % all vs NBHF
            RadioNBHF = uibuttongroup('Parent', TickPan, 'Position', [10 70 200 70]);
            CTInfoTick = uicheckbox('Parent', RadioNBHF,'Text', 'Click trains',...
                'Position', [20 40 220 20]);  
                 % Create three radio buttons in the button group.
                AllCT = uiradiobutton(RadioNBHF,'Text','All',...
                                  'Position',[20 6 100 30],...
                                  'HandleVisibility','off');               
                NBHFCT = uiradiobutton(RadioNBHF,'Text','NBHF',...
                                  'Position',[120 6 100 30],...
                                  'HandleVisibility','off');
            SummaryTick = uicheckbox('Parent', TickPan,'Text', 'Summary table',...
                  'Position', [10 40 220 20]);
            PPMTick = uicheckbox('Parent', TickPan,'Text', 'Positive Porpoise Minute',...
                   'Position', [10 10 220 20]);
           
              % OK button
            OKButtonD = uibutton(DownloadPan, 'push',...
                'ButtonPushedFcn', @(OKButtonNew,event) PushOKButton(app,OKButtonNew));
            OKButtonD.Position = [10 10 90 25];
            OKButtonD.Text = 'Download';
            
            function PushOKButton(OKButtonD, event)
                % Close menu window
               DownloadFig.Visible = 'off';  
               if CTInfoTick.Value == 1
                    if AllCT.Value == 1
                     %% Open all subfolders
                     AllCT = table();
                     for FolderN = 2:length(app.listOfFolderNamesProj)
                        % Get this folder and print it out.
                        thisFolder = app.listOfFolderNamesProj{FolderN}; 
                        CTInfoName = strcat(thisFolder, '\CTInfoNew.mat');
                        CTInfoTemp = load(CTInfoName);
                        app.CTInfo = CTInfoTemp.CTInfo;
                        if FolderN == 2
                          AllCT = app.CTInfo;
                        else
                          L = length(app.CTInfo);
                          AllCT(end+1:end+L) = NewNBHF;
                        end
                     end 
                     % Save table with all NBHF 
                     CTPath = strcat(FolderPathDownload.Value , '\AllCT.csv');
                     AllCT = struct2table(AllCT);
                     writetable(AllCT, CTPath);  
                    elseif NBHFCT.Value == 1
                     %% Open all subfolders
                     AllNBHF = table();
                     for FolderN = 2:length(app.listOfFolderNamesProj)
                        % Get this folder and print it out.
                        thisFolder = app.listOfFolderNamesProj{FolderN}; 
                        CTInfoName = strcat(thisFolder, '\CTInfoNew.mat');
                        CTInfoTemp = load(CTInfoName);
                        app.CTInfo = CTInfoTemp.CTInfo;
                      
                        if FolderN == 2
                          RowsNBHF = strcmp({app.CTInfo.Species}, 'NBHF');
                          AllNBHF = app.CTInfo(RowsNBHF);
                        else
                          RowsNBHF = strcmp({app.CTInfo.Species}, 'NBHF');
                          L = sum(RowsNBHF);
                          NewNBHF = app.CTInfo(RowsNBHF);
                          AllNBHF(end+1:end+L) = NewNBHF;
                        end
                     end 
                     % Save table with all NBHF 
                     NBHFPath = strcat(FolderPathDownload.Value , '\AllNBHF.csv');
                     AllNBHF = struct2table(AllNBHF);
                     writetable(AllNBHF, NBHFPath);
                    end   
               end
               if SummaryTick.Value == 1
                  FileToLoadST = strcat(FolderPathProject.Value, '\SummaryTable.mat'); 
                  SumT = load(FileToLoadST);
                  SummaryTable = SumT.SummaryTable;
                  SaveFileST = strcat(FolderPathDownload.Value, '\SummaryTable.csv');
                  writetable(SummaryTable, SaveFileST)
               end
               if PPMTick.Value == 1
                  FileToLoadPPM = strcat(FolderPathProject.Value, '\PosPorMin.mat'); 
                  PPMT = load(FileToLoadPPM);
                  PosPorMin = PPMT.PosPorMin;
                  assignin('base', 'PosPorMin', PosPorMin)
                  SaveFilePPM = strcat(FolderPathDownload.Value, '\PosPorMin.csv');
                  writetable(PosPorMin, SaveFilePPM)
               end
            end
            % Cancel button
            CancelButton = uibutton(DownloadPan, 'push',...
                'ButtonPushedFcn', @(CancelButton,event) PushCancelButton(app,CancelButton));
            CancelButton.Position = [140 10 90 25];
            CancelButton.Text = 'Cancel';
            % Cancel function
            function PushCancelButton(CancelButton, event)
              DownloadFig.Visible = 'off';
            end
        end

        % Button pushed function: ResetallButton
        function ResetMetrics(app, event)
           cla(app.CPMAxesMetrics, 'reset')
%            cla(app.PPMAxTimes, 'reset')
%            cla(app.PPMAxTot, 'reset')
           app.MetricsTable.Data = {};
           app.UploadData.Enable = 'off';
           app.DisplayButton.Enable = 'off';
           app.SelectFolderMetricEdit.Value = 'C:\Users\';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DetectorMenu = uimenu(app.UIFigure);
            app.DetectorMenu.Text = 'Detector';
            app.SettingsDetectorMenu = uimenu(app.DetectorMenu);
            app.SettingsDetectorMenu.MenuSelectedFcn = createCallbackFcn(app, @FigSetDet, true);
            app.SettingsDetectorMenu.Text = 'Settings Detector';
            app.PorCCMenu = uimenu(app.UIFigure);
            app.PorCCMenu.Text = 'PorCC';
            app.PorCCSettingsMenu = uimenu(app.PorCCMenu);
            app.PorCCSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @FigPorCCSettings, true);
            app.PorCCSettingsMenu.Text = 'PorCC Settings';
            app.ClickTrainsMenu = uimenu(app.UIFigure);
            app.ClickTrainsMenu.Text = 'Click Trains';
            app.NewprojectMenu = uimenu(app.ClickTrainsMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewCTSet, true);
            app.NewprojectMenu.Text = 'New project';
            app.OpenexistingprojectMenu = uimenu(app.ClickTrainsMenu);
            app.OpenexistingprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenCT, true);
            app.OpenexistingprojectMenu.Text = 'Open existing project';
            app.DownloadsMenu = uimenu(app.UIFigure);
            app.DownloadsMenu.Text = 'Downloads';
            app.TablesMenu = uimenu(app.DownloadsMenu);
            app.TablesMenu.MenuSelectedFcn = createCallbackFcn(app, @MenuDownloadTable, true);
            app.TablesMenu.Text = 'Tables';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help ';
            app.AboutDPorCCAMenu = uimenu(app.HelpMenu);
            app.AboutDPorCCAMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutDPorCCAMenuSelected, true);
            app.AboutDPorCCAMenu.Text = 'About D-PorCCA';
            app.SearchMenu = uimenu(app.HelpMenu);
            app.SearchMenu.MenuSelectedFcn = createCallbackFcn(app, @SearchMenuSelected, true);
            app.SearchMenu.Text = 'Search';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CLICKTRAINSTab = uitab(app.TabGroup);
            app.CLICKTRAINSTab.Title = 'CLICK TRAINS ';
            app.DISPLAYSETTINGSPanel = uipanel(app.CLICKTRAINSTab);
            app.DISPLAYSETTINGSPanel.Title = 'DISPLAY SETTINGS';
            app.DatePanel = uipanel(app.DISPLAYSETTINGSPanel);
            app.DatePanel.Title = 'Date';
            app.DateandtimeofCTLabel = uilabel(app.DatePanel);
            app.DateandtimeofCTLabel.Text = 'Date and time of CT';
            app.ClicktrainnumberTotalPanel = uipanel(app.DISPLAYSETTINGSPanel);
            app.ClicktrainnumberTotalPanel.Title = 'Click train number (Total)';
            app.CTForw = uibutton(app.ClicktrainnumberTotalPanel, 'push');
            app.CTForw.ButtonPushedFcn = createCallbackFcn(app, @CTForwCB, true);
            app.CTForw.Text = '>';
            app.CTNumD = uieditfield(app.ClicktrainnumberTotalPanel, 'numeric');
            app.CTNumD.ValueChangedFcn = createCallbackFcn(app, @ChangeCTNum, true);
            app.CTBack = uibutton(app.ClicktrainnumberTotalPanel, 'push');
            app.CTBack.ButtonPushedFcn = createCallbackFcn(app, @CTBackCB, true);
            app.CTBack.Text = '<';
            app.TotalLabel = uilabel(app.ClicktrainnumberTotalPanel);
            app.TotalLabel.Text = '()';
            app.ClicktraintypePanel = uipanel(app.DISPLAYSETTINGSPanel);
            app.ClicktraintypePanel.Title = 'Click train type ';
            app.CTTypeLabel = uilabel(app.ClicktraintypePanel);
            app.CTTypeLabel.Text = 'NBHF';
            app.LengthPanelength = uipanel(app.DISPLAYSETTINGSPanel);
            app.LengthPanelength.Title = 'Length';
            app.LengthLabel = uilabel(app.LengthPanelength);
            app.LengthLabel.Text = '0';
            app.BehaviourPanel = uipanel(app.DISPLAYSETTINGSPanel);
            app.BehaviourPanel.Title = 'Behaviour';
            app.BehavLabel = uilabel(app.BehaviourPanel);
            app.BehavLabel.Text = 'Orientation';
            app.CalfPanel = uipanel(app.DISPLAYSETTINGSPanel);
            app.CalfPanel.Title = 'Calf';
            app.CalfLabel = uilabel(app.CalfPanel);
            app.CalfLabel.Text = '-';
            app.DayNightPanel = uipanel(app.DISPLAYSETTINGSPanel);
            app.DayNightPanel.Title = 'Day/Night';
            app.DayLabel = uilabel(app.DayNightPanel);
            app.DayLabel.Text = 'Day';
            app.NotesPanel = uipanel(app.DISPLAYSETTINGSPanel);
            app.NotesPanel.Title = 'Notes';
            app.SeeAddButton = uibutton(app.NotesPanel, 'push');
            app.SeeAddButton.ButtonPushedFcn = createCallbackFcn(app, @NotesCT, true);
            app.SeeAddButton.Text = 'See/Add';
            app.SaveupdatesButton = uibutton(app.DISPLAYSETTINGSPanel, 'push');
            app.SaveupdatesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveUpdates, true);
            app.SaveupdatesButton.Text = {'Save'; 'updates'};
            app.ACTIONSClicktrainsPanel = uipanel(app.CLICKTRAINSTab);
            app.ACTIONSClicktrainsPanel.Title = 'ACTIONS - Click trains';
            app.SelectionPanel = uipanel(app.ACTIONSClicktrainsPanel);
            app.SelectionPanel.Title = 'Selection ';
            app.SelectedCTCheckBox = uicheckbox(app.SelectionPanel);
            app.SelectedCTCheckBox.Enable = 'off';
            app.SelectedCTCheckBox.Text = 'Selected CT';
            app.SelectButton = uibutton(app.SelectionPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCT, true);
            app.SelectButton.Text = 'Select';
            app.UnselectButton = uibutton(app.SelectionPanel, 'push');
            app.UnselectButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectCT, true);
            app.UnselectButton.Text = 'Unselect';
            app.Individualclicks3DPanel = uipanel(app.ACTIONSClicktrainsPanel);
            app.Individualclicks3DPanel.Title = 'Individual clicks - 3D';
            app.IndClicksAnd3D = uibutton(app.Individualclicks3DPanel, 'push');
            app.IndClicksAnd3D.ButtonPushedFcn = createCallbackFcn(app, @SeeClicksCB, true);
            app.IndClicksAnd3D.Text = {'Individual clicks & CT'; ' in 3D'};
            app.SpectrogramButton = uibutton(app.Individualclicks3DPanel, 'push');
            app.SpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramFig, true);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.VALIDATIONCHANGESPanel = uipanel(app.ACTIONSClicktrainsPanel);
            app.VALIDATIONCHANGESPanel.Title = 'VALIDATION / CHANGES';
            app.CTTypeDropDownLabel = uilabel(app.VALIDATIONCHANGESPanel);
            app.CTTypeDropDownLabel.Text = 'CT Type';
            app.CTTypeDropDown = uidropdown(app.VALIDATIONCHANGESPanel);
            app.CTTypeDropDown.Items = {'Select one', 'NBHF', 'LQ-NBHF', 'Non-NBHF', 'Noise', 'Sonar', '-'};
            app.CTTypeDropDown.Value = 'Select one';
            app.BehaviourDropDownLabel = uilabel(app.VALIDATIONCHANGESPanel);
            app.BehaviourDropDownLabel.Text = 'Behaviour';
            app.BehaviourDropDown = uidropdown(app.VALIDATIONCHANGESPanel);
            app.BehaviourDropDown.Items = {'Select one', 'Navigation', 'Foraging', 'Socialising', 'Unknown', '-'};
            app.BehaviourDropDown.Value = 'Select one';
            app.UpdateButton = uibutton(app.VALIDATIONCHANGESPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @Validate, true);
            app.UpdateButton.Text = 'Update';
            app.DisplayButtonGroup = uibuttongroup(app.ACTIONSClicktrainsPanel);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AllvsNBHFDisplay, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.AllButton = uiradiobutton(app.DisplayButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.NBHFButton = uiradiobutton(app.DisplayButtonGroup);
            app.NBHFButton.Text = 'NBHF';
            app.PLOTSPanel = uipanel(app.CLICKTRAINSTab);
            app.PLOTSPanel.Title = 'PLOTS';
            app.AmpAxesCT = uiaxes(app.PLOTSPanel);
            title(app.AmpAxesCT, 'Amplitude (dB)')
            xlabel(app.AmpAxesCT, '')
            ylabel(app.AmpAxesCT, '')
            app.AmpAxesCT.XTick = [];
            app.ICIAxesCT = uiaxes(app.PLOTSPanel);
            title(app.ICIAxesCT, '')
            ylabel(app.ICIAxesCT, '')
            app.ICIAxesCT.XTick = [];
            app.FreqAxesCT = uiaxes(app.PLOTSPanel);
            title(app.FreqAxesCT, '')
            xlabel(app.FreqAxesCT, 'Time (s)')
            app.ButtonGroupFreqBear = uibuttongroup(app.PLOTSPanel);
            app.ButtonGroupFreqBear.SelectionChangedFcn = createCallbackFcn(app, @FreqvsBearing, true);
            app.CentroidfrequencykHzButton = uiradiobutton(app.ButtonGroupFreqBear);
            app.CentroidfrequencykHzButton.Text = 'Centroid frequency (kHz)';
            app.CentroidfrequencykHzButton.Value = true;
            app.DirectionofarrivalButton = uiradiobutton(app.ButtonGroupFreqBear);
            app.DirectionofarrivalButton.Text = 'Direction of arrival';
            app.ButtonGroupICICPS = uibuttongroup(app.PLOTSPanel);
            app.ButtonGroupICICPS.SelectionChangedFcn = createCallbackFcn(app, @CPSvsICI, true);
            app.InterclickintervalmsButton = uiradiobutton(app.ButtonGroupICICPS);
            app.InterclickintervalmsButton.Text = 'Inter-click interval (ms)';
            app.ClickspersecondButton = uiradiobutton(app.ButtonGroupICICPS);
            app.ClickspersecondButton.Text = 'Clicks per second';
            app.ClickspersecondButton.Value = true;
            app.Image = uiimage(app.CLICKTRAINSTab);
            app.Image.ImageSource = 'Label.png';
            app.METRICSTab = uitab(app.TabGroup);
            app.METRICSTab.Title = 'METRICS';
            app.DISPLAYPanelMetrics = uipanel(app.METRICSTab);
            app.DISPLAYPanelMetrics.Title = 'DISPLAY';
            app.CPMAxesMetrics = uiaxes(app.DISPLAYPanelMetrics);
            title(app.CPMAxesMetrics, '')
            xlabel(app.CPMAxesMetrics, 'Minute of the day')
            ylabel(app.CPMAxesMetrics, 'Number of HQ clicks ')
            app.DISPLAYSETTINGSPanel_2 = uipanel(app.METRICSTab);
            app.DISPLAYSETTINGSPanel_2.Title = 'DISPLAY SETTINGS';
            app.MetricstodisplayPanel = uipanel(app.DISPLAYSETTINGSPanel_2);
            app.MetricstodisplayPanel.Title = 'Metrics to display ';
            app.ButtonGroup = uibuttongroup(app.MetricstodisplayPanel);
            app.PositiveporpoiseminuteButton = uiradiobutton(app.ButtonGroup);
            app.PositiveporpoiseminuteButton.Text = 'Positive porpoise minute';
            app.TypeofCTButton = uiradiobutton(app.ButtonGroup);
            app.TypeofCTButton.Text = 'Type of CT';
            app.BehaviourButton = uiradiobutton(app.ButtonGroup);
            app.BehaviourButton.Text = 'Behaviour ';
            app.BehaviourButton.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.MetricstodisplayPanel);
            app.AllButtonMetrics = uiradiobutton(app.ButtonGroup_2);
            app.AllButtonMetrics.Text = 'All';
            app.AllButtonMetrics.Value = true;
            app.NightDayButton = uiradiobutton(app.ButtonGroup_2);
            app.NightDayButton.Text = 'Night/Day';
            app.PanelDropdown = uipanel(app.DISPLAYSETTINGSPanel_2);
            app.SelectadateDropDownLabel = uilabel(app.PanelDropdown);
            app.SelectadateDropDownLabel.Text = 'Select a date';
            app.SelectadateDropDown = uidropdown(app.PanelDropdown);
            app.SelectadateDropDown.Items = {'All days'};
            app.SelectadateDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownDays, true);
            app.SelectadateDropDown.Enable = 'off';
            app.SelectadateDropDown.Value = 'All days';
            app.DisplayButton = uibutton(app.DISPLAYSETTINGSPanel_2, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMetrics, true);
            app.DisplayButton.Enable = 'off';
            app.DisplayButton.Text = 'UPDATE';
            app.ALLDAYSPanel = uipanel(app.METRICSTab);
            app.ALLDAYSPanel.Title = 'ALL DAYS ';
            app.MetricsTable = uitable(app.ALLDAYSPanel);
            app.MetricsTable.ColumnName = {'Date'; 'NBHF'; 'LQNBHF'; 'NonNBHF'; 'Sonar'; 'Orient'; 'Forage'; 'Social'; 'Unknown'; 'Day'; 'Night'; ''};
            app.MetricsTable.RowName = {};
            app.MetricsTable.ColumnEditable = true;
            app.Panel_3 = uipanel(app.ALLDAYSPanel);
            app.SelectFolderMetricEdit = uieditfield(app.Panel_3, 'text');
            app.SelectFolderMetricEdit.Value = 'C:\';
            app.BrowseButton_2 = uibutton(app.Panel_3, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @MetricBrowse, true);
            app.BrowseButton_2.Text = 'Browse';
            app.SelectfolderwithsubfoldersLabel = uilabel(app.Panel_3);
            app.SelectfolderwithsubfoldersLabel.Text = 'Select folder (with subfolders)';
            app.UploadData = uibutton(app.Panel_3, 'push');
            app.UploadData.ButtonPushedFcn = createCallbackFcn(app, @UploadDataMetrics, true);
            app.UploadData.Enable = 'off';
            app.UploadData.Text = {'UPLOAD'; ' DATA'};
            app.ResetallButton = uibutton(app.Panel_3, 'push');
            app.ResetallButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMetrics, true);
            app.ResetallButton.Text = 'Reset all';
            app.DISPLAYPanelCPM = uipanel(app.METRICSTab);
            app.DISPLAYPanelCPM.Title = 'DISPLAY';
            app.CPMAxesTimes = uiaxes(app.DISPLAYPanelCPM);
            title(app.CPMAxesTimes, 'Positive Porpoise Minute')
            xlabel(app.CPMAxesTimes, 'X')
            ylabel(app.CPMAxesTimes, '')
            app.CPMAxesTimes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CPMAxesTot = uiaxes(app.DISPLAYPanelCPM);
            title(app.CPMAxesTot, 'Total PPM')
            xlabel(app.CPMAxesTot, '')
            ylabel(app.CPMAxesTot, '')
            app.CPMAxesTot.XTick = [0 0.5 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DPorCCA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end